classdef coilanalysis_cyclefiltering < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        EarDropDown                    matlab.ui.control.DropDown
        EarLabel                       matlab.ui.control.Label
        CombineIdenticalStimuliButton  matlab.ui.control.Button
        AnalyzeDataLabel               matlab.ui.control.Label
        Label_4                        matlab.ui.control.Label
        UpdateCumulativeFigureLabel    matlab.ui.control.Label
        Label_3                        matlab.ui.control.Label
        TabGroup                       matlab.ui.container.TabGroup
        bPositionFilteringTab          matlab.ui.container.Tab
        FilterTracesButton_Position    matlab.ui.control.Button
        ResetFilteringButton_Position  matlab.ui.control.Button
        showrawdataCheckBox_2          matlab.ui.control.CheckBox
        righteyecoilCheckBox           matlab.ui.control.CheckBox
        lefteyecoilCheckBox            matlab.ui.control.CheckBox
        ZCheckBox                      matlab.ui.control.CheckBox
        DisplayLabel                   matlab.ui.control.Label
        YCheckBox_2                    matlab.ui.control.CheckBox
        XCheckBox_2                    matlab.ui.control.CheckBox
        EyePositionAxes                matlab.ui.control.UIAxes
        cQPRemovalTab                  matlab.ui.container.Tab
        FilterTracesButton_Velocity_2  matlab.ui.control.Button
        ResetFilteringButton_Velocity_2  matlab.ui.control.Button
        thresholddps2EditField         matlab.ui.control.NumericEditField
        thresholddps2EditFieldLabel    matlab.ui.control.Label
        QPlengthmsEditField            matlab.ui.control.NumericEditField
        QPlengthmsEditFieldLabel       matlab.ui.control.Label
        DisplayLabel_4                 matlab.ui.control.Label
        lefteyecoilCheckBox_4          matlab.ui.control.CheckBox
        righteyecoilCheckBox_4         matlab.ui.control.CheckBox
        LHRHZCheckBox_6                matlab.ui.control.CheckBox
        RALPCheckBox_4                 matlab.ui.control.CheckBox
        LARPCheckBox_4                 matlab.ui.control.CheckBox
        YCheckBox_4                    matlab.ui.control.CheckBox
        XCheckBox_4                    matlab.ui.control.CheckBox
        EyeVelocityAxes_QP             matlab.ui.control.UIAxes
        EyeAccelerationAxes            matlab.ui.control.UIAxes
        dVelocityFilteringTab          matlab.ui.container.Tab
        FilterTracesButton_Velocity    matlab.ui.control.Button
        DisplayLabel_2                 matlab.ui.control.Label
        ResetFilteringButton_Velocity  matlab.ui.control.Button
        lefteyecoilCheckBox_2          matlab.ui.control.CheckBox
        righteyecoilCheckBox_2         matlab.ui.control.CheckBox
        showunfiltereddataCheckBox     matlab.ui.control.CheckBox
        LHRHZCheckBox_5                matlab.ui.control.CheckBox
        RALPCheckBox_3                 matlab.ui.control.CheckBox
        LARPCheckBox_3                 matlab.ui.control.CheckBox
        YCheckBox_3                    matlab.ui.control.CheckBox
        XCheckBox_3                    matlab.ui.control.CheckBox
        EyeVelocityAxes                matlab.ui.control.UIAxes
        eCycleFilteringTab             matlab.ui.container.Tab
        shownystagmuscorrecteddataCheckBox  matlab.ui.control.CheckBox
        ncyclesLabel                   matlab.ui.control.Label
        DisplayLabel_3                 matlab.ui.control.Label
        linkxaxesCheckBox              matlab.ui.control.CheckBox
        lefteyecoilCheckBox_3          matlab.ui.control.CheckBox
        righteyecoilCheckBox_3         matlab.ui.control.CheckBox
        ResetButton                    matlab.ui.control.Button
        CyclesListBox                  matlab.ui.control.ListBox
        CyclesListBoxLabel             matlab.ui.control.Label
        LHRHCheckBox_2                 matlab.ui.control.CheckBox
        RALPCheckBox_2                 matlab.ui.control.CheckBox
        LARPCheckBox_2                 matlab.ui.control.CheckBox
        WaveformAxes                   matlab.ui.control.UIAxes
        AllCyclesAxes                  matlab.ui.control.UIAxes
        aLabel                         matlab.ui.control.Label
        SelectafolderListBox           matlab.ui.control.ListBox
        SelectafolderListBoxLabel      matlab.ui.control.Label
        CreateCycleFiguresButton       matlab.ui.control.Button
        StimAxisDropDown               matlab.ui.control.DropDown
        StimAxisDropDownLabel          matlab.ui.control.Label
        DataTypeDropDown               matlab.ui.control.DropDown
        DataTypeLabel                  matlab.ui.control.Label
        plotcycleaverageCheckBox       matlab.ui.control.CheckBox
        fLabel                         matlab.ui.control.Label
        SaveCyclesParametersButton     matlab.ui.control.Button
        autoresizeGUIcomponentsLabel   matlab.ui.control.Label
        autoresizeGUIcomponentsCheckBox  matlab.ui.control.CheckBox
        VelocityFilteringParametersPanel  matlab.ui.container.Panel
        NystagmusRemovalButton         matlab.ui.control.Button
        ApplyFilteringButton_5         matlab.ui.control.Button
        CloseButton_Velocity           matlab.ui.control.Button
        applyfilterLabel_10            matlab.ui.control.Label
        applyVelocityFilter5_CheckBox  matlab.ui.control.CheckBox
        splineLabel                    matlab.ui.control.Label
        VelocityFilterParamsTable_spline  matlab.ui.control.Table
        applyfilterLabel_9             matlab.ui.control.Label
        applyfilterLabel_8             matlab.ui.control.Label
        applyfilterLabel_7             matlab.ui.control.Label
        applyfilterLabel_6             matlab.ui.control.Label
        applyVelocityFilter4_CheckBox  matlab.ui.control.CheckBox
        applyVelocityFilter3_CheckBox  matlab.ui.control.CheckBox
        applyVelocityFilter2_CheckBox  matlab.ui.control.CheckBox
        applyVelocityFilter1_CheckBox  matlab.ui.control.CheckBox
        irlssqprLabel                  matlab.ui.control.Label
        SavitzkyGolayLabel_2           matlab.ui.control.Label
        movingmedianLabel_2            matlab.ui.control.Label
        movingmeanLabel_2              matlab.ui.control.Label
        VelocityFilterParamsTable_irlss  matlab.ui.control.Table
        VelocityFilterParamsTable_savitzkygolay  matlab.ui.control.Table
        VelocityFilterParamsTable_movingmean  matlab.ui.control.Table
        VelocityFilterParamsTable_movingmedian  matlab.ui.control.Table
        SelectfileListBox              matlab.ui.control.ListBox
        SelectfileListBoxLabel         matlab.ui.control.Label
        PreviousFileButton             matlab.ui.control.Button
        NextFileButton                 matlab.ui.control.Button
        Label                          matlab.ui.control.Label
        createdbyKNM2024Label          matlab.ui.control.Label
        CycleAnalysisGUILabel          matlab.ui.control.Label
        FileLocationEditField          matlab.ui.control.EditField
        LoadFilesButton                matlab.ui.control.Button
        PositionFilteringParametersPanel  matlab.ui.container.Panel
        ApplyFilteringButton_4         matlab.ui.control.Button
        CloseButton_Position           matlab.ui.control.Button
        applyfilterLabel_5             matlab.ui.control.Label
        applyfilterLabel_4             matlab.ui.control.Label
        applyfilterLabel_2             matlab.ui.control.Label
        applyfilterLabel               matlab.ui.control.Label
        applyPositionFilter4_CheckBox  matlab.ui.control.CheckBox
        applyPositionFilter3_CheckBox  matlab.ui.control.CheckBox
        applyPositionFilter2_CheckBox  matlab.ui.control.CheckBox
        applyPositionFilter1_CheckBox  matlab.ui.control.CheckBox
        irlssLabel                     matlab.ui.control.Label
        SavitzkyGolayLabel             matlab.ui.control.Label
        movingmeanLabel_3              matlab.ui.control.Label
        movingmedianLabel_3            matlab.ui.control.Label
        PositionFilterParamsTable_irlss  matlab.ui.control.Table
        PositionFilterParamsTable_savitzkygolay  matlab.ui.control.Table
        PositionFilterParamsTable_movingmean  matlab.ui.control.Table
        PositionFilterParamsTable_movingmedian  matlab.ui.control.Table
        WaveformAxes_2                 matlab.ui.control.UIAxes
        CycleAverageAxes               matlab.ui.control.UIAxes
    end


    % Properties that correspond to public variables
    properties (Access = public)
        rawData % raw data
        filtData % filtered data
        fileNum % number of the file which is being analyzed
    end

    methods (Access = private)

        function plotEyePosition(app)
            cla(app.EyePositionAxes)
            % plot raw data
            if app.showrawdataCheckBox_2.Value
                if app.righteyecoilCheckBox.Value
                    RE_Pos_X = app.rawData(app.fileNum).RE_Position_X;
                    RE_Pos_Y = app.rawData(app.fileNum).RE_Position_Y;
                    RE_Pos_Z = app.rawData(app.fileNum).RE_Position_Z;
                    t = (1:length(RE_Pos_X))/1000;

                    if app.XCheckBox_2.Value % plot X if selected
                        plot(app.EyePositionAxes,t,RE_Pos_X,'Color',[0.9290 0.6940 0.1250])
                        hold(app.EyePositionAxes,'on')
                    end

                    if app.YCheckBox_2.Value % plot Y if selected
                        plot(app.EyePositionAxes,t,RE_Pos_Y,'Color',[0.4940 0.1840 0.5560])
                        hold(app.EyePositionAxes,'on')
                    end

                    if app.ZCheckBox.Value % plot Z if selected
                        plot(app.EyePositionAxes,t,RE_Pos_Z,'Color',[0.6350 0.0780 0.1840])
                        hold(app.EyePositionAxes,'on')
                    end
                end

                if app.lefteyecoilCheckBox.Value
                    LE_Pos_X = app.rawData(app.fileNum).LE_Position_X;
                    LE_Pos_Y = app.rawData(app.fileNum).LE_Position_Y;
                    LE_Pos_Z = app.rawData(app.fileNum).LE_Position_Z;
                    t = (1:length(LE_Pos_X))/1000;

                    if app.XCheckBox_2.Value % plot X if selected
                        plot(app.EyePositionAxes,t,LE_Pos_X,'Color',[0.9290 0.6940 0.1250])
                        hold(app.EyePositionAxes,'on')
                    end

                    if app.YCheckBox_2.Value % plot Y if selected
                        plot(app.EyePositionAxes,t,LE_Pos_Y,'Color',[0.4940 0.1840 0.5560])
                        hold(app.EyePositionAxes,'on')
                    end

                    if app.ZCheckBox.Value % plot Z if selected
                        plot(app.EyePositionAxes,t,LE_Pos_Z,'Color',[0.6350 0.0780 0.1840])
                        hold(app.EyePositionAxes,'on')
                    end
                end
            else % plot filtered data
                if app.righteyecoilCheckBox.Value
                    RE_Pos_X = app.filtData(app.fileNum).RE_Position_X_filt;
                    RE_Pos_Y = app.filtData(app.fileNum).RE_Position_Y_filt;
                    RE_Pos_Z = app.filtData(app.fileNum).RE_Position_Z_filt;
                    t = (1:length(RE_Pos_X))/1000;

                    if app.XCheckBox_2.Value % plot X if selected
                        plot(app.EyePositionAxes,t,RE_Pos_X,'Color',[0.9290 0.6940 0.1250])
                        hold(app.EyePositionAxes,'on')
                    end

                    if app.YCheckBox_2.Value % plot Y if selected
                        plot(app.EyePositionAxes,t,RE_Pos_Y,'Color',[0.4940 0.1840 0.5560])
                        hold(app.EyePositionAxes,'on')
                    end

                    if app.ZCheckBox.Value % plot Z if selected
                        plot(app.EyePositionAxes,t,RE_Pos_Z,'Color',[0.6350 0.0780 0.1840])
                        hold(app.EyePositionAxes,'on')
                    end
                end

                if app.lefteyecoilCheckBox.Value
                    LE_Pos_X = app.filtData(app.fileNum).LE_Position_X_filt;
                    LE_Pos_Y = app.filtData(app.fileNum).LE_Position_Y_filt;
                    LE_Pos_Z = app.filtData(app.fileNum).LE_Position_Z_filt;
                    t = (1:length(LE_Pos_X))/1000;

                    if app.XCheckBox_2.Value % plot X if selected
                        plot(app.EyePositionAxes,t,LE_Pos_X,'Color',[0.9290 0.6940 0.1250])
                        hold(app.EyePositionAxes,'on')
                    end

                    if app.YCheckBox_2.Value % plot Y if selected
                        plot(app.EyePositionAxes,t,LE_Pos_Y,'Color',[0.4940 0.1840 0.5560])
                        hold(app.EyePositionAxes,'on')
                    end

                    if app.ZCheckBox.Value % plot Z if selected
                        plot(app.EyePositionAxes,t,LE_Pos_Z,'Color',[0.6350 0.0780 0.1840])
                        hold(app.EyePositionAxes,'on')
                    end
                end
            end
        end

        function plotEyeVelocity(app)
            cla(app.EyeVelocityAxes)
            % plot unfiltered data
            if app.showunfiltereddataCheckBox.Value
                if app.righteyecoilCheckBox_2.Value
                    RE_Vel_X = app.filtData(app.fileNum).RE_Velocity_X;
                    RE_Vel_Y = app.filtData(app.fileNum).RE_Velocity_Y;
                    RE_Vel_Z = app.filtData(app.fileNum).RE_Velocity_Z;
                    RE_Vel_LARP = app.filtData(app.fileNum).RE_Velocity_LARP;
                    RE_Vel_RALP = app.filtData(app.fileNum).RE_Velocity_RALP;
                    t = (1:length(RE_Vel_X))/1000;

                    if app.XCheckBox_3.Value % plot X if selected
                        plot(app.EyeVelocityAxes,t,RE_Vel_X,'Color',[0.9290 0.6940 0.1250])
                        hold(app.EyeVelocityAxes,'on')
                    end

                    if app.YCheckBox_3.Value % plot Y if selected
                        plot(app.EyeVelocityAxes,t,RE_Vel_Y,'Color',[0.4940 0.1840 0.5560])
                        hold(app.EyeVelocityAxes,'on')
                    end

                    if app.LHRHZCheckBox_5.Value % plot Z if selected
                        plot(app.EyeVelocityAxes,t,RE_Vel_Z,'Color',[0.6350 0.0780 0.1840])
                        hold(app.EyeVelocityAxes,'on')
                    end

                    if app.LARPCheckBox_3.Value % plot LARP if selected
                        plot(app.EyeVelocityAxes,t,RE_Vel_LARP,'Color',[0.4660 0.6740 0.1880])
                        hold(app.EyeVelocityAxes,'on')
                    end

                    if app.RALPCheckBox_3.Value % plot RALP if selected
                        plot(app.EyeVelocityAxes,t,RE_Vel_RALP,'Color',[0 0.4470 0.7410])
                        hold(app.EyeVelocityAxes,'on')
                    end
                end

                if app.lefteyecoilCheckBox_2.Value
                    LE_Vel_X = app.filtData(app.fileNum).LE_Velocity_X;
                    LE_Vel_Y = app.filtData(app.fileNum).LE_Velocity_Y;
                    LE_Vel_Z = app.filtData(app.fileNum).LE_Velocity_Z;
                    LE_Vel_LARP = app.filtData(app.fileNum).LE_Velocity_LARP;
                    LE_Vel_RALP = app.filtData(app.fileNum).LE_Velocity_RALP;
                    t = (1:length(LE_Vel_X))/1000;

                    if app.XCheckBox_3.Value % plot X if selected
                        plot(app.EyeVelocityAxes,t,LE_Vel_X,'Color',[0.9290 0.6940 0.1250])
                        hold(app.EyeVelocityAxes,'on')
                    end

                    if app.YCheckBox_3.Value % plot Y if selected
                        plot(app.EyeVelocityAxes,t,LE_Vel_Y,'Color',[0.4940 0.1840 0.5560])
                        hold(app.EyeVelocityAxes,'on')
                    end

                    if app.LHRHZCheckBox_5.Value % plot Z if selected
                        plot(app.EyeVelocityAxes,t,LE_Vel_Z,'Color',[0.6350 0.0780 0.1840])
                        hold(app.EyeVelocityAxes,'on')
                    end

                    if app.LARPCheckBox_3.Value % plot LARP if selected
                        plot(app.EyeVelocityAxes,t,LE_Vel_LARP,'Color',[0.4660 0.6740 0.1880])
                        hold(app.EyeVelocityAxes,'on')
                    end

                    if app.RALPCheckBox_3.Value % plot RALP if selected
                        plot(app.EyeVelocityAxes,t,LE_Vel_RALP,'Color',[0 0.4470 0.7410])
                        hold(app.EyeVelocityAxes,'on')
                    end
                end
            else % plot filtered data
                if app.righteyecoilCheckBox_2.Value
                    RE_Vel_X = app.filtData(app.fileNum).RE_Velocity_X_filt;
                    RE_Vel_Y = app.filtData(app.fileNum).RE_Velocity_Y_filt;
                    RE_Vel_Z = app.filtData(app.fileNum).RE_Velocity_Z_filt;
                    RE_Vel_LARP = app.filtData(app.fileNum).RE_Velocity_LARP_filt;
                    RE_Vel_RALP = app.filtData(app.fileNum).RE_Velocity_RALP_filt;
                    t = (1:length(RE_Vel_X))/1000;

                    if app.XCheckBox_3.Value % plot X if selected
                        plot(app.EyeVelocityAxes,t,RE_Vel_X,'Color',[0.9290 0.6940 0.1250])
                        hold(app.EyeVelocityAxes,'on')
                    end

                    if app.YCheckBox_3.Value % plot Y if selected
                        plot(app.EyeVelocityAxes,t,RE_Vel_Y,'Color',[0.4940 0.1840 0.5560])
                        hold(app.EyeVelocityAxes,'on')
                    end

                    if app.LHRHZCheckBox_5.Value % plot Z if selected
                        plot(app.EyeVelocityAxes,t,RE_Vel_Z,'Color',[0.6350 0.0780 0.1840])
                        hold(app.EyeVelocityAxes,'on')
                    end

                    if app.LARPCheckBox_3.Value % plot LARP if selected
                        plot(app.EyeVelocityAxes,t,RE_Vel_LARP,'Color',[0.4660 0.6740 0.1880])
                        hold(app.EyeVelocityAxes,'on')
                    end

                    if app.RALPCheckBox_3.Value % plot RALP if selected
                        plot(app.EyeVelocityAxes,t,RE_Vel_RALP,'Color',[0 0.4470 0.7410])
                        hold(app.EyeVelocityAxes,'on')
                    end
                end

                if app.lefteyecoilCheckBox_2.Value
                    LE_Vel_X = app.filtData(app.fileNum).LE_Velocity_X_filt;
                    LE_Vel_Y = app.filtData(app.fileNum).LE_Velocity_Y_filt;
                    LE_Vel_Z = app.filtData(app.fileNum).LE_Velocity_Z_filt;
                    LE_Vel_LARP = app.filtData(app.fileNum).LE_Velocity_LARP_filt;
                    LE_Vel_RALP = app.filtData(app.fileNum).LE_Velocity_RALP_filt;
                    t = (1:length(LE_Vel_X))/1000;

                    if app.XCheckBox_3.Value % plot X if selected
                        plot(app.EyeVelocityAxes,t,LE_Vel_X,'Color',[0.9290 0.6940 0.1250])
                        hold(app.EyeVelocityAxes,'on')
                    end

                    if app.YCheckBox_3.Value % plot Y if selected
                        plot(app.EyeVelocityAxes,t,LE_Vel_Y,'Color',[0.4940 0.1840 0.5560])
                        hold(app.EyeVelocityAxes,'on')
                    end

                    if app.LHRHZCheckBox_5.Value % plot Z if selected
                        plot(app.EyeVelocityAxes,t,LE_Vel_Z,'Color',[0.6350 0.0780 0.1840])
                        hold(app.EyeVelocityAxes,'on')
                    end

                    if app.LARPCheckBox_3.Value % plot LARP if selected
                        plot(app.EyeVelocityAxes,t,LE_Vel_LARP,'Color',[0.4660 0.6740 0.1880])
                        hold(app.EyeVelocityAxes,'on')
                    end

                    if app.RALPCheckBox_3.Value % plot RALP if selected
                        plot(app.EyeVelocityAxes,t,LE_Vel_RALP,'Color',[0 0.4470 0.7410])
                        hold(app.EyeVelocityAxes,'on')
                    end
                end
            end
        end

        function plotEyeVelocity_QP(app)
            cla(app.EyeVelocityAxes_QP)
            % plot filtered data
            if app.righteyecoilCheckBox_4.Value
                RE_Vel_X = app.filtData(app.fileNum).RE_Velocity_X_filt;
                RE_Vel_Y = app.filtData(app.fileNum).RE_Velocity_Y_filt;
                RE_Vel_Z = app.filtData(app.fileNum).RE_Velocity_Z_filt;
                RE_Vel_LARP = app.filtData(app.fileNum).RE_Velocity_LARP_filt;
                RE_Vel_RALP = app.filtData(app.fileNum).RE_Velocity_RALP_filt;
                t = (1:length(RE_Vel_X))/1000;

                if app.XCheckBox_4.Value % plot X if selected
                    plot(app.EyeVelocityAxes_QP,t,RE_Vel_X,'Color',[0.9290 0.6940 0.1250])
                    hold(app.EyeVelocityAxes_QP,'on')
                end

                if app.YCheckBox_4.Value % plot Y if selected
                    plot(app.EyeVelocityAxes_QP,t,RE_Vel_Y,'Color',[0.4940 0.1840 0.5560])
                    hold(app.EyeVelocityAxes_QP,'on')
                end

                if app.LHRHZCheckBox_6.Value % plot Z if selected
                    plot(app.EyeVelocityAxes_QP,t,RE_Vel_Z,'Color',[0.6350 0.0780 0.1840])
                    hold(app.EyeVelocityAxes_QP,'on')
                end

                if app.LARPCheckBox_4.Value % plot LARP if selected
                    plot(app.EyeVelocityAxes_QP,t,RE_Vel_LARP,'Color',[0.4660 0.6740 0.1880])
                    hold(app.EyeVelocityAxes_QP,'on')
                end

                if app.RALPCheckBox_4.Value % plot RALP if selected
                    plot(app.EyeVelocityAxes_QP,t,RE_Vel_RALP,'Color',[0 0.4470 0.7410])
                    hold(app.EyeVelocityAxes_QP,'on')
                end
            end

            if app.lefteyecoilCheckBox_4.Value
                LE_Vel_X = app.filtData(app.fileNum).LE_Velocity_X_filt;
                LE_Vel_Y = app.filtData(app.fileNum).LE_Velocity_Y_filt;
                LE_Vel_Z = app.filtData(app.fileNum).LE_Velocity_Z_filt;
                LE_Vel_LARP = app.filtData(app.fileNum).LE_Velocity_LARP_filt;
                LE_Vel_RALP = app.filtData(app.fileNum).LE_Velocity_RALP_filt;
                t = (1:length(LE_Vel_X))/1000;

                if app.XCheckBox_4.Value % plot X if selected
                    plot(app.EyeVelocityAxes_QP,t,LE_Vel_X,'Color',[0.9290 0.6940 0.1250])
                    hold(app.EyeVelocityAxes_QP,'on')
                end

                if app.YCheckBox_4.Value % plot Y if selected
                    plot(app.EyeVelocityAxes_QP,t,LE_Vel_Y,'Color',[0.4940 0.1840 0.5560])
                    hold(app.EyeVelocityAxes_QP,'on')
                end

                if app.LHRHZCheckBox_6.Value % plot Z if selected
                    plot(app.EyeVelocityAxes_QP,t,LE_Vel_Z,'Color',[0.6350 0.0780 0.1840])
                    hold(app.EyeVelocityAxes_QP,'on')
                end

                if app.LARPCheckBox_4.Value % plot LARP if selected
                    plot(app.EyeVelocityAxes_QP,t,LE_Vel_LARP,'Color',[0.4660 0.6740 0.1880])
                    hold(app.EyeVelocityAxes_QP,'on')
                end

                if app.RALPCheckBox_4.Value % plot RALP if selected
                    plot(app.EyeVelocityAxes_QP,t,LE_Vel_RALP,'Color',[0 0.4470 0.7410])
                    hold(app.EyeVelocityAxes_QP,'on')
                end
            end
        end


        function plotEyeAcceleration(app)
            cla(app.EyeAccelerationAxes)

            RE_Vel_X = app.filtData(app.fileNum).RE_Velocity_X;
            RE_Vel_Y = app.filtData(app.fileNum).RE_Velocity_Y;
            RE_Vel_Z = app.filtData(app.fileNum).RE_Velocity_Z;
            RE_Vel_LARP = app.filtData(app.fileNum).RE_Velocity_LARP;
            RE_Vel_RALP = app.filtData(app.fileNum).RE_Velocity_RALP;

            LE_Vel_X = app.filtData(app.fileNum).LE_Velocity_X;
            LE_Vel_Y = app.filtData(app.fileNum).LE_Velocity_Y;
            LE_Vel_Z = app.filtData(app.fileNum).LE_Velocity_Z;
            LE_Vel_LARP = app.filtData(app.fileNum).LE_Velocity_LARP;
            LE_Vel_RALP = app.filtData(app.fileNum).LE_Velocity_RALP;   

            if app.righteyecoilCheckBox.Value                
                x = RE_Vel_X;
                y = RE_Vel_Y;
                larp = RE_Vel_LARP;
                ralp = RE_Vel_RALP;
                lhrh = RE_Vel_Z;
            else % app.lefteyecoilCheckBox.Value
                x = LE_Vel_X;
                y = LE_Vel_Y;
                larp = LE_Vel_LARP;
                ralp = LE_Vel_RALP;
                lhrh = LE_Vel_Z;
            end
            
            stimAxis = app.StimAxisDropDown.Value;
            switch stimAxis
                case "LARP"
                    chosenTrace = larp;
                case "RALP"
                    chosenTrace = ralp;
                case "Z"
                    chosenTrace = lhrh;
            end

            eyeAcc = diff(...
                sqrt((x).^2 + (y).^2 + (lhrh).^2));
            % trace_I = cat(1,0,diff(movmedian(eyeAcc,length(chosenTrace)/500)));
            trace_I = abs(diff(movmedian(chosenTrace,5)));
            t = (1:length(trace_I))/1000;
            
            plot(app.EyeAccelerationAxes,t,trace_I,'Color','b')
            hold(app.EyeAccelerationAxes,'on')

             
        end

        function plotAllCycles(app)
            cla(app.AllCyclesAxes)

            % create waveforms for plot: 
            % !!!!!!!!!!!!!!!!!!! THIS SHOULD
            % PROBABLY BE ITS OWN FUNCTION!!!!!!!!!!!!!!!!!!!!
            filenames = app.SelectfileListBox.Items;
            currentfile = filenames{app.fileNum};

            if contains(currentfile, 'PFMRamp') % create PFM waveform
                if contains(currentfile,'Base')
                    % waveformInfo = strsplit(currentfile,{'_','pps','x','Base','Pulse'});
                    % offset = str2double(waveformInfo{14});
                    % low_amp = str2double(waveformInfo{16});
                    % high_amp = str2double(waveformInfo{17});
                    % nCycles = str2double(waveformInfo{10});
                    % phase1dur = str2double(waveformInfo{12});
                    % phase2dur = str2double(waveformInfo{12});
                    % rampdur = str2double(waveformInfo{11});
                    % interstimgap = str2double(extractBefore(waveformInfo{13},'ms'));

                    offset = 0;
                    low_amp = 0;
                    high_amp = 450;
                    nCycles = 20;
                    phase1dur = 150;
                    phase2dur = 150;
                    ipg = 750;
                    rampdur = 50;
                    interstimgap = 750;
                else

                    waveformInfo = strsplit(currentfile,{'_','pps','x','Pulse','ms','PFM'});
                    offset = str2double(waveformInfo{13});
                    low_amp = str2double(waveformInfo{15});
                    high_amp = str2double(waveformInfo{16});
                    nCycles = str2double(waveformInfo{9});
                    phase1dur = str2double(waveformInfo{11});
                    phase2dur = str2double(waveformInfo{11});
                    rampdur = str2double(waveformInfo{10});
                    interstimgap = str2double(waveformInfo{12});
                    % offset = 0;
                    % low_amp = 0;
                    % high_amp = 450;
                    % nCycles = 20;
                    % phase1dur = 150;
                    % phase2dur = 150;
                    % ipg = 750;
                    % rampdur = 50;
                    % interstimgap = 750;
                end

                rampdown1 = interp1([1 rampdur],[offset high_amp],[1:rampdur],'linear');
                rampup1 = interp1([1 rampdur],[high_amp offset],[1:rampdur],'linear');
                rampup2 = interp1([1 rampdur],[offset low_amp],[1:rampdur],'linear');
                rampdown2 = interp1([1 rampdur],[low_amp offset],[1:rampdur],'linear');
                waveform = [rampup1 repmat(high_amp,1,phase1dur) rampdown1...
                    repmat(offset,1,interstimgap)...
                    rampdown2 repmat(low_amp,1,phase2dur) rampup2...
                    repmat(offset,1,interstimgap)];

                cycleLength = length(waveform); % in ms
                waveformLength = nCycles * cycleLength; % in ms
                t_wave = (1:(nCycles * cycleLength))/1000; % in s
                t_wave_ms = t_wave(1:cycleLength)*1000;

                app.WaveformAxes.YLabel.String = 'pulse frequency (Hz)';
                app.WaveformAxes_2.YLabel.String = 'pulse frequency (Hz)';
                ytickformat(app.WaveformAxes,'%+4.4g')
                ytickformat(app.WaveformAxes_2,'%+4.4g')

            elseif contains(currentfile, 'PFMSine')

                % waveformInfo = strsplit(currentfile,{' ','_','Hz','x','uA','Amp','Offs','ms','Freq','Delta','pps','Base'});
                % 
                % frequency = str2double(strrep(waveformInfo{13},'p','.'))/1000; % in cycles/ms
                % cycleLength = str2double(waveformInfo{11}); % in ms
                % sineAmp = str2double(waveformInfo{14});
                % nCycles = str2double(waveformInfo{10});
                % 
                % % early files did not contain information about pfm baseline
                % % in the flags - these files all had an offset of 100pps
                % if ~contains(currentfile,'Base')
                %     offset = 100;
                % else
                %     offset = str2double(waveformInfo{12});
                % end

                % 2025-10-29 EOV: changed waveformInfo extraction to be
                % accurate for today's data. I'm not sure if this isn't
                % working because of a bug earlier in creating the
                % filename, or if it is just based on an outdated naming
                % structure
                waveformInfo = strsplit(currentfile,{' ','_','Hz','x','uA','Amp','Offs','ms','Freq','Delta','pps','Base','('});

                frequency = str2double(strrep(waveformInfo{12},'p','.'))/1000; % in cycles/ms
                cycleLength = str2double(waveformInfo{10}); % in ms
                sineAmp = str2double(waveformInfo{13});
                nCycles = str2double(waveformInfo{9});

                % early files did not contain information about pfm baseline
                % in the flags - these files all had an offset of 100pps
                if ~contains(currentfile,'Base')
                    offset = 100;
                else
                    offset = str2double(waveformInfo{11});
                end

                Fs = 1;                   % samples per ms
                dt = 1/Fs;                   % ms per sample
                t_cycle = (0:dt:cycleLength-dt)';     % ms
                waveform = (sin(2*pi*frequency*t_cycle)*sineAmp)' + offset;

                waveformLength = nCycles * cycleLength; % in ms
                t_wave = (0:(dt/1000):(waveformLength/1000)-(dt/1000))';
                t_wave_ms = 0:dt:(waveformLength)-dt';

                app.WaveformAxes.YLabel.String = 'pulse frequency (Hz)';
                app.WaveformAxes_2.YLabel.String = 'pulse frequency (Hz)';
                ytickformat(app.WaveformAxes,'%+4.4g')
                ytickformat(app.WaveformAxes_2,'%+4.4g')

            else % create iDC waveform

                if contains(currentfile, 'DCSine')

%                     frequency = str2double(strrep(waveformInfo{9},'p','.'))/1000; % in cycles/ms
%                     cycleLength = str2double(waveformInfo{8}); % in ms
%                     sineAmp = str2double(waveformInfo{5});
%                     offset = str2double(waveformInfo{6});
%                     nCycles = str2double(waveformInfo{7});
                    
                    pat = '_' + digitsPattern(1,3) + '_[';
                    frequency = str2double(replace(char(extract(strrep(currentfile,'p','.'),pat)),{'x','[','_'},''))/1000; % in cycles/ms
                    cycleLength = str2double(extractBetween(currentfile,'x','_')); % in ms
                    sineAmp = str2double(extractBetween(currentfile,'DCSine','_'));
                    offset = str2double(extractBetween(currentfile,string(sineAmp) + '_','_'));
                    nCycles = str2double(extractBetween(currentfile,'_' + string(offset) + '_','x'));

                    Fs = 1;                   % samples per ms
                    dt = 1/Fs;                   % ms per sample
                    t_cycle = (0:dt:cycleLength-dt)';     % ms
                    waveform = (sin(2*pi*frequency*t_cycle)*sineAmp)' + offset;

                    waveformLength = nCycles * cycleLength; % in ms
                    t_wave = (0:(dt/1000):(waveformLength/1000)-(dt/1000))';
                    t_wave_ms = 0:dt:(waveformLength)-dt';

                elseif contains(currentfile, 'DCTrap')

                    if contains(app.FileLocationEditField.Value,'20240207')
                        waveformInfo = strsplit(currentfile,{'_','Offs','x','uA','+','ms','Trap'});
                        offset = str2double(waveformInfo{7});
                        low_amp = offset + str2double(waveformInfo{4});
                        high_amp = offset + str2double(waveformInfo{6});
                        nCycles = str2double(waveformInfo{8});
                        phase1dur = str2double(waveformInfo{9});
                        phase2dur = str2double(waveformInfo{11});
                        ipg = str2double(waveformInfo{10});
                        rampdur = str2double(waveformInfo{14});
                        interstimgap = str2double(waveformInfo{12});
                    else

                    waveformInfo = strsplit(currentfile,{'_','Offs','x','uA','+','ms','Trap','.mat'});
                    % offset = str2double(waveformInfo{7});
                    offset = str2double(waveformInfo{5});
                    % low_amp = offset + str2double(waveformInfo{4});
                    low_amp = offset + str2double(waveformInfo{3});
                    % high_amp = offset + str2double(waveformInfo{6});
                    high_amp = offset + str2double(waveformInfo{4});
                    % nCycles = str2double(waveformInfo{8});
                    nCycles = str2double(waveformInfo{6});
                    % phase1dur = str2double(waveformInfo{9});
                    phase1dur = str2double(waveformInfo{7});
                    % phase2dur = str2double(waveformInfo{11});
                    phase2dur = str2double(waveformInfo{9});
                    % ipg = str2double(waveformInfo{10});
                    ipg = str2double(waveformInfo{8});
                    % rampdur = str2double(waveformInfo{14});
                    rampdur = str2double(waveformInfo{11});
                    % interstimgap = str2double(waveformInfo{12});
                    interstimgap = str2double(waveformInfo{10});
                    end

                    rampdown1 = interp1([1 rampdur],[offset low_amp],[1:rampdur],'linear');
                    rampup1 = interp1([1 rampdur],[low_amp offset],[1:rampdur],'linear');
                    rampup2 = interp1([1 rampdur],[offset high_amp],[1:rampdur],'linear');
                    rampdown2 = interp1([1 rampdur],[high_amp offset],[1:rampdur],'linear');
                    waveform = [rampdown1 repmat(low_amp,1,phase1dur) rampup1...
                        repmat(offset,1,ipg)...
                        rampup2 repmat(high_amp,1,phase2dur) rampdown2...
                        repmat(offset,1,interstimgap)];

                    cycleLength = length(waveform); % in ms
                    waveformLength = nCycles * cycleLength; % in ms
                    t_wave = (1:(nCycles * cycleLength))/1000; % in s
                    t_wave_ms = t_wave(1:cycleLength)*1000;
                elseif contains(currentfile,'DCHalfSine')
                    frequency = str2double(extractBetween(currentfile,'x','_+'))/1000; % in cycles/ms
                    cycleLength = 1/frequency; % in ms
                    sineAmp1 = str2double(extractBetween(currentfile,'DCHalfSine','_'));
                    sineAmp2 = str2double(extractBetween(currentfile,string(sineAmp1) + '_','_'));
                    offset = str2double(extractBetween(currentfile,string(sineAmp2) + '_','_'));
                    nCycles = str2double(extractBetween(currentfile,'_' + string(offset) + '_','x'));

                    Fs = 1;                   % samples per ms
                    dt = 1/Fs;                   % ms per sample
                    t_cycle = (0:dt:cycleLength/2-dt)';     % ms
                    firstPhase = (sin(2*pi*frequency*t_cycle)*sineAmp1)' + offset;
                    ipg = str2double(extractBetween(currentfile,'+_','_[')); % in ms
                    secondPhase = (sin(2*pi*frequency*t_cycle)*sineAmp2)' + offset;
                    waveform = [firstPhase,repmat(offset,1,ipg),secondPhase,repmat(offset,1,ipg)];

                    waveformLength = nCycles * (cycleLength + 2*ipg); % in ms
                    t_wave = (0:(dt/1000):(waveformLength/1000)-(dt/1000))';
                    t_wave_ms = 0:dt:(waveformLength)-dt';
                end

                app.WaveformAxes.YLabel.String = 'current (uA)';
                    app.WaveformAxes_2.YLabel.String = 'current (uA)';
                    ytickformat(app.WaveformAxes,'%+4.4g')
                    ytickformat(app.WaveformAxes_2,'%+4.4g')
            end

            % plot filtered data
            if app.righteyecoilCheckBox_3.Value

                if app.shownystagmuscorrecteddataCheckBox.Value % if show nystagmus corrected data
                    RE_Vel_Z = app.filtData(app.fileNum).RE_Velocity_Z_nystagmusCorrected;
                    RE_Vel_LARP = app.filtData(app.fileNum).RE_Velocity_LARP_nystagmusCorrected;
                    RE_Vel_RALP = app.filtData(app.fileNum).RE_Velocity_RALP_nystagmusCorrected;
                else % plot data without nystagmus correction
                    RE_Vel_Z = app.filtData(app.fileNum).RE_Velocity_Z_filt;
                    RE_Vel_LARP = app.filtData(app.fileNum).RE_Velocity_LARP_filt;
                    RE_Vel_RALP = app.filtData(app.fileNum).RE_Velocity_RALP_filt;
                end
                t = (1:length(RE_Vel_Z))/1000;


                if app.LHRHCheckBox_2.Value % plot Z if selected
                    plot(app.AllCyclesAxes,t,RE_Vel_Z,'Color',[0.6350 0.0780 0.1840])
                    hold(app.AllCyclesAxes,'on')
                end

                if app.LARPCheckBox_2.Value % plot LARP if selected
                    plot(app.AllCyclesAxes,t,RE_Vel_LARP,'Color',[0.4660 0.6740 0.1880])
                    hold(app.AllCyclesAxes,'on')
                end

                if app.RALPCheckBox_2.Value % plot RALP if selected
                    plot(app.AllCyclesAxes,t,RE_Vel_RALP,'Color',[0 0.4470 0.7410])
                    hold(app.AllCyclesAxes,'on')
                end
            end

            if app.lefteyecoilCheckBox_3.Value

                if app.shownystagmuscorrecteddataCheckBox.Value % if show nystagmus corrected data
                    LE_Vel_Z = app.filtData(app.fileNum).LE_Velocity_Z_nystagmusCorrected;
                    LE_Vel_LARP = app.filtData(app.fileNum).LE_Velocity_LARP_nystagmusCorrected;
                    LE_Vel_RALP = app.filtData(app.fileNum).LE_Velocity_RALP_nystagmusCorrected;
                else % plot data without nystagmus correction
                    LE_Vel_Z = app.filtData(app.fileNum).LE_Velocity_Z_filt;
                    LE_Vel_LARP = app.filtData(app.fileNum).LE_Velocity_LARP_filt;
                    LE_Vel_RALP = app.filtData(app.fileNum).LE_Velocity_RALP_filt;
                end

                if app.LHRHCheckBox_2.Value % plot Z if selected
                    plot(app.AllCyclesAxes,t,LE_Vel_Z,'Color',[0.6350 0.0780 0.1840])
                    hold(app.AllCyclesAxes,'on')
                end

                if app.LARPCheckBox_2.Value % plot LARP if selected
                    plot(app.AllCyclesAxes,t,LE_Vel_LARP,'Color',[0.4660 0.6740 0.1880])
                    hold(app.AllCyclesAxes,'on')
                end

                if app.RALPCheckBox_2.Value % plot RALP if selected
                    plot(app.AllCyclesAxes,t,LE_Vel_RALP,'Color',[0 0.4470 0.7410])
                    hold(app.AllCyclesAxes,'on')
                end
            end

            % plot asktrisk 60ms into cycle
            app.filtData(app.fileNum).RE_Velocity_X_Cycles = reshape(app.filtData(app.fileNum).RE_Velocity_X_filt(1:waveformLength),[],nCycles);
            app.filtData(app.fileNum).RE_Velocity_Y_Cycles = reshape(app.filtData(app.fileNum).RE_Velocity_Y_filt(1:waveformLength),[],nCycles);
            app.filtData(app.fileNum).RE_Velocity_Z_Cycles = reshape(app.filtData(app.fileNum).RE_Velocity_Z_filt(1:waveformLength),[],nCycles);
            app.filtData(app.fileNum).RE_Velocity_LARP_Cycles = reshape(app.filtData(app.fileNum).RE_Velocity_LARP_filt(1:waveformLength),[],nCycles);
            app.filtData(app.fileNum).RE_Velocity_RALP_Cycles = reshape(app.filtData(app.fileNum).RE_Velocity_RALP_filt(1:waveformLength),[],nCycles);
            app.filtData(app.fileNum).LE_Velocity_X_Cycles = reshape(app.filtData(app.fileNum).LE_Velocity_X_filt(1:waveformLength),[],nCycles);
            app.filtData(app.fileNum).LE_Velocity_Y_Cycles = reshape(app.filtData(app.fileNum).LE_Velocity_Y_filt(1:waveformLength),[],nCycles);
            app.filtData(app.fileNum).LE_Velocity_Z_Cycles = reshape(app.filtData(app.fileNum).LE_Velocity_Z_filt(1:waveformLength),[],nCycles);
            app.filtData(app.fileNum).LE_Velocity_LARP_Cycles = reshape(app.filtData(app.fileNum).LE_Velocity_LARP_filt(1:waveformLength),[],nCycles);
            app.filtData(app.fileNum).LE_Velocity_RALP_Cycles = reshape(app.filtData(app.fileNum).LE_Velocity_RALP_filt(1:waveformLength),[],nCycles);

            VOR_latency = 20; %ms, high estimate
            % find index of waveform where the maximum current is being
            % delivered
            cathodicStim_idx = min(find(waveform == min(waveform)));
            anodicStim_idx = min(find(waveform == max(waveform)));
            cathodicResponse_t = (cathodicStim_idx + VOR_latency:cycleLength:waveformLength)/1000;
            anodicResponse_t = (anodicStim_idx + VOR_latency:cycleLength:waveformLength)/1000;

%             % plot asterisks for cathodic stim
%             plot(app.AllCyclesAxes,cathodicResponse_t,app.filtData(app.fileNum).RE_Velocity_LARP_Cycles(cathodicStim_idx + VOR_latency,:),'k*')
%             plot(app.AllCyclesAxes,cathodicResponse_t,app.filtData(app.fileNum).RE_Velocity_RALP_Cycles(cathodicStim_idx + VOR_latency,:),'k*')
%             plot(app.AllCyclesAxes,cathodicResponse_t,app.filtData(app.fileNum).RE_Velocity_Z_Cycles(cathodicStim_idx + VOR_latency,:),'k*')
% 
%             % plot asterisks for anodic stim
%             plot(app.AllCyclesAxes,anodicResponse_t,app.filtData(app.fileNum).RE_Velocity_LARP_Cycles(anodicStim_idx + VOR_latency,:),'k*')
%             plot(app.AllCyclesAxes,anodicResponse_t,app.filtData(app.fileNum).RE_Velocity_RALP_Cycles(anodicStim_idx + VOR_latency,:),'k*')
%             plot(app.AllCyclesAxes,anodicResponse_t,app.filtData(app.fileNum).RE_Velocity_Z_Cycles(anodicStim_idx + VOR_latency,:),'k*')

            % fill cycles list box with number for each cycle if empty
            if isempty(app.filtData(app.fileNum).KeepCycleNums) % if there are no cycles selected yet
                app.CyclesListBox.Items = string([1:nCycles]');
                app.CyclesListBox.Value = app.CyclesListBox.Items;
                app.filtData(app.fileNum).KeepCycleNums = app.CyclesListBox.Items;
            elseif any(contains(app.CyclesListBox.Items,'Item'))
                app.CyclesListBox.Items = string([1:nCycles]');
                app.CyclesListBox.Value = app.filtData(app.fileNum).KeepCycleNums;
            else
                app.CyclesListBox.Items = string([1:nCycles]');
                app.CyclesListBox.Value = app.filtData(app.fileNum).KeepCycleNums;
            end

            % plot lines to divide cycles
            cycleEnds = (0:nCycles-1)' * cycleLength/1000;
            xline(app.AllCyclesAxes,cycleEnds,'--',app.CyclesListBox.Items)

            % plot waveform
            waveform_allCycles = reshape(repmat(waveform,1,nCycles),1,[]);
%             waveform_allCycles = [waveform_allCycles offset];
            plot(app.WaveformAxes,t_wave,waveform_allCycles,'k')
            plot(app.WaveformAxes_2,t_wave_ms(1:length(waveform)),waveform,'k')

            ytickformat(app.AllCyclesAxes,'%+4.4g')

            % save waveform and number of cycles info to filtData
            app.filtData(app.fileNum).Waveform = waveform;
            app.filtData(app.fileNum).NumCycles = nCycles;

            xlim(app.AllCyclesAxes,'tight')
            ylim(app.AllCyclesAxes,'padded')
            xlim(app.WaveformAxes,'tight')
            ylim(app.WaveformAxes,'padded')
        end

        function plotCycleAverage(app)
            cla(app.CycleAverageAxes)

            % get number of cycles from section name
            filenames = app.SelectfileListBox.Items;
            currentfile = filenames{app.fileNum};
            if contains(currentfile, 'PFMRamp') % create PFM waveform
                if contains(currentfile,'Base')
                    waveformInfo = strsplit(currentfile,{'_','pps','x','Base'});
                    nCycles = str2double(waveformInfo{10});
                else
                    nCycles = 20;
                end
            elseif contains(currentfile, 'PFMSine') % create PFM sine waveform
                waveformInfo = strsplit(currentfile,{'_','Offs','x','uA'});
                nCycles = str2double(waveformInfo{10});
            else % create iDC waveform
                if contains(currentfile,'DCTrap')
                    waveformInfo = strsplit(currentfile,{'_','Offs','x','uA'});
                    nCycles = str2double(waveformInfo{8});
                elseif contains(currentfile,'DCSine')
                    StimAmplitude = str2double(extractBetween(currentfile,'DCSine','_'));
                    StimOffset = str2double(extractBetween(currentfile,string(StimAmplitude) + '_','_'));
                    nCycles = str2double(extractBetween(currentfile,'_' + string(StimOffset) + '_','x'));
                end
            end

            % plot filtered data for cycles to keep
            cyclestokeep = str2double(app.CyclesListBox.Value);

            % plot right eye data
            if app.shownystagmuscorrecteddataCheckBox.Value % if show nystagmus corrected data
                RE_Vel_Z = app.filtData(app.fileNum).RE_Velocity_Z_Cycles_nystagmusCorrected(:,cyclestokeep);
                RE_Vel_LARP = app.filtData(app.fileNum).RE_Velocity_LARP_Cycles_nystagmusCorrected(:,cyclestokeep);
                RE_Vel_RALP = app.filtData(app.fileNum).RE_Velocity_RALP_Cycles_nystagmusCorrected(:,cyclestokeep);
            else % plot data without nystagmus correction
                RE_Vel_Z = app.filtData(app.fileNum).RE_Velocity_Z_Cycles(:,cyclestokeep);
                RE_Vel_LARP = app.filtData(app.fileNum).RE_Velocity_LARP_Cycles(:,cyclestokeep);
                RE_Vel_RALP = app.filtData(app.fileNum).RE_Velocity_RALP_Cycles(:,cyclestokeep);
            end
            t = (1:length(RE_Vel_Z));

            if app.righteyecoilCheckBox_3.Value
                if app.LHRHCheckBox_2.Value % plot Z if selected
                    plot(app.CycleAverageAxes,t,RE_Vel_Z,'Color',[0.6350 0.0780 0.1840 0.3])
                    hold(app.CycleAverageAxes,'on')
                end

                if app.LARPCheckBox_2.Value % plot LARP if selected
                    plot(app.CycleAverageAxes,t,RE_Vel_LARP,'Color',[0.4660 0.6740 0.1880 0.3])
                    hold(app.CycleAverageAxes,'on')
                end

                if app.RALPCheckBox_2.Value % plot RALP if selected
                    plot(app.CycleAverageAxes,t,RE_Vel_RALP,'Color',[0 0.4470 0.7410 0.3])
                    hold(app.CycleAverageAxes,'on')
                end
            end

            % plot left eye data
            if app.shownystagmuscorrecteddataCheckBox.Value % if show nystagmus corrected data
                LE_Vel_Z = app.filtData(app.fileNum).LE_Velocity_Z_Cycles_nystagmusCorrected(:,cyclestokeep);
                LE_Vel_LARP = app.filtData(app.fileNum).LE_Velocity_LARP_Cycles_nystagmusCorrected(:,cyclestokeep);
                LE_Vel_RALP = app.filtData(app.fileNum).LE_Velocity_RALP_Cycles_nystagmusCorrected(:,cyclestokeep);
            else % plot data without nystagmus correction
                LE_Vel_Z = app.filtData(app.fileNum).LE_Velocity_Z_Cycles(:,cyclestokeep);
                LE_Vel_LARP = app.filtData(app.fileNum).LE_Velocity_LARP_Cycles(:,cyclestokeep);
                LE_Vel_RALP = app.filtData(app.fileNum).LE_Velocity_RALP_Cycles(:,cyclestokeep);
            end
            t = (1:length(LE_Vel_Z));

            if app.lefteyecoilCheckBox_3.Value
                if app.LHRHCheckBox_2.Value % plot Z if selected
                    plot(app.CycleAverageAxes,t,LE_Vel_Z,'Color',[0.6350 0.0780 0.1840 0.3])
                    hold(app.CycleAverageAxes,'on')
                end

                if app.LARPCheckBox_2.Value % plot LARP if selected
                    plot(app.CycleAverageAxes,t,LE_Vel_LARP,'Color',[0.4660 0.6740 0.1880 0.3])
                    hold(app.CycleAverageAxes,'on')
                end

                if app.RALPCheckBox_2.Value % plot RALP if selected
                    plot(app.CycleAverageAxes,t,LE_Vel_RALP,'Color',[0 0.4470 0.7410 0.3])
                    hold(app.CycleAverageAxes,'on')
                end
            end

            % plot cycle average
            if app.plotcycleaverageCheckBox.Value
                
                % plot data traces for only the selected eye(s) and axes
                if app.shownystagmuscorrecteddataCheckBox.Value % plot nystagmus corrected
                    RE_LARP_cycavg = app.filtData(app.fileNum).RE_LARP_cycavg_nystagmusCorrected;
                    RE_RALP_cycavg = app.filtData(app.fileNum).RE_RALP_cycavg_nystagmusCorrected;
                    RE_Z_cycavg = app.filtData(app.fileNum).RE_Z_cycavg_nystagmusCorrected;

                    RE_LARP_cycstd = app.filtData(app.fileNum).RE_LARP_cycstd_nystagmusCorrected;
                    RE_RALP_cycstd = app.filtData(app.fileNum).RE_RALP_cycstd_nystagmusCorrected;
                    RE_Z_cycstd = app.filtData(app.fileNum).RE_Z_cycstd_nystagmusCorrected;

                    LE_LARP_cycavg = app.filtData(app.fileNum).LE_LARP_cycavg_nystagmusCorrected;
                    LE_RALP_cycavg = app.filtData(app.fileNum).LE_RALP_cycavg_nystagmusCorrected;
                    LE_Z_cycavg = app.filtData(app.fileNum).LE_Z_cycavg_nystagmusCorrected;

                    LE_LARP_cycstd = app.filtData(app.fileNum).LE_LARP_cycstd_nystagmusCorrected;
                    LE_RALP_cycstd = app.filtData(app.fileNum).LE_RALP_cycstd_nystagmusCorrected;
                    LE_Z_cycstd = app.filtData(app.fileNum).LE_Z_cycstd_nystagmusCorrected;
                else % plot data uncorrect for nystagmus
                    RE_LARP_cycavg = app.filtData(app.fileNum).RE_LARP_cycavg;
                    RE_RALP_cycavg = app.filtData(app.fileNum).RE_RALP_cycavg;
                    RE_Z_cycavg = app.filtData(app.fileNum).RE_Z_cycavg;

                    RE_LARP_cycstd = app.filtData(app.fileNum).RE_LARP_cycstd;
                    RE_RALP_cycstd = app.filtData(app.fileNum).RE_RALP_cycstd;
                    RE_Z_cycstd = app.filtData(app.fileNum).RE_Z_cycstd;

                    LE_LARP_cycavg = app.filtData(app.fileNum).LE_LARP_cycavg;
                    LE_RALP_cycavg = app.filtData(app.fileNum).LE_RALP_cycavg;
                    LE_Z_cycavg = app.filtData(app.fileNum).LE_Z_cycavg;

                    LE_LARP_cycstd = app.filtData(app.fileNum).LE_LARP_cycstd;
                    LE_RALP_cycstd = app.filtData(app.fileNum).LE_RALP_cycstd;
                    LE_Z_cycstd = app.filtData(app.fileNum).LE_Z_cycstd;
                end
                
                axesHandle = app.CycleAverageAxes;
                if app.righteyecoilCheckBox_3.Value
                    if app.LHRHCheckBox_2.Value % plot Z if selected
                        trace_avg = RE_Z_cycavg;
                        trace_std = RE_Z_cycstd;
                        trace_color = [0.6350 0.0780 0.1840];

                        plotShadedError(app,axesHandle,t,trace_avg,trace_std,trace_color)
                    end

                    if app.LARPCheckBox_2.Value % plot LARP if selected
                        trace_avg = RE_LARP_cycavg;
                        trace_std = RE_LARP_cycstd;
                        trace_color = [0.4660 0.6740 0.1880];

                        plotShadedError(app,axesHandle,t,trace_avg,trace_std,trace_color)
                    end

                    if app.RALPCheckBox_2.Value % plot RALP if selected
                        trace_avg = RE_RALP_cycavg;
                        trace_std = RE_RALP_cycstd;
                        trace_color = [0 0.4470 0.7410];

                        plotShadedError(app,axesHandle,t,trace_avg,trace_std,trace_color)
                    end
                end


                if app.lefteyecoilCheckBox_3.Value
                    if app.LHRHCheckBox_2.Value % plot Z if selected
                        trace_avg = LE_Z_cycavg;
                        trace_std = LE_Z_cycstd;
                        trace_color = [0.6350 0.0780 0.1840];

                        plotShadedError(app,axesHandle,t,trace_avg,trace_std,trace_color)
                    end

                    if app.LARPCheckBox_2.Value % plot LARP if selected
                        trace_avg = LE_LARP_cycavg;
                        trace_std = LE_LARP_cycstd;
                        trace_color = [0.4660 0.6740 0.1880];

                        plotShadedError(app,axesHandle,t,trace_avg,trace_std,trace_color)
                    end

                    if app.RALPCheckBox_2.Value % plot RALP if selected
                        trace_avg = LE_RALP_cycavg;
                        trace_std = LE_RALP_cycstd;
                        trace_color = [0 0.4470 0.7410];

                        plotShadedError(app,axesHandle,t,trace_avg,trace_std,trace_color)
                    end
                end
                
            end

            ytickformat(app.CycleAverageAxes,'%+4.4g')
            xlim(app.CycleAverageAxes,'tight')
            ylim(app.CycleAverageAxes,'padded')
            xlim(app.WaveformAxes_2,'tight')
            ylim(app.WaveformAxes_2,'padded')
        end

        function setFilterParameterstoDefault(app,segmentNum,condition)
            if nargin < 3
                currentTab = char(app.TabGroup.SelectedTab.Title);
            else
                switch char(condition)
                    case "velocity"
                        currentTab = "4. Velocity Filtering";
                    case "position"
                        currentTab = "3. Position Filtering";
                end
            end

            switch currentTab
                case "3. Position Filtering"
                    app.filtData(segmentNum).LE_Position_X_filt = app.filtData(segmentNum).LE_Position_X; % this should be the same as the raw data
                    app.filtData(segmentNum).LE_Position_Y_filt = app.filtData(segmentNum).LE_Position_Y;
                    app.filtData(segmentNum).LE_Position_Z_filt = app.filtData(segmentNum).LE_Position_Z;

                    app.filtData(segmentNum).RE_Position_X_filt = app.filtData(segmentNum).RE_Position_X;
                    app.filtData(segmentNum).RE_Position_Y_filt = app.filtData(segmentNum).RE_Position_Y;
                    app.filtData(segmentNum).RE_Position_Z_filt = app.filtData(segmentNum).RE_Position_Z;

                    DataTrace = {'LEAngPosX';'LEAngPosY';'LEAngPosZ';...
                        'REAngPosX';'REAngPosY';'REAngPosZ'};
                    WindowSize = strings(6,1);
                    FilterOrder = strings(6,1);
                    Tau = strings(6,1);
                    app.filtData(segmentNum).FilteringParameters.Position.movingmedian = table(DataTrace,WindowSize);
                    app.filtData(segmentNum).FilteringParameters.Position.movingmean = table(DataTrace,WindowSize);
                    app.filtData(segmentNum).FilteringParameters.Position.savitzkygolay = table(DataTrace,WindowSize,FilterOrder);
                    app.filtData(segmentNum).FilteringParameters.Position.irlss = table(DataTrace,Tau);

                    app.filtData(segmentNum).FilteringParameters.Position.FilterAppliedFlag = 0;

                    % if position filtering is reset, reset velocity trace
                    app.filtData(segmentNum).LE_Velocity_LARP = app.rawData(segmentNum).LE_Velocity_LARP;
                    app.filtData(segmentNum).LE_Velocity_RALP = app.rawData(segmentNum).LE_Velocity_RALP;
                    app.filtData(segmentNum).LE_Velocity_X = app.rawData(segmentNum).LE_Velocity_X;
                    app.filtData(segmentNum).LE_Velocity_Y = app.rawData(segmentNum).LE_Velocity_Y;
                    app.filtData(segmentNum).LE_Velocity_Z = app.rawData(segmentNum).LE_Velocity_Z;

                    app.filtData(segmentNum).RE_Velocity_LARP = app.rawData(segmentNum).RE_Velocity_LARP;
                    app.filtData(segmentNum).RE_Velocity_RALP = app.rawData(segmentNum).RE_Velocity_RALP;
                    app.filtData(segmentNum).RE_Velocity_X = app.rawData(segmentNum).RE_Velocity_X;
                    app.filtData(segmentNum).RE_Velocity_Y = app.rawData(segmentNum).RE_Velocity_Y;
                    app.filtData(segmentNum).RE_Velocity_Z = app.rawData(segmentNum).RE_Velocity_Z;

                    setFilterParameterstoDefault(app,segmentNum,"velocity")

                case "4. Velocity Filtering"
                    app.filtData(segmentNum).LE_Velocity_LARP_filt = app.filtData(segmentNum).LE_Velocity_LARP;
                    app.filtData(segmentNum).LE_Velocity_RALP_filt = app.filtData(segmentNum).LE_Velocity_RALP;
                    app.filtData(segmentNum).LE_Velocity_X_filt = app.filtData(segmentNum).LE_Velocity_X;
                    app.filtData(segmentNum).LE_Velocity_Y_filt = app.filtData(segmentNum).LE_Velocity_Y;
                    app.filtData(segmentNum).LE_Velocity_Z_filt = app.filtData(segmentNum).LE_Velocity_Z;

                    app.filtData(segmentNum).RE_Velocity_LARP_filt = app.filtData(segmentNum).RE_Velocity_LARP;
                    app.filtData(segmentNum).RE_Velocity_RALP_filt = app.filtData(segmentNum).RE_Velocity_RALP;
                    app.filtData(segmentNum).RE_Velocity_X_filt = app.filtData(segmentNum).RE_Velocity_X;
                    app.filtData(segmentNum).RE_Velocity_Y_filt = app.filtData(segmentNum).RE_Velocity_Y;
                    app.filtData(segmentNum).RE_Velocity_Z_filt = app.filtData(segmentNum).RE_Velocity_Z;

                    DataTrace = {'LEAngVelLARP';'LEAngVelRALP';'LEAngVelZ';...
                        'LEAngVelX';'LEAngVelY';'REAngVelLARP';'REAngVelRALP';...
                        'REAngVelZ';'REAngVelX';'REAngVelY'};
                    WindowSize = strings(10,1);
                    FilterOrder = strings(10,1);
                    Tau = strings(10,1);
                    SplineTau = strings(10,1);
                    app.filtData(segmentNum).FilteringParameters.Velocity.movingmedian = table(DataTrace,WindowSize);
                    app.filtData(segmentNum).FilteringParameters.Velocity.movingmean = table(DataTrace,WindowSize);
                    app.filtData(segmentNum).FilteringParameters.Velocity.savitzkygolay = table(DataTrace,WindowSize,FilterOrder);
                    app.filtData(segmentNum).FilteringParameters.Velocity.irlss = table(DataTrace,Tau);
                    app.filtData(segmentNum).FilteringParameters.Velocity.spline = table(DataTrace,SplineTau);
                    app.filtData(segmentNum).FilteringParameters.Velocity.FilterAppliedFlag = 0;
            end
        end

        function displayPositionFilteringParameters(app)
            % only display data for selected eye
            if app.righteyecoilCheckBox.Value && app.lefteyecoilCheckBox.Value
                movingmedian_table = app.filtData(app.fileNum).FilteringParameters.Position.movingmedian;
                movingmean_table = app.filtData(app.fileNum).FilteringParameters.Position.movingmean;
                savitzkygolay_table = app.filtData(app.fileNum).FilteringParameters.Position.savitzkygolay;
                irlss_table = app.filtData(app.fileNum).FilteringParameters.Position.irlss;
            elseif app.righteyecoilCheckBox.Value
                movingmedian_table = app.filtData(app.fileNum).FilteringParameters.Position.movingmedian(4:6,:);
                movingmean_table = app.filtData(app.fileNum).FilteringParameters.Position.movingmean(4:6,:);
                savitzkygolay_table = app.filtData(app.fileNum).FilteringParameters.Position.savitzkygolay(4:6,:);
                irlss_table = app.filtData(app.fileNum).FilteringParameters.Position.irlss(4:6,:);
            elseif app.lefteyecoilCheckBox.Value
                movingmedian_table = app.filtData(app.fileNum).FilteringParameters.Position.movingmedian(1:3,:);
                movingmean_table = app.filtData(app.fileNum).FilteringParameters.Position.movingmean(1:3,:);
                savitzkygolay_table = app.filtData(app.fileNum).FilteringParameters.Position.savitzkygolay(1:3,:);
                irlss_table = app.filtData(app.fileNum).FilteringParameters.Position.irlss(1:3,:);

            end

            % display moving median table
            app.PositionFilterParamsTable_movingmedian.Data = movingmedian_table;
            app.PositionFilterParamsTable_movingmedian.ColumnName = movingmedian_table.Properties.VariableNames;
            app.PositionFilterParamsTable_movingmedian.ColumnWidth = {[90] [100]};
            movingmedian_table_position = ...
                [app.PositionFilterParamsTable_movingmedian.Position(1:2) ...
                sum([app.PositionFilterParamsTable_movingmedian.ColumnWidth{:}])+2 ...
                app.PositionFilterParamsTable_movingmedian.Position(4)];
            app.PositionFilterParamsTable_movingmedian.Position = movingmedian_table_position;
            app.PositionFilterParamsTable_movingmedian.ColumnEditable = true(1,size(movingmedian_table,1));
            app.PositionFilterParamsTable_movingmedian.ColumnEditable(1) = false;

            % display moving mean table
            app.PositionFilterParamsTable_movingmean.Data = movingmean_table;
            app.PositionFilterParamsTable_movingmean.ColumnName = movingmean_table.Properties.VariableNames;
            app.PositionFilterParamsTable_movingmean.ColumnWidth = {[90] [100]};
            movingmean_table_position = ...
                [app.PositionFilterParamsTable_movingmean.Position(1:2) ...
                sum([app.PositionFilterParamsTable_movingmean.ColumnWidth{:}])+2 ...
                app.PositionFilterParamsTable_movingmean.Position(4)];
            app.PositionFilterParamsTable_movingmean.Position = movingmean_table_position;
            app.PositionFilterParamsTable_movingmean.ColumnEditable = true(1,size(movingmean_table,1));
            app.PositionFilterParamsTable_movingmean.ColumnEditable(1) = false;


            % display savitzky-golay table
            app.PositionFilterParamsTable_savitzkygolay.Data = savitzkygolay_table;
            app.PositionFilterParamsTable_savitzkygolay.ColumnName = savitzkygolay_table.Properties.VariableNames;
            app.PositionFilterParamsTable_savitzkygolay.ColumnWidth = {[90] [100] [100]};
            savitzkygolay_table_position = ...
                [app.PositionFilterParamsTable_savitzkygolay.Position(1:2) ...
                sum([app.PositionFilterParamsTable_savitzkygolay.ColumnWidth{:}])+2 ...
                app.PositionFilterParamsTable_savitzkygolay.Position(4)];
            app.PositionFilterParamsTable_savitzkygolay.Position = savitzkygolay_table_position;
            app.PositionFilterParamsTable_savitzkygolay.ColumnEditable = true(1,size(savitzkygolay_table,1));
            app.PositionFilterParamsTable_savitzkygolay.ColumnEditable(1) = false;

            % display irlss table
            app.PositionFilterParamsTable_irlss.Data = irlss_table;
            app.PositionFilterParamsTable_irlss.ColumnName = irlss_table.Properties.VariableNames;
            app.PositionFilterParamsTable_irlss.ColumnWidth = {[90] [100] [100]};
            irlss_table_position = ...
                [app.PositionFilterParamsTable_irlss.Position(1:2) ...
                sum([app.PositionFilterParamsTable_irlss.ColumnWidth{:}])+2 ...
                app.PositionFilterParamsTable_irlss.Position(4)];
            app.PositionFilterParamsTable_irlss.Position = irlss_table_position;
            app.PositionFilterParamsTable_irlss.ColumnEditable = true(1,size(irlss_table,1));
            app.PositionFilterParamsTable_irlss.ColumnEditable(1) = false;

        end

        function updateFilteringParameters(app,condition)

            
            switch char(condition)
                case "position"
                    if app.righteyecoilCheckBox.Value && app.lefteyecoilCheckBox.Value
                        row_idx = 1:size(app.filtData(app.fileNum).FilteringParameters.Position.movingmedian,1);
                    elseif app.righteyecoilCheckBox.Value
                        row_idx = 4:6;
                    else % app.lefteyecoilCheckBox.Value
                        row_idx = 1:3;
                    end
                    app.filtData(app.fileNum).FilteringParameters.Position.movingmedian(row_idx,:) =  app.PositionFilterParamsTable_movingmedian.Data;
                    app.filtData(app.fileNum).FilteringParameters.Position.movingmean(row_idx,:) =  app.PositionFilterParamsTable_movingmean.Data;
                    app.filtData(app.fileNum).FilteringParameters.Position.savitzkygolay(row_idx,:) =  app.PositionFilterParamsTable_savitzkygolay.Data;
                    app.filtData(app.fileNum).FilteringParameters.Position.irlss(row_idx,:) =  app.PositionFilterParamsTable_irlss.Data;
                case "velocity"
                    if app.righteyecoilCheckBox_2.Value && app.lefteyecoilCheckBox_2.Value
                        row_idx = 1:size(app.filtData(app.fileNum).FilteringParameters.Velocity.movingmedian,1);
                    elseif app.righteyecoilCheckBox_2.Value
                        row_idx = 6:10;
                    else % app.lefteyecoilCheckBox.Value
                        row_idx = 1:5;
                    end
                    app.filtData(app.fileNum).FilteringParameters.Velocity.movingmedian(row_idx,:) =  app.VelocityFilterParamsTable_movingmedian.Data;
                    app.filtData(app.fileNum).FilteringParameters.Velocity.movingmean(row_idx,:) =  app.VelocityFilterParamsTable_movingmean.Data;
                    app.filtData(app.fileNum).FilteringParameters.Velocity.savitzkygolay(row_idx,:) =  app.VelocityFilterParamsTable_savitzkygolay.Data;
                    app.filtData(app.fileNum).FilteringParameters.Velocity.irlss(row_idx,:) =  app.VelocityFilterParamsTable_irlss.Data;
                    app.filtData(app.fileNum).FilteringParameters.Velocity.spline(row_idx,:) =  app.VelocityFilterParamsTable_spline.Data;
            end
        end

        function applyPositionFiltering(app)

            % reset filter parameters
            % velocity filtered traces will revert to unfiltered velocity 
            % (RE_Velocity_Z_filt = RE_Velocity_Z)
            if app.filtData(app.fileNum).FilteringParameters.Velocity.FilterAppliedFlag
                disp('Warning: The position filtering was changed, so the velocity filtering parameters were reset.')
            end
            
            setFilterParameterstoDefault(app,app.fileNum,"position")

            if app.applyPositionFilter1_CheckBox.Value
                % moving median
                windowSize = double(app.PositionFilterParamsTable_movingmedian.Data.WindowSize);
                dataTraceNames = app.PositionFilterParamsTable_movingmedian.Data.DataTrace;
                isnotnan = ~isnan(windowSize);
                windowSize = windowSize(isnotnan);
                dataTraceNames = dataTraceNames(isnotnan);
                for iTrace = 1:length(dataTraceNames)
                    switch char(dataTraceNames{iTrace})
                        case 'REAngPosX'
                            app.filtData(app.fileNum).RE_Position_X_filt = movmedian(app.filtData(app.fileNum).RE_Position_X_filt,windowSize(iTrace));
                        case 'REAngPosY'
                            app.filtData(app.fileNum).RE_Position_Y_filt = movmedian(app.filtData(app.fileNum).RE_Position_Y_filt,windowSize(iTrace));
                        case 'REAngPosZ'
                            app.filtData(app.fileNum).RE_Position_Z_filt = movmedian(app.filtData(app.fileNum).RE_Position_Z_filt,windowSize(iTrace));
                        case 'LEAngPosX'
                            app.filtData(app.fileNum).LE_Position_X_filt = movmedian(app.filtData(app.fileNum).LE_Position_X_filt,windowSize(iTrace));
                        case 'LEAngPosY'
                            app.filtData(app.fileNum).LE_Position_Y_filt = movmedian(app.filtData(app.fileNum).LE_Position_Y_filt,windowSize(iTrace));
                        case 'LEAngPosZ'
                            app.filtData(app.fileNum).LE_Position_Z_filt = movmedian(app.filtData(app.fileNum).LE_Position_Z_filt,windowSize(iTrace));
                    end
                end
            end

            % moving mean
            if app.applyPositionFilter2_CheckBox.Value
                windowSize = double(app.PositionFilterParamsTable_movingmean.Data.WindowSize);
                dataTraceNames = app.PositionFilterParamsTable_movingmean.Data.DataTrace;
                isnotnan = ~isnan(windowSize);
                windowSize = windowSize(isnotnan);
                dataTraceNames = dataTraceNames(isnotnan);

                for iTrace = 1:length(dataTraceNames)
                    switch char(dataTraceNames{iTrace})
                        case 'REAngPosX'
                            app.filtData(app.fileNum).RE_Position_X_filt = movmean(app.filtData(app.fileNum).RE_Position_X_filt,windowSize(iTrace));
                        case 'REAngPosY'
                            app.filtData(app.fileNum).RE_Position_Y_filt = movmean(app.filtData(app.fileNum).RE_Position_Y_filt,windowSize(iTrace));
                        case 'REAngPosZ'
                            app.filtData(app.fileNum).RE_Position_Z_filt = movmean(app.filtData(app.fileNum).RE_Position_Z_filt,windowSize(iTrace));
                        case 'LEAngPosX'
                            app.filtData(app.fileNum).LE_Position_X_filt = movmean(app.filtData(app.fileNum).LE_Position_X_filt,windowSize(iTrace));
                        case 'LEAngPosY'
                            app.filtData(app.fileNum).LE_Position_Y_filt = movmean(app.filtData(app.fileNum).LE_Position_Y_filt,windowSize(iTrace));
                        case 'LEAngPosZ'
                            app.filtData(app.fileNum).LE_Position_Z_filt = movmean(app.filtData(app.fileNum).LE_Position_Z_filt,windowSize(iTrace));
                    end
                end
            end

            % savitzky-golay
            if app.applyPositionFilter3_CheckBox.Value
                windowSize = double(app.PositionFilterParamsTable_savitzkygolay.Data.WindowSize);
                filterOrder = double(app.PositionFilterParamsTable_savitzkygolay.Data.FilterOrder);
                dataTraceNames = app.PositionFilterParamsTable_savitzkygolay.Data.DataTrace;
                isnotnan = sum(isnan([windowSize filterOrder]),2) == 0;
                windowSize = windowSize(isnotnan);
                dataTraceNames = dataTraceNames(isnotnan);
                filterOrder = filterOrder(isnotnan);

                for iTrace = 1:length(dataTraceNames)
                    switch char(dataTraceNames{iTrace})
                        case 'REAngPosX'
                            app.filtData(app.fileNum).RE_Position_X_filt = sgolayfilt(app.filtData(app.fileNum).RE_Position_X_filt,filterOrder(iTrace),windowSize(iTrace));
                        case 'REAngPosY'
                            app.filtData(app.fileNum).RE_Position_Y_filt = sgolayfilt(app.filtData(app.fileNum).RE_Position_Y_filt,filterOrder(iTrace),windowSize(iTrace));
                        case 'REAngPosZ'
                            app.filtData(app.fileNum).RE_Position_Z_filt = sgolayfilt(app.filtData(app.fileNum).RE_Position_Z_filt,filterOrder(iTrace),windowSize(iTrace));
                        case 'LEAngPosX'
                            app.filtData(app.fileNum).LE_Position_X_filt = sgolayfilt(app.filtData(app.fileNum).LE_Position_X_filt,filterOrder(iTrace),windowSize(iTrace));
                        case 'LEAngPosY'
                            app.filtData(app.fileNum).LE_Position_Y_filt = sgolayfilt(app.filtData(app.fileNum).LE_Position_Y_filt,filterOrder(iTrace),windowSize(iTrace));
                        case 'LEAngPosZ'
                            app.filtData(app.fileNum).LE_Position_Z_filt = sgolayfilt(app.filtData(app.fileNum).LE_Position_Z_filt,filterOrder(iTrace),windowSize(iTrace));
                    end
                end
            end

            % irlss
            if app.applyPositionFilter4_CheckBox.Value
            end

            app.filtData(app.fileNum).FilteringParameters.Position.FilterAppliedFlag = 1;

            % update filtered data

            % This system code marks that no additional manipulations of the raw data
            % are required.
            data_rot = 1;
            % If, regardless of the system used to record the eye movement data,
            % the data is presented in Fick angles, change the DAQ_code to '5'
            DAQ_code = 5;
            OutputFormat = 1; % fick

            Data_In.Data_LE_Pos_X = app.filtData(app.fileNum).LE_Position_X_filt;
            Data_In.Data_LE_Pos_Y = app.filtData(app.fileNum).LE_Position_Y_filt;
            Data_In.Data_LE_Pos_Z = app.filtData(app.fileNum).LE_Position_Z_filt;

            Data_In.Data_RE_Pos_X = app.filtData(app.fileNum).RE_Position_X_filt;
            Data_In.Data_RE_Pos_Y = app.filtData(app.fileNum).RE_Position_Y_filt;
            Data_In.Data_RE_Pos_Z = app.filtData(app.fileNum).RE_Position_Z_filt;

            Data_In.Fs = app.filtData(app.fileNum).Fs(1);

            [New_Ang_Vel] = voma__processeyemovements([],[],[],[],0,data_rot,DAQ_code,OutputFormat,Data_In);

            app.filtData(app.fileNum).LE_Position_X_filt = New_Ang_Vel.LE_Pos_X;
            app.filtData(app.fileNum).LE_Position_Y_filt = New_Ang_Vel.LE_Pos_Y;
            app.filtData(app.fileNum).LE_Position_Z_filt = New_Ang_Vel.LE_Pos_Z;
            app.filtData(app.fileNum).LE_Velocity_LARP = New_Ang_Vel.LE_Vel_LARP;
            app.filtData(app.fileNum).LE_Velocity_RALP = New_Ang_Vel.LE_Vel_RALP;
            app.filtData(app.fileNum).LE_Velocity_X = New_Ang_Vel.LE_Vel_X;
            app.filtData(app.fileNum).LE_Velocity_Y = New_Ang_Vel.LE_Vel_Y;
            app.filtData(app.fileNum).LE_Velocity_Z = New_Ang_Vel.LE_Vel_Z;

            app.filtData(app.fileNum).RE_Position_X_filt = New_Ang_Vel.RE_Pos_X;
            app.filtData(app.fileNum).RE_Position_Y_filt = New_Ang_Vel.RE_Pos_Y;
            app.filtData(app.fileNum).RE_Position_Z_filt = New_Ang_Vel.RE_Pos_Z;
            app.filtData(app.fileNum).RE_Velocity_LARP = New_Ang_Vel.RE_Vel_LARP;
            app.filtData(app.fileNum).RE_Velocity_RALP = New_Ang_Vel.RE_Vel_RALP;
            app.filtData(app.fileNum).RE_Velocity_X = New_Ang_Vel.RE_Vel_X;
            app.filtData(app.fileNum).RE_Velocity_Y = New_Ang_Vel.RE_Vel_Y;
            app.filtData(app.fileNum).RE_Velocity_Z = New_Ang_Vel.RE_Vel_Z;

            % update default velocity filtering parameters
            % unfiltered velocity data should reflect position filtering
            setFilterParameterstoDefault(app,app.fileNum,"velocity")
        end

        function displayVelocityFilteringParameters(app)
            % only display data for selected eye
            if app.righteyecoilCheckBox_2.Value && app.lefteyecoilCheckBox_2.Value
                movingmedian_table = app.filtData(app.fileNum).FilteringParameters.Velocity.movingmedian;
                movingmean_table = app.filtData(app.fileNum).FilteringParameters.Velocity.movingmean;
                savitzkygolay_table = app.filtData(app.fileNum).FilteringParameters.Velocity.savitzkygolay;
                irlss_table = app.filtData(app.fileNum).FilteringParameters.Velocity.irlss;
                spline_table = app.filtData(app.fileNum).FilteringParameters.Velocity.spline;
            elseif app.righteyecoilCheckBox_2.Value
                movingmedian_table = app.filtData(app.fileNum).FilteringParameters.Velocity.movingmedian(6:10,:);
                movingmean_table = app.filtData(app.fileNum).FilteringParameters.Velocity.movingmean(6:10,:);
                savitzkygolay_table = app.filtData(app.fileNum).FilteringParameters.Velocity.savitzkygolay(6:10,:);
                irlss_table = app.filtData(app.fileNum).FilteringParameters.Velocity.irlss(6:10,:);
                spline_table = app.filtData(app.fileNum).FilteringParameters.Velocity.spline(6:10,:);
            elseif app.lefteyecoilCheckBox_2.Value
                movingmedian_table = app.filtData(app.fileNum).FilteringParameters.Velocity.movingmedian(1:5,:);
                movingmean_table = app.filtData(app.fileNum).FilteringParameters.Velocity.movingmean(1:5,:);
                savitzkygolay_table = app.filtData(app.fileNum).FilteringParameters.Velocity.savitzkygolay(1:5,:);
                irlss_table = app.filtData(app.fileNum).FilteringParameters.Velocity.irlss(1:5,:);
                spline_table = app.filtData(app.fileNum).FilteringParameters.Velocity.spline(1:5,:);
            end

            % display moving median table
            app.VelocityFilterParamsTable_movingmedian.Data = movingmedian_table;
            app.VelocityFilterParamsTable_movingmedian.ColumnName = movingmedian_table.Properties.VariableNames;
            app.VelocityFilterParamsTable_movingmedian.ColumnWidth = {[90] [100]};
            movingmedian_table_position = ...
                [app.VelocityFilterParamsTable_movingmedian.Position(1:2) ...
                sum([app.VelocityFilterParamsTable_movingmedian.ColumnWidth{:}])+2 ...
                app.VelocityFilterParamsTable_movingmedian.Position(4)];
            app.VelocityFilterParamsTable_movingmedian.Position = movingmedian_table_position;
            app.VelocityFilterParamsTable_movingmedian.ColumnEditable = true(1,size(movingmedian_table,1));
            app.VelocityFilterParamsTable_movingmedian.ColumnEditable(1) = false;

            % display moving mean table
            app.VelocityFilterParamsTable_movingmean.Data = movingmean_table;
            app.VelocityFilterParamsTable_movingmean.ColumnName = movingmean_table.Properties.VariableNames;
            app.VelocityFilterParamsTable_movingmean.ColumnWidth = {[90] [100]};
            movingmean_table_position = ...
                [app.VelocityFilterParamsTable_movingmean.Position(1:2) ...
                sum([app.VelocityFilterParamsTable_movingmean.ColumnWidth{:}])+2 ...
                app.VelocityFilterParamsTable_movingmean.Position(4)];
            app.VelocityFilterParamsTable_movingmean.Position = movingmean_table_position;
            app.VelocityFilterParamsTable_movingmean.ColumnEditable = true(1,size(movingmean_table,1));
            app.VelocityFilterParamsTable_movingmean.ColumnEditable(1) = false;


            % display savitzky-golay table
            app.VelocityFilterParamsTable_savitzkygolay.Data = savitzkygolay_table;
            app.VelocityFilterParamsTable_savitzkygolay.ColumnName = savitzkygolay_table.Properties.VariableNames;
            app.VelocityFilterParamsTable_savitzkygolay.ColumnWidth = {[90] [100] [100]};
            savitzkygolay_table_position = ...
                [app.VelocityFilterParamsTable_savitzkygolay.Position(1:2) ...
                sum([app.VelocityFilterParamsTable_savitzkygolay.ColumnWidth{:}])+2 ...
                app.VelocityFilterParamsTable_savitzkygolay.Position(4)];
            app.VelocityFilterParamsTable_savitzkygolay.Position = savitzkygolay_table_position;
            app.VelocityFilterParamsTable_savitzkygolay.ColumnEditable = true(1,size(savitzkygolay_table,1));
            app.VelocityFilterParamsTable_savitzkygolay.ColumnEditable(1) = false;

            % display irlss table
            app.VelocityFilterParamsTable_irlss.Data = irlss_table;
            app.VelocityFilterParamsTable_irlss.ColumnName = irlss_table.Properties.VariableNames;
            app.VelocityFilterParamsTable_irlss.ColumnWidth = {[90] [100]};
            irlss_table_position = ...
                [app.VelocityFilterParamsTable_irlss.Position(1:2) ...
                sum([app.VelocityFilterParamsTable_irlss.ColumnWidth{:}])+2 ...
                app.VelocityFilterParamsTable_irlss.Position(4)];
            app.VelocityFilterParamsTable_irlss.Position = irlss_table_position;
            app.VelocityFilterParamsTable_irlss.ColumnEditable = true(1,size(irlss_table,1));
            app.VelocityFilterParamsTable_irlss.ColumnEditable(1) = false;

            % display spline table
            app.VelocityFilterParamsTable_spline.Data = spline_table;
            app.VelocityFilterParamsTable_spline.ColumnName = spline_table.Properties.VariableNames;
            app.VelocityFilterParamsTable_spline.ColumnWidth = {[90] [100]};
            spline_table_position = ...
                [app.VelocityFilterParamsTable_spline.Position(1:2) ...
                sum([app.VelocityFilterParamsTable_spline.ColumnWidth{:}])+2 ...
                app.VelocityFilterParamsTable_spline.Position(4)];
            app.VelocityFilterParamsTable_spline.Position = spline_table_position;
            app.VelocityFilterParamsTable_spline.ColumnEditable = true(1,size(spline_table,1));
            app.VelocityFilterParamsTable_spline.ColumnEditable(1) = false;

        end

        function applyVelocityFiltering(app)
            % reset filter parameters
            % setFilterParameterstoDefault(app,app.fileNum,"velocity")

            if app.applyVelocityFilter1_CheckBox.Value
                % moving median
                windowSize = double(app.VelocityFilterParamsTable_movingmedian.Data.WindowSize);
                dataTraceNames = app.VelocityFilterParamsTable_movingmedian.Data.DataTrace;
                isnotnan = ~isnan(windowSize);
                windowSize = windowSize(isnotnan);
                dataTraceNames = dataTraceNames(isnotnan);
                for iTrace = 1:length(dataTraceNames)
                    switch char(dataTraceNames{iTrace})
                        case 'REAngVelX'
                            app.filtData(app.fileNum).RE_Velocity_X_filt = movmedian(app.filtData(app.fileNum).RE_Velocity_X_filt,windowSize(iTrace));
                        case 'REAngVelY'
                            app.filtData(app.fileNum).RE_Velocity_Y_filt = movmedian(app.filtData(app.fileNum).RE_Velocity_Y_filt,windowSize(iTrace));
                        case 'REAngVelZ'
                            app.filtData(app.fileNum).RE_Velocity_Z_filt = movmedian(app.filtData(app.fileNum).RE_Velocity_Z_filt,windowSize(iTrace));
                        case 'REAngVelLARP'
                            app.filtData(app.fileNum).RE_Velocity_LARP_filt = movmedian(app.filtData(app.fileNum).RE_Velocity_LARP_filt,windowSize(iTrace));
                        case 'REAngVelRALP'
                            app.filtData(app.fileNum).RE_Velocity_RALP_filt = movmedian(app.filtData(app.fileNum).RE_Velocity_RALP_filt,windowSize(iTrace));
                        case 'LEAngVelX'
                            app.filtData(app.fileNum).LE_Velocity_X_filt = movmedian(app.filtData(app.fileNum).LE_Velocity_X_filt,windowSize(iTrace));
                        case 'LEAngVelY'
                            app.filtData(app.fileNum).LE_Velocity_Y_filt = movmedian(app.filtData(app.fileNum).LE_Velocity_Y_filt,windowSize(iTrace));
                        case 'LEAngVelZ'
                            app.filtData(app.fileNum).LE_Velocity_Z_filt = movmedian(app.filtData(app.fileNum).LE_Velocity_Z_filt,windowSize(iTrace));
                        case 'LEAngVelLARP'
                            app.filtData(app.fileNum).LE_Velocity_LARP_filt = movmedian(app.filtData(app.fileNum).LE_Velocity_LARP_filt,windowSize(iTrace));
                        case 'LEAngVelRALP'
                            app.filtData(app.fileNum).LE_Velocity_RALP_filt = movmedian(app.filtData(app.fileNum).LE_Velocity_RALP_filt,windowSize(iTrace));
                    end
                end
            end

            % moving mean
            windowSize = double(app.VelocityFilterParamsTable_movingmean.Data.WindowSize);
            dataTraceNames = app.VelocityFilterParamsTable_movingmean.Data.DataTrace;
            isnotnan = ~isnan(windowSize);
            windowSize = windowSize(isnotnan);
            dataTraceNames = dataTraceNames(isnotnan);
            for iTrace = 1:length(dataTraceNames)
                switch char(dataTraceNames{iTrace})
                    case 'REAngVelX'
                        app.filtData(app.fileNum).RE_Velocity_X_filt = movmean(app.filtData(app.fileNum).RE_Velocity_X_filt,windowSize(iTrace));
                    case 'REAngVelY'
                        app.filtData(app.fileNum).RE_Position_Y_filt = movmean(app.filtData(app.fileNum).RE_Velocity_Y_filt,windowSize(iTrace));
                    case 'REAngVelZ'
                        app.filtData(app.fileNum).RE_Position_Z_filt = movmean(app.filtData(app.fileNum).RE_Velocity_Z_filt,windowSize(iTrace));
                    case 'REAngVelLARP'
                        app.filtData(app.fileNum).RE_Position_LARP_filt = movmean(app.filtData(app.fileNum).RE_Velocity_LARP_filt,windowSize(iTrace));
                    case 'REAngVelRALP'
                        app.filtData(app.fileNum).RE_Position_RALP_filt = movmean(app.filtData(app.fileNum).RE_Velocity_RALP_filt,windowSize(iTrace));
                    case 'LEAngVelX'
                        app.filtData(app.fileNum).LE_Position_X_filt = movmean(app.filtData(app.fileNum).LE_Velocity_X_filt,windowSize(iTrace));
                    case 'LEAngVelY'
                        app.filtData(app.fileNum).LE_Position_Y_filt = movmean(app.filtData(app.fileNum).LE_Velocity_Y_filt,windowSize(iTrace));
                    case 'LEAngVelZ'
                        app.filtData(app.fileNum).LE_Position_Z_filt = movmean(app.filtData(app.fileNum).LE_Velocity_Z_filt,windowSize(iTrace));
                    case 'LEAngVelLARP'
                        app.filtData(app.fileNum).LE_Position_LARP_filt = movmean(app.filtData(app.fileNum).LE_Velocity_LARP_filt,windowSize(iTrace));
                    case 'LEAngVelRALP'
                        app.filtData(app.fileNum).LE_Position_RALP_filt = movmean(app.filtData(app.fileNum).LE_Velocity_RALP_filt,windowSize(iTrace));
                end
            end

            % savitzky-golay
            if app.applyVelocityFilter3_CheckBox.Value
                windowSize = double(app.VelocityFilterParamsTable_savitzkygolay.Data.WindowSize);
                filterOrder = double(app.VelocityFilterParamsTable_savitzkygolay.Data.FilterOrder);
                dataTraceNames = app.VelocityFilterParamsTable_savitzkygolay.Data.DataTrace;
                isnotnan = sum(isnan([windowSize filterOrder]),2) == 0;
                windowSize = windowSize(isnotnan);
                dataTraceNames = dataTraceNames(isnotnan);
                filterOrder = filterOrder(isnotnan);

                for iTrace = 1:length(dataTraceNames)
                    switch char(dataTraceNames{iTrace})
                        case 'REAngVelX'
                            app.filtData(app.fileNum).RE_Velocity_X_filt = sgolayfilt(app.filtData(app.fileNum).RE_Velocity_X_filt,filterOrder(iTrace),windowSize(iTrace));
                        case 'REAngVelY'
                            app.filtData(app.fileNum).RE_Velocity_Y_filt = sgolayfilt(app.filtData(app.fileNum).RE_Velocity_Y_filt,filterOrder(iTrace),windowSize(iTrace));
                        case 'REAngVelZ'
                            app.filtData(app.fileNum).RE_Velocity_Z_filt = sgolayfilt(app.filtData(app.fileNum).RE_Velocity_Z_filt,filterOrder(iTrace),windowSize(iTrace));
                        case 'REAngVelLARP'
                            app.filtData(app.fileNum).RE_Velocity_LARP_filt = sgolayfilt(app.filtData(app.fileNum).RE_Velocity_LARP_filt,filterOrder(iTrace),windowSize(iTrace));
                        case 'REAngVelRALP'
                            app.filtData(app.fileNum).RE_Velocity_RALP_filt = sgolayfilt(app.filtData(app.fileNum).RE_Velocity_RALP_filt,filterOrder(iTrace),windowSize(iTrace));
                        case 'LEAngVelX'
                            app.filtData(app.fileNum).LE_Velocity_X_filt = sgolayfilt(app.filtData(app.fileNum).LE_Velocity_X_filt,filterOrder(iTrace),windowSize(iTrace));
                        case 'LEAngVelY'
                            app.filtData(app.fileNum).LE_Velocity_Y_filt = sgolayfilt(app.filtData(app.fileNum).LE_Velocity_Y_filt,filterOrder(iTrace),windowSize(iTrace));
                        case 'LEAngVelZ'
                            app.filtData(app.fileNum).LE_Velocity_Z_filt = sgolayfilt(app.filtData(app.fileNum).LE_Velocity_Z_filt,filterOrder(iTrace),windowSize(iTrace));
                        case 'LEAngVelLARP'
                            app.filtData(app.fileNum).LE_Velocity_LARP_filt = sgolayfilt(app.filtData(app.fileNum).LE_Velocity_LARP_filt,filterOrder(iTrace),windowSize(iTrace));
                        case 'LEAngVelRALP'
                            app.filtData(app.fileNum).LE_Velocity_RALP_filt = sgolayfilt(app.filtData(app.fileNum).LE_Velocity_RALP_filt,filterOrder(iTrace),windowSize(iTrace));
                    end
                end
            end

            % irlss
            if app.applyVelocityFilter4_CheckBox.Value

                tau = double(app.VelocityFilterParamsTable_irlss.Data.Tau);
                dataTraceNames = app.VelocityFilterParamsTable_irlss.Data.DataTrace;
                isnotnan = ~isnan(tau);
                dataTraceNames = dataTraceNames(isnotnan);
                tau = tau(isnotnan);
                for iTrace = 1:length(dataTraceNames)
                    switch char(dataTraceNames{iTrace})
                        case 'REAngVelX'
                            app.filtData(app.fileNum).RE_Velocity_X_filt = voma__irlssmooth(app.filtData(app.fileNum).RE_Velocity_X_filt,tau(iTrace));
                        case 'REAngVelY'
                            app.filtData(app.fileNum).RE_Velocity_Y_filt = voma__irlssmooth(app.filtData(app.fileNum).RE_Velocity_Y_filt,tau(iTrace));
                        case 'REAngVelZ'
                            app.filtData(app.fileNum).RE_Velocity_Z_filt = voma__irlssmooth(app.filtData(app.fileNum).RE_Velocity_Z_filt,tau(iTrace));
                        case 'REAngVelLARP'
                            app.filtData(app.fileNum).RE_Velocity_LARP_filt = voma__irlssmooth(app.filtData(app.fileNum).RE_Velocity_LARP_filt,tau(iTrace));
                        case 'REAngVelRALP'
                            app.filtData(app.fileNum).RE_Velocity_RALP_filt = voma__irlssmooth(app.filtData(app.fileNum).RE_Velocity_RALP_filt,tau(iTrace));
                        case 'LEAngVelX'
                            app.filtData(app.fileNum).LE_Velocity_X_filt = voma__irlssmooth(app.filtData(app.fileNum).LE_Velocity_X_filt,tau(iTrace));
                        case 'LEAngVelY'
                            app.filtData(app.fileNum).LE_Velocity_Y_filt = voma__irlssmooth(app.filtData(app.fileNum).LE_Velocity_Y_filt,tau(iTrace));
                        case 'LEAngVelZ'
                            app.filtData(app.fileNum).LE_Velocity_Z_filt = voma__irlssmooth(app.filtData(app.fileNum).LE_Velocity_Z_filt,tau(iTrace));
                        case 'LEAngVelLARP'
                            app.filtData(app.fileNum).LE_Velocity_LARP_filt = voma__irlssmooth(app.filtData(app.fileNum).LE_Velocity_LARP_filt,tau(iTrace));
                        case 'LEAngVelRALP'
                            app.filtData(app.fileNum).LE_Velocity_RALP_filt = voma__irlssmooth(app.filtData(app.fileNum).LE_Velocity_RALP_filt,tau(iTrace));
                    end
                end
            end

            % spline
            if app.applyVelocityFilter5_CheckBox.Value

%                 tau = double(app.VelocityFilterParamsTable_irlss.Data.Tau);
%                 qpr_priority = double(app.VelocityFilterParamsTable_spline.Data.QuickPhaseMin);
                tau = double(app.VelocityFilterParamsTable_spline.Data.SplineTau);
                dataTraceNames = app.VelocityFilterParamsTable_spline.Data.DataTrace;
%                 isnotnan = sum(isnan([qpr_priority tau]),2) == 0;
                isnotnan = ~isnan(tau);
%                 qpr_priority = qpr_priority(isnotnan);
                dataTraceNames = dataTraceNames(isnotnan);
                tau = tau(isnotnan);

                traceLARP = app.filtData(app.fileNum).RE_Velocity_LARP_filt;
                traceRALP = app.filtData(app.fileNum).RE_Velocity_RALP_filt;
                traceZ = app.filtData(app.fileNum).RE_Velocity_Z_filt;

                trace_idx = contains(dataTraceNames, app.StimAxisDropDown.Value);

                    switch char(dataTraceNames{trace_idx})
                        case 'REAngVelX'
                            trace = app.filtData(app.fileNum).RE_Velocity_X_filt(1:end-1);
                            trace_noqp = qprfilt(app,trace,threshold(trace_idx));
                            trace_irlss = voma__irlssmooth(trace_noqp,tau(trace_idx));
                            app.filtData(app.fileNum).RE_Velocity_X_filt = [trace_irlss' app.filtData(app.fileNum).RE_Velocity_X_filt(end)];
                        case 'REAngVelY'
                            trace = app.filtData(app.fileNum).RE_Velocity_Y_filt(1:end-1);
                            trace_noqp = qprfilt(app,trace,threshold(trace_idx));
                            trace_irlss = voma__irlssmooth(trace_noqp,tau(trace_idx));
                            app.filtData(app.fileNum).RE_Velocity_Y_filt = [trace_irlss' app.filtData(app.fileNum).RE_Velocity_Y_filt(end)];
                        case 'REAngVelZ'
                            tau_in([2,3,1]) = tau; % make stim axis the priority
                            [traceZ_noqp, traceLARP_noqp, traceRALP_noqp] = qprfilt(app,traceZ,traceLARP,traceRALP,tau_in);
                        case 'REAngVelLARP'
                            trace = -app.filtData(app.fileNum).RE_Velocity_LARP_filt(1:end-1);
                            trace_noqp = qprfilt(app,trace,threshold(trace_idx));
%                             trace_irlss = voma__irlssmooth(trace_noqp,tau(trace_idx));
                            app.filtData(app.fileNum).RE_Velocity_LARP_filt = [-trace_noqp' app.filtData(app.fileNum).RE_Velocity_LARP_filt(end)];
                        case 'REAngVelRALP'
                            trace = -app.filtData(app.fileNum).RE_Velocity_RALP_filt(1:end-1);
                            trace_noqp = qprfilt(app,trace,threshold(trace_idx));
                            app.filtData(app.fileNum).RE_Velocity_RALP_filt = [-trace_noqp' app.filtData(app.fileNum).RE_Velocity_RALP_filt(end)];
                        case 'LEAngVelX'
                            app.filtData(app.fileNum).LE_Position_X_filt = sgolayfilt(app.filtData(app.fileNum).LE_Position_X_filt,filterOrder(trace_idx),windowSize(trace_idx));
                        case 'LEAngVelY'
                            app.filtData(app.fileNum).LE_Position_Y_filt = sgolayfilt(app.filtData(app.fileNum).LE_Position_Y_filt,filterOrder(trace_idx),windowSize(trace_idx));
                        case 'LEAngVelZ'
                            app.filtData(app.fileNum).LE_Position_Z_filt = sgolayfilt(app.filtData(app.fileNum).LE_Position_Z_filt,filterOrder(trace_idx),windowSize(trace_idx));
                        case 'LEAngVelLARP'
                            app.filtData(app.fileNum).LE_Position_LARP_filt = sgolayfilt(app.filtData(app.fileNum).LE_Position_LARP_filt,filterOrder(trace_idx),windowSize(trace_idx));
                        case 'LEAngVelRALP'
                            app.filtData(app.fileNum).LE_Position_RALP_filt = sgolayfilt(app.filtData(app.fileNum).LE_Position_RALP_filt,filterOrder(trace_idx),windowSize(trace_idx));
                    end

                    app.filtData(app.fileNum).RE_Velocity_LARP_filt = traceLARP_noqp;
                    app.filtData(app.fileNum).RE_Velocity_RALP_filt = traceRALP_noqp;
                    app.filtData(app.fileNum).RE_Velocity_Z_filt = traceZ_noqp;
                

            end

            app.filtData(app.fileNum).FilteringParameters.Velocity.FilterAppliedFlag = 1;

        end
        

        function [traceA_noqp traceB_noqp traceC_noqp] = qprfilt(app,traceA,traceB,traceC,tau)
            % traceA takes priority!

            windowSize = length(traceA)/15;
            threshold = 10;

            if contains(app.SelectfileListBox.Value,'DC')
                pat = "Offs" + digitsPattern + "uA";
            else
                pat = "Base" + digitsPattern + "pps";
            end
            offset = str2double(extract(extract(app.SelectfileListBox.Value,pat),digitsPattern));
            
            % if offset is still empty
            if isnan(offset) 
                % this happens if there is no offset information in the Spike2 flag
                % (the name of the segment file is the Spike2 flag)
                % if there is no offset information, then there is no
                % offset
                offset = 0;
            end

            pat = "_" + digitsPattern + "x";
            nCycles = str2double(extract(extract(app.SelectfileListBox.Value,pat),digitsPattern));
            waveform = repmat(app.filtData(app.fileNum).Waveform,1,nCycles) - offset;

            if contains(app.SelectfileListBox.Value,'DC')
                % for DC, excitation is caused by cathodic stim
                % this section is set up so that the template has the
                % opposite sign as the response
                % so, we need to flip the sign for DC waveforms
                waveform = -waveform;
            end

            traceA = traceA(1:length(waveform));
            traceB = traceB(1:length(waveform));
            traceC = traceC(1:length(waveform));
            nystagmus_offset = movmedian(traceA,windowSize);
            trace_nystagmusCorrected = traceA - nystagmus_offset;
            

            VOR_latency = 20; %ms
            waveform_wlatency = [zeros(1,VOR_latency) waveform(1:end-VOR_latency)];
            template = movmean(waveform_wlatency(1:length(waveform)),200);
            data_in(:,1) = 1:length(traceA);
            data_in(:,2) = trace_nystagmusCorrected.*sign(template');

            qp_found_idx = find(data_in(:,2) > threshold);
            D = diff([0,diff(qp_found_idx')==1,0]);
            start_qp_found = qp_found_idx(D>0);
            end_qp_found = qp_found_idx(D<0);

            pos_slope_idx = find(diff(data_in(:,2))>=0);
            neg_slope_idx = find(diff(data_in(:,2))<=0);

            qp_idx = [];
            for iQP = 1:length(start_qp_found)
                start_qp_temp = neg_slope_idx(find(neg_slope_idx < start_qp_found(iQP),1,'last'));
                end_qp_temp = pos_slope_idx(find(pos_slope_idx > end_qp_found(iQP),1,'first'));

                qp_idx = [qp_idx,start_qp_temp:end_qp_temp];
            end

            %             trace_noqp = trace;
            %             trace_noqp(qp_idx) = NaN;

            traceA_noqp_beforespline = traceA;
            traceA_noqp_beforespline(qp_idx,:) = NaN;

            traceB_noqp_beforespline = traceB;
            traceB_noqp_beforespline(qp_idx,:) = NaN;

            traceC_noqp_beforespline = traceC;
            traceC_noqp_beforespline(qp_idx,:) = NaN;

            xx = qp_idx;
            yyA = interp1(data_in(:,1),traceA_noqp_beforespline,xx,'pchip');
            yyB = interp1(data_in(:,1),traceB_noqp_beforespline,xx,'pchip');
            yyC = interp1(data_in(:,1),traceC_noqp_beforespline,xx,'pchip');

            traceA_noqp_afterspline = traceA;
            traceA_noqp_afterspline(qp_idx) = yyA;
            
            traceB_noqp_afterspline = traceB;
            traceB_noqp_afterspline(qp_idx) = yyB;

            traceC_noqp_afterspline = traceC;
            traceC_noqp_afterspline(qp_idx) = yyC;

            traceA_noqp = voma__irlssmooth(traceA_noqp_afterspline,tau(1));
            traceB_noqp = voma__irlssmooth(traceB_noqp_afterspline,tau(2));
            traceC_noqp = voma__irlssmooth(traceC_noqp_afterspline,tau(3));
        end

        function plotShadedError(app,axesHandle,t,trace_avg,trace_std,trace_color)
            f = figure('Visible','off');
            p.rz = shadedErrorBar(t,trace_avg,trace_std,'lineprops',...
                {'r-','MarkerFaceColor',trace_color});
            set(p.rz.edge,'LineWidth',1,'color',trace_color)
            set(p.rz.patch,'facecolor',trace_color)
            set(p.rz.mainLine,'color',trace_color)
            set(p.rz.mainLine,'LineWidth',2)
            set(p.rz.edge,'LineStyle','-')
            set(p.rz.mainLine,'LineStyle','-')
            copyobj(p.rz.mainLine, axesHandle)
            copyobj(p.rz.patch, axesHandle)
            copyobj(p.rz.edge, axesHandle)
            close(f)
        end
        
        function calculateEyeVel_Misalignment(app)
            % calculate maximum response to cathodic & anodic stim

            cyclestokeep = str2double(app.CyclesListBox.Value);

            RE_Vel_Z = app.filtData(app.fileNum).RE_Velocity_Z_Cycles(:,cyclestokeep);
            RE_Vel_LARP = app.filtData(app.fileNum).RE_Velocity_LARP_Cycles(:,cyclestokeep);
            RE_Vel_RALP = app.filtData(app.fileNum).RE_Velocity_RALP_Cycles(:,cyclestokeep);
            LE_Vel_Z = app.filtData(app.fileNum).LE_Velocity_Z_Cycles(:,cyclestokeep);
            LE_Vel_LARP = app.filtData(app.fileNum).LE_Velocity_LARP_Cycles(:,cyclestokeep);
            LE_Vel_RALP = app.filtData(app.fileNum).LE_Velocity_RALP_Cycles(:,cyclestokeep);
            
            t = (1:length(RE_Vel_Z));

            % get responses during stim, taking into account VOR latency
            offset = app.filtData(app.fileNum).Waveform(end);
            VOR_latency = 20; %ms
            stim_fromBaseline = [zeros(1,VOR_latency) app.filtData(app.fileNum).Waveform(1:end-VOR_latency) - offset];

            % flip waveform for PFM because increasing from baseline is
            % excitatory (it is inhibitory for iDC)
            currentfile = app.SelectfileListBox.Value;
            if contains(currentfile, 'PFM')
                stim_fromBaseline = -stim_fromBaseline;
            end

            % flip waveform for DC data collected on 20240503 because the
            % anode and cathode were accidentally flipped
            if contains(app.FileLocationEditField.Value, '20240503') && contains(currentfile, 'DC')
                stim_fromBaseline = -stim_fromBaseline;
            end

            if app.righteyecoilCheckBox_3.Value % right eye
                cathodic_trace_LARP = RE_Vel_LARP(stim_fromBaseline < 0,:);
                cathodic_trace_RALP = RE_Vel_RALP(stim_fromBaseline < 0,:);
                cathodic_trace_Z = RE_Vel_Z(stim_fromBaseline < 0,:);
                anodic_trace_LARP = RE_Vel_LARP(stim_fromBaseline > 0,:);
                anodic_trace_RALP = RE_Vel_RALP(stim_fromBaseline > 0,:);
                anodic_trace_Z = RE_Vel_Z(stim_fromBaseline > 0,:);

            else % left eye
                cathodic_trace_LARP = LE_Vel_LARP(stim_fromBaseline < 0,:);
                cathodic_trace_RALP = LE_Vel_RALP(stim_fromBaseline < 0,:);
                cathodic_trace_Z = LE_Vel_Z(stim_fromBaseline < 0,:);
                anodic_trace_LARP = LE_Vel_LARP(stim_fromBaseline > 0,:);
                anodic_trace_RALP = LE_Vel_RALP(stim_fromBaseline > 0,:);
                anodic_trace_Z = LE_Vel_Z(stim_fromBaseline > 0,:);

            end

            stimAxis = app.StimAxisDropDown.Value;

            % find dominant axis of response - not necessarily stim axis,
            % used to find time point to calculate response magnitude
            if matches(app.EarDropDown.Value,'left')
                idx_cath = find(median(cathodic_trace_LARP') >= -15 & median(cathodic_trace_RALP') >= -15 & median(cathodic_trace_Z') <= 15);
            else % is right ear implant
                idx_cath = find(median(cathodic_trace_LARP') <= 15 & median(cathodic_trace_RALP') <= 15 & median(cathodic_trace_Z') >= -15);
            end

            [~,idx] = max(sqrt(median(cathodic_trace_LARP(idx_cath),1).^2 + median(cathodic_trace_RALP(idx_cath),1).^2 + median(cathodic_trace_Z(idx_cath),1).^2));
            [~,dominantAxis_idx] = max(abs([cathodic_trace_LARP(idx_cath(idx)), cathodic_trace_RALP(idx_cath(idx)), cathodic_trace_Z(idx_cath(idx))]));
            % figure, plot(median(cathodic_trace_Z(idx_cath),1))

            switch dominantAxis_idx
                case 1
                    dominantAxis = "LARP";
                case 2
                    dominantAxis = "RALP";
                case 3 
                    dominantAxis = "Z";
            end

            % set stim axis vector - axis of stimulation, used to calculate
            % response misalignment 
            switch char(stimAxis)
                case "LARP"
                    if matches(app.EarDropDown.Value,'left')
                        stimAxis_vector_cathodic = [1 0 0];
                        stimAxis_vector_anodic = [-1 0 0];
                    else
                        stimAxis_vector_cathodic = [-1 0 0];
                        stimAxis_vector_anodic = [1 0 0];
                    end
                case "RALP"
                    if matches(app.EarDropDown.Value,'left')
                        stimAxis_vector_cathodic = [0 1 0];
                        stimAxis_vector_anodic = [0 -1 0];
                    else
                        stimAxis_vector_cathodic = [0 -1 0];
                        stimAxis_vector_anodic = [0 1 0];
                    end
                case "Z"
                    if matches(app.EarDropDown.Value,'left')
                        stimAxis_vector_cathodic = [0 0 -1];
                        stimAxis_vector_anodic = [0 0 1];
                    else
                        stimAxis_vector_cathodic = [0 0 1];
                        stimAxis_vector_anodic = [0 0 -1];
                    end
            end
            

            switch char(dominantAxis)
                case "LARP"
                    if ~isempty(cathodic_trace_LARP)
                        if matches(app.EarDropDown.Value,'left')
                            [~, idx] = max(cathodic_trace_LARP);
                            % LARPaxis_vector_cathodic = [1 0 0];
                        else % opposite for right ear
                            [~, idx] = min(cathodic_trace_LARP);
                            % LARPaxis_vector_cathodic = [-1 0 0];
                        end
                        idx = sub2ind(size(cathodic_trace_LARP),idx,1:length(idx));
                        cathodic_vector = [cathodic_trace_LARP(idx);...
                            cathodic_trace_RALP(idx);...
                            cathodic_trace_Z(idx)...
                            ];
                        maxEyeVel_cathodic = vecnorm(cathodic_vector);
                        for iCycle = 1:size(cathodic_vector,2)
                            misalignment_cathodic(iCycle) = rad2deg(atan2(norm(cross(cathodic_vector(:,iCycle),stimAxis_vector_cathodic)), dot(cathodic_vector(:,iCycle),stimAxis_vector_cathodic)));
                            % misalignment_cathodic(iCycle) = rad2deg(atan2(norm(cross(cathodic_vector(:,iCycle),LARPaxis_vector_cathodic)), dot(cathodic_vector(:,iCycle),LARPaxis_vector_cathodic)));
                        end
                    else
                        maxEyeVel_cathodic = NaN;
                        misalignment_cathodic = NaN;
                    end

                    % for thresholding, there is only a cathodic trapezoid
                    % (no anodic)
                    if ~isempty(anodic_trace_LARP)
                        if median(anodic_trace_LARP,'all') <= 0
                            [~, idx] = min(anodic_trace_LARP);
                            % for the left ear, 
                            % if the median is below zero, there is an
                            % inhibitory response, so find the point where
                            % that responses is maximized and calculate the
                            % magnitude and misalignment of the eye
                            % velocity
                            % if matches(app.EarDropDown.Value,'left')
                            %     LARPaxis_vector_anodic = [-1 0 0];
                            % else % opposite for right ear
                            %     LARPaxis_vector_anodic = [1 0 0];
                            % end
                        else
                            [~, idx] = max(anodic_trace_LARP);
                            % there is an excitatory response, so find the
                            % maximum in the excitatory
                            % direction, i.e. the max
                            % if matches(app.EarDropDown.Value,'left')
                            %     LARPaxis_vector_anodic = [-1 0 0];
                            % else % opposite for right ear
                            %     LARPaxis_vector_anodic = [1 0 0];
                            % end
                        end
                        
                        idx = sub2ind(size(anodic_trace_LARP),idx,1:length(idx));
                        anodic_vector = [anodic_trace_LARP(idx);...
                            anodic_trace_RALP(idx);...
                            anodic_trace_Z(idx)...
                            ];
                        maxEyeVel_anodic = vecnorm(anodic_vector);
                        for iCycle = 1:size(anodic_vector,2)
                            misalignment_anodic(iCycle) = rad2deg(atan2(norm(cross(anodic_vector(:,iCycle),stimAxis_vector_anodic)), dot(anodic_vector(:,iCycle),stimAxis_vector_anodic)));
                            % misalignment_anodic(iCycle) = rad2deg(atan2(norm(cross(anodic_vector(:,iCycle),LARPaxis_vector_anodic)), dot(anodic_vector(:,iCycle),LARPaxis_vector_anodic)));
                        end
                    else
                        maxEyeVel_anodic = NaN;
                        misalignment_anodic = NaN;
                    end
                case "RALP"
                    if ~isempty(cathodic_trace_RALP)
                        if matches(app.EarDropDown.Value,'left')
                            [~, idx] = max(cathodic_trace_RALP);
                            % RALPaxis_vector_cathodic = [0 1 0];
                        else % opposite for right ear
                            [~, idx] = min(cathodic_trace_RALP);
                            % RALPaxis_vector_cathodic = [0 -1 0];
                        end
                        idx = sub2ind(size(cathodic_trace_RALP),idx,1:length(idx));
                        cathodic_vector = [cathodic_trace_LARP(idx);...
                            cathodic_trace_RALP(idx);...
                            cathodic_trace_Z(idx)...
                            ];
                        maxEyeVel_cathodic = vecnorm(cathodic_vector);
                        for iCycle = 1:size(cathodic_vector,2)
                            misalignment_cathodic(iCycle) = rad2deg(atan2(norm(cross(cathodic_vector(:,iCycle),stimAxis_vector_cathodic)), dot(cathodic_vector(:,iCycle),stimAxis_vector_cathodic)));
                            % misalignment_cathodic(iCycle) = rad2deg(atan2(norm(cross(cathodic_vector(:,iCycle),RALPaxis_vector_cathodic)), dot(cathodic_vector(:,iCycle),RALPaxis_vector_cathodic)));
                        end
                    else
                        maxEyeVel_cathodic = NaN;
                        misalignment_cathodic = NaN;
                    end

                    % for thresholding, there is only a cathodic trapezoid
                    % (no anodic)
                    if ~isempty(anodic_trace_RALP)
                        if median(anodic_trace_RALP,'all') <= 0
                            [~, idx] = min(anodic_trace_RALP);
                            % for the left ear,
                            % if the median is below zero, there is an
                            % inhibitory response, so find the point where
                            % that responses is maximized and calculate the
                            % magnitude and misalignment of the eye
                            % velocity
                            % if matches(app.EarDropDown.Value,'left')
                            %     RALPaxis_vector_anodic = [0 -1 0];
                            % else % opposite for right ear (it is an excitatory response)
                            %     RALPaxis_vector_anodic = [0 1 0];
                            % end
                        else
                            [~, idx] = max(anodic_trace_RALP);
                            % there is an excitatory response, so the eye 
                            % is moving in the excitatory
                            % direction, i.e. the max
                            % if matches(app.EarDropDown.Value,'left')
                            %     RALPaxis_vector_anodic = [0 -1 0];
                            % else % opposite for right ear
                            %     RALPaxis_vector_anodic = [0 1 0];
                            % end
                        end
                        
                        idx = sub2ind(size(anodic_trace_RALP),idx,1:length(idx));
                        anodic_vector = [anodic_trace_LARP(idx);...
                            anodic_trace_RALP(idx);...
                            anodic_trace_Z(idx)...
                            ];
                        maxEyeVel_anodic = vecnorm(anodic_vector);
                        for iCycle = 1:size(anodic_vector,2)
                            misalignment_anodic(iCycle) = rad2deg(atan2(norm(cross(anodic_vector(:,iCycle),stimAxis_vector_anodic)), dot(anodic_vector(:,iCycle),stimAxis_vector_anodic)));
                            % misalignment_anodic(iCycle) = rad2deg(atan2(norm(cross(anodic_vector(:,iCycle),RALPaxis_vector_anodic)), dot(anodic_vector(:,iCycle),RALPaxis_vector_anodic)));
                        end
                    else
                        maxEyeVel_anodic = NaN;
                        misalignment_anodic = NaN;
                    end
                case "Z"
                    if ~isempty(cathodic_trace_Z)
                        if matches(app.EarDropDown.Value,'left')
                            [~, idx] = min(cathodic_trace_Z);
                            % Zaxis_vector_cathodic = [0 0 -1];
                        else % opposite for right ear
                            [~, idx] = max(cathodic_trace_Z);
                            % Zaxis_vector_cathodic = [0 0 1];
                        end
                        idx = sub2ind(size(cathodic_trace_Z),idx,1:length(idx));
                        cathodic_vector = [cathodic_trace_LARP(idx);...
                            cathodic_trace_RALP(idx);...
                            cathodic_trace_Z(idx)...
                            ];
                        maxEyeVel_cathodic = vecnorm(cathodic_vector);
                        for iCycle = 1:size(cathodic_vector,2)
                            misalignment_cathodic(iCycle) = rad2deg(atan2(norm(cross(cathodic_vector(:,iCycle),stimAxis_vector_cathodic)), dot(cathodic_vector(:,iCycle),stimAxis_vector_cathodic)));
                            % misalignment_cathodic(iCycle) = rad2deg(atan2(norm(cross(cathodic_vector(:,iCycle),Zaxis_vector_cathodic)), dot(cathodic_vector(:,iCycle),Zaxis_vector_cathodic)));
                        end
                    else
                        maxEyeVel_cathodic = NaN;
                        misalignment_cathodic = NaN;
                    end

                    % for thresholding, there is only a cathodic trapezoid
                    % (no anodic)
                    if ~isempty(anodic_trace_Z)
                        if median(anodic_trace_Z,'all') >= 0
                            [~, idx] = max(anodic_trace_Z);
                            % for the left ear,
                            % if the median is above zero, there is an
                            % inhibitory response, so find the point where
                            % that responses is maximized and calculate the
                            % magnitude and misalignment of the eye
                            % velocity
                            % if matches(app.EarDropDown.Value,'left')
                            %     Zaxis_vector_anodic = [0 0 1];
                            % else % opposite for the right ear
                            %     Zaxis_vector_anodic = [0 0 -1];
                            % end
                        else
                            [~, idx] = min(anodic_trace_Z);
                            % there is an excitatory response, so find the
                            % maximum in the excitatory (negative)
                            % direction, i.e. the minimum
                            % if matches(app.EarDropDown.Value,'left')
                            %     Zaxis_vector_anodic = [0 0 1];
                            % else % opposite for the right ear
                            %     Zaxis_vector_anodic = [0 0 -1];
                            % end
                        end
                        
                        idx = sub2ind(size(anodic_trace_Z),idx,1:length(idx));
                        anodic_vector = [anodic_trace_LARP(idx);...
                            anodic_trace_RALP(idx);...
                            anodic_trace_Z(idx)...
                            ];
                        maxEyeVel_anodic = vecnorm(anodic_vector);
                        for iCycle = 1:size(anodic_vector,2)
                            misalignment_anodic(iCycle) = rad2deg(atan2(norm(cross(anodic_vector(:,iCycle),stimAxis_vector_anodic)), dot(anodic_vector(:,iCycle),stimAxis_vector_anodic)));
                            % misalignment_anodic(iCycle) = rad2deg(atan2(norm(cross(anodic_vector(:,iCycle),Zaxis_vector_anodic)), dot(anodic_vector(:,iCycle),Zaxis_vector_anodic)));
                        end
                    else
                        maxEyeVel_anodic = NaN;
                        misalignment_anodic = NaN;
                    end
            end

            app.filtData(app.fileNum).EyeVel_Magnitude_Cathodic_avg = mean(maxEyeVel_cathodic);
            app.filtData(app.fileNum).EyeVel_Magnitude_Cathodic_std = std(maxEyeVel_cathodic);
            app.filtData(app.fileNum).EyeVel_Misalignment_Cathodic_avg = mean(misalignment_cathodic);
            app.filtData(app.fileNum).EyeVel_Misalignment_Cathodic_std = std(misalignment_cathodic);
            
            app.filtData(app.fileNum).EyeVel_Magnitude_Anodic_avg = mean(maxEyeVel_anodic);
            app.filtData(app.fileNum).EyeVel_Magnitude_Anodic_std = std(maxEyeVel_anodic);
            app.filtData(app.fileNum).EyeVel_Misalignment_Anodic_avg = mean(misalignment_anodic);
            app.filtData(app.fileNum).EyeVel_Misalignment_Anodic_std = std(misalignment_anodic);

            if app.shownystagmuscorrecteddataCheckBox.Value
                calculateEyeVel_Misalignment_nystagmusCorrected(app)
            end

        end
        
        function correctnystagmus(app,segmentNum)

            nCycles = app.filtData(app.fileNum).NumCycles;
            waveformInfo = split(app.SelectfileListBox.Value,{'x','ms'});
            % calculate cycle length for DC trapezoids
            if contains(app.SelectfileListBox.Value,'DCTrap')
                cycleLengthInfo = str2double(split(waveformInfo{2},{'+4_','_','+','.mat'}));
                cycleLength = sum(cycleLengthInfo(1:4)) + (cycleLengthInfo(5)*4);
            elseif contains(app.SelectfileListBox.Value,'PFMRamp')
                cycleLengthInfo = str2double(split(waveformInfo{2},{'_','+'}));
                cycleLength = sum([4*cycleLengthInfo(1),2*cycleLengthInfo(2),2*cycleLengthInfo(3)]);
            else % cycle length info for sines is included in flag name
                cycleLength = str2double(extractBetween(app.SelectfileListBox.Value,'x','_'));
            end
            waveformLength = cycleLength * nCycles;
            % correct for nystagmus
            data_temp = app.filtData(segmentNum).RE_Velocity_X_filt;
             %            windowSize = length(data_temp)/20;
            %             filt_data = movmedian(data_temp,windowSize);
            tau = length(data_temp)/10;
            filt_data = voma__irlssmooth(data_temp,tau);
            nystagmusCorrected = data_temp-filt_data;
            app.filtData(segmentNum).RE_Velocity_X_nystagmusCorrected = nystagmusCorrected;
            app.filtData(segmentNum).RE_Velocity_X_Cycles_nystagmusCorrected = reshape(nystagmusCorrected(1:waveformLength),[],nCycles);

            data_temp = app.filtData(segmentNum).RE_Velocity_Y_filt;
            %            windowSize = length(data_temp)/20;
            %             filt_data = movmedian(data_temp,windowSize);
            tau = length(data_temp)/10;
            filt_data = voma__irlssmooth(data_temp,tau);
            nystagmusCorrected = data_temp-filt_data;
            app.filtData(segmentNum).RE_Velocity_Y_nystagmusCorrected = nystagmusCorrected;
            app.filtData(segmentNum).RE_Velocity_Y_Cycles_nystagmusCorrected = reshape(nystagmusCorrected(1:waveformLength),[],nCycles);

            data_temp = app.filtData(segmentNum).RE_Velocity_LARP_filt;
             %            windowSize = length(data_temp)/20;
            %             filt_data = movmedian(data_temp,windowSize);
            tau = length(data_temp)/10;
            filt_data = voma__irlssmooth(data_temp,tau);
            nystagmusCorrected = data_temp-filt_data;
            app.filtData(segmentNum).RE_Velocity_LARP_nystagmusCorrected = nystagmusCorrected;
            app.filtData(segmentNum).RE_Velocity_LARP_Cycles_nystagmusCorrected = reshape(nystagmusCorrected(1:waveformLength),[],nCycles);

            data_temp = app.filtData(segmentNum).RE_Velocity_RALP_filt;
            %            windowSize = length(data_temp)/20;
            %             filt_data = movmedian(data_temp,windowSize);
            tau = length(data_temp)/10;
            filt_data = voma__irlssmooth(data_temp,tau);
            nystagmusCorrected = data_temp-filt_data;
            app.filtData(segmentNum).RE_Velocity_RALP_nystagmusCorrected = nystagmusCorrected;
            app.filtData(segmentNum).RE_Velocity_RALP_Cycles_nystagmusCorrected = reshape(nystagmusCorrected(1:waveformLength),[],nCycles);

            data_temp = app.filtData(segmentNum).RE_Velocity_Z_filt;
%            windowSize = length(data_temp)/20;
%             filt_data = movmedian(data_temp,windowSize);
            tau = length(data_temp)/10;
            filt_data = voma__irlssmooth(data_temp,tau);
            nystagmusCorrected = data_temp-filt_data;
            app.filtData(segmentNum).RE_Velocity_Z_nystagmusCorrected = nystagmusCorrected;
            app.filtData(segmentNum).RE_Velocity_Z_Cycles_nystagmusCorrected = reshape(nystagmusCorrected(1:waveformLength),[],nCycles);

            data_temp = app.filtData(segmentNum).LE_Velocity_X_filt;
            windowSize = length(data_temp)/20;
%             filt_data = movmedian(data_temp,windowSize);
            tau = length(data_temp)/10;
            filt_data = voma__irlssmooth(data_temp,tau);
            nystagmusCorrected = data_temp-filt_data;
            app.filtData(segmentNum).LE_Velocity_X_nystagmusCorrected = nystagmusCorrected;
            app.filtData(segmentNum).LE_Velocity_X_Cycles_nystagmusCorrected = reshape(nystagmusCorrected(1:waveformLength),[],nCycles);

            data_temp = app.filtData(segmentNum).LE_Velocity_Y_filt;
             windowSize = length(data_temp)/20;
%             filt_data = movmedian(data_temp,windowSize);
            tau = length(data_temp)/10;
            filt_data = voma__irlssmooth(data_temp,tau);
            nystagmusCorrected = data_temp-filt_data;
            app.filtData(segmentNum).LE_Velocity_Y_nystagmusCorrected = nystagmusCorrected;
            app.filtData(segmentNum).LE_Velocity_Y_Cycles_nystagmusCorrected = reshape(nystagmusCorrected(1:waveformLength),[],nCycles);

            data_temp = app.filtData(segmentNum).LE_Velocity_LARP_filt;
             windowSize = length(data_temp)/20;
%             filt_data = movmedian(data_temp,windowSize);
            tau = length(data_temp)/10;
            filt_data = voma__irlssmooth(data_temp,tau);
            nystagmusCorrected = data_temp-filt_data;
            app.filtData(segmentNum).LE_Velocity_LARP_nystagmusCorrected = nystagmusCorrected;
            app.filtData(segmentNum).LE_Velocity_LARP_Cycles_nystagmusCorrected = reshape(nystagmusCorrected(1:waveformLength),[],nCycles);

            data_temp = app.filtData(segmentNum).LE_Velocity_RALP_filt;
            windowSize = length(data_temp)/20;
%             filt_data = movmedian(data_temp,windowSize);
            tau = length(data_temp)/10;
            filt_data = voma__irlssmooth(data_temp,tau);
            nystagmusCorrected = data_temp-filt_data;
            app.filtData(segmentNum).LE_Velocity_RALP_nystagmusCorrected = nystagmusCorrected;
            app.filtData(segmentNum).LE_Velocity_RALP_Cycles_nystagmusCorrected = reshape(nystagmusCorrected(1:waveformLength),[],nCycles);

            data_temp = app.filtData(segmentNum).LE_Velocity_Z_filt;
             windowSize = length(data_temp)/20;
%             filt_data = movmedian(data_temp,windowSize);
            tau = length(data_temp)/10;
            filt_data = voma__irlssmooth(data_temp,tau);
            nystagmusCorrected = data_temp-filt_data;
            app.filtData(segmentNum).LE_Velocity_Z_nystagmusCorrected = nystagmusCorrected;
            app.filtData(segmentNum).LE_Velocity_Z_Cycles_nystagmusCorrected = reshape(nystagmusCorrected(1:waveformLength),[],nCycles);
        end
        
        function calculateCycleAverage(app)
            % calculate cycle average and standard deviation
            cyclestokeep = str2double(app.filtData(app.fileNum).KeepCycleNums);

            % right eye
            data_temp = app.filtData(app.fileNum).RE_Velocity_LARP_Cycles(:,cyclestokeep);
            app.filtData(app.fileNum).RE_LARP_cycavg = mean(data_temp,2);
            app.filtData(app.fileNum).RE_LARP_cycstd = std(data_temp,0,2);

            data_temp = app.filtData(app.fileNum).RE_Velocity_RALP_Cycles(:,cyclestokeep);
            app.filtData(app.fileNum).RE_RALP_cycavg = mean(data_temp,2);
            app.filtData(app.fileNum).RE_RALP_cycstd = std(data_temp,0,2);

            data_temp = app.filtData(app.fileNum).RE_Velocity_Z_Cycles(:,cyclestokeep);
            app.filtData(app.fileNum).RE_Z_cycavg = mean(data_temp,2);
            app.filtData(app.fileNum).RE_Z_cycstd = std(data_temp,0,2);

            % left eye
            data_temp = app.filtData(app.fileNum).LE_Velocity_LARP_Cycles(:,cyclestokeep);
            app.filtData(app.fileNum).LE_LARP_cycavg = mean(data_temp,2);
            app.filtData(app.fileNum).LE_LARP_cycstd = std(data_temp,0,2);

            data_temp = app.filtData(app.fileNum).LE_Velocity_RALP_Cycles(:,cyclestokeep);
            app.filtData(app.fileNum).LE_RALP_cycavg = mean(data_temp,2);
            app.filtData(app.fileNum).LE_RALP_cycstd = std(data_temp,0,2);

            data_temp = app.filtData(app.fileNum).LE_Velocity_Z_Cycles(:,cyclestokeep);
            app.filtData(app.fileNum).LE_Z_cycavg = mean(data_temp,2);
            app.filtData(app.fileNum).LE_Z_cycstd = std(data_temp,0,2);

            if app.shownystagmuscorrecteddataCheckBox.Value
                calculateCycleAverage_nystagmusCorrected(app)
            end

            % recalculate magnitude and misalignment
            calculateEyeVel_Misalignment(app)

            % calculate phase shift if sine data
            if strmatch(app.DataTypeDropDown.Value,'SineStim')
                if app.lefteyecoilCheckBox_3.Value % left eye coil
                    DataIn = sprintf('app.filtData(app.fileNum).LE_Velocity_%s_Cycles',app.StimAxisDropDown.Value);
                    calculatePhaseShift(app,DataIn,'left')
                end
                if app.righteyecoilCheckBox_3.Value % right eye coil
                    DataIn = sprintf('app.filtData(app.fileNum).RE_Velocity_%s_Cycles',app.StimAxisDropDown.Value);
                    calculatePhaseShift(app,DataIn,'right')
                end
            else
                app.filtData(app.fileNum).PhaseShift = NaN;
            end
        end
        
        function calculateCycleAverage_nystagmusCorrected(app)
            % calculate cycle average and standard deviation for nystagmus
            % corrected traces
            cyclestokeep = str2double(app.filtData(app.fileNum).KeepCycleNums);

            % right eye
            data_temp = app.filtData(app.fileNum).RE_Velocity_LARP_Cycles_nystagmusCorrected(:,cyclestokeep);
            app.filtData(app.fileNum).RE_LARP_cycavg_nystagmusCorrected = mean(data_temp,2);
            app.filtData(app.fileNum).RE_LARP_cycstd_nystagmusCorrected  = std(data_temp,0,2);

            data_temp = app.filtData(app.fileNum).RE_Velocity_RALP_Cycles_nystagmusCorrected(:,cyclestokeep);
            app.filtData(app.fileNum).RE_RALP_cycavg_nystagmusCorrected = mean(data_temp,2);
            app.filtData(app.fileNum).RE_RALP_cycstd_nystagmusCorrected = std(data_temp,0,2);

            data_temp = app.filtData(app.fileNum).RE_Velocity_Z_Cycles_nystagmusCorrected(:,cyclestokeep);
            app.filtData(app.fileNum).RE_Z_cycavg_nystagmusCorrected = mean(data_temp,2);
            app.filtData(app.fileNum).RE_Z_cycstd_nystagmusCorrected = std(data_temp,0,2);  

            % left eye
            data_temp = app.filtData(app.fileNum).LE_Velocity_LARP_Cycles_nystagmusCorrected(:,cyclestokeep);
            app.filtData(app.fileNum).LE_LARP_cycavg_nystagmusCorrected = mean(data_temp,2);
            app.filtData(app.fileNum).LE_LARP_cycstd_nystagmusCorrected  = std(data_temp,0,2);

            data_temp = app.filtData(app.fileNum).LE_Velocity_RALP_Cycles_nystagmusCorrected(:,cyclestokeep);
            app.filtData(app.fileNum).LE_RALP_cycavg_nystagmusCorrected = mean(data_temp,2);
            app.filtData(app.fileNum).LE_RALP_cycstd_nystagmusCorrected = std(data_temp,0,2);

            data_temp = app.filtData(app.fileNum).LE_Velocity_Z_Cycles_nystagmusCorrected(:,cyclestokeep);
            app.filtData(app.fileNum).LE_Z_cycavg_nystagmusCorrected = mean(data_temp,2);
            app.filtData(app.fileNum).LE_Z_cycstd_nystagmusCorrected = std(data_temp,0,2);
        end
        
        function calculateEyeVel_Misalignment_nystagmusCorrected(app)
            % calculate maximum response to cathodic & anodic stim

            cyclestokeep = str2double(app.CyclesListBox.Value);

            RE_Vel_Z_nystagmusCorrected = app.filtData(app.fileNum).RE_Velocity_Z_Cycles_nystagmusCorrected(:,cyclestokeep);
            RE_Vel_LARP_nystagmusCorrected = app.filtData(app.fileNum).RE_Velocity_LARP_Cycles_nystagmusCorrected(:,cyclestokeep);
            RE_Vel_RALP_nystagmusCorrected = app.filtData(app.fileNum).RE_Velocity_RALP_Cycles_nystagmusCorrected(:,cyclestokeep);
            LE_Vel_Z_nystagmusCorrected = app.filtData(app.fileNum).LE_Velocity_Z_Cycles_nystagmusCorrected(:,cyclestokeep);
            LE_Vel_LARP_nystagmusCorrected = app.filtData(app.fileNum).LE_Velocity_LARP_Cycles_nystagmusCorrected(:,cyclestokeep);
            LE_Vel_RALP_nystagmusCorrected = app.filtData(app.fileNum).LE_Velocity_RALP_Cycles_nystagmusCorrected(:,cyclestokeep);

            t = (1:length(RE_Vel_Z_nystagmusCorrected));

            % get responses during stim, taking into account VOR latency
            offset = app.filtData(app.fileNum).Waveform(end);
            VOR_latency = 20; %ms
            stim_fromBaseline = [zeros(1,VOR_latency) app.filtData(app.fileNum).Waveform(1:end-VOR_latency) - offset];

            % flip waveform for PFM because increasing from baseline is
            % excitatory (it is inhibitory for iDC)
            currentfile = app.SelectfileListBox.Value;
            if contains(currentfile, 'PFM')
                stim_fromBaseline = -stim_fromBaseline;
            end

            % flip waveform for DC data collected on 20240503 because the
            % anode and cathode were accidentally flipped
            if contains(app.FileLocationEditField.Value, '20240503') && contains(currentfile, 'DC')
                stim_fromBaseline = -stim_fromBaseline;
            end

            if app.righteyecoilCheckBox_3.Value % right eye
                cathodic_trace_LARP_nystagmusCorrected = RE_Vel_LARP_nystagmusCorrected(stim_fromBaseline < 0,:);
                cathodic_trace_RALP_nystagmusCorrected = RE_Vel_RALP_nystagmusCorrected(stim_fromBaseline < 0,:);
                cathodic_trace_Z_nystagmusCorrected = RE_Vel_Z_nystagmusCorrected(stim_fromBaseline < 0,:);
                anodic_trace_LARP_nystagmusCorrected = RE_Vel_LARP_nystagmusCorrected(stim_fromBaseline > 0,:);
                anodic_trace_RALP_nystagmusCorrected = RE_Vel_RALP_nystagmusCorrected(stim_fromBaseline > 0,:);
                anodic_trace_Z_nystagmusCorrected = RE_Vel_Z_nystagmusCorrected(stim_fromBaseline > 0,:);
            else % left eye
                cathodic_trace_LARP_nystagmusCorrected = LE_Vel_LARP_nystagmusCorrected(stim_fromBaseline < 0,:);
                cathodic_trace_RALP_nystagmusCorrected = LE_Vel_RALP_nystagmusCorrected(stim_fromBaseline < 0,:);
                cathodic_trace_Z_nystagmusCorrected = LE_Vel_Z_nystagmusCorrected(stim_fromBaseline < 0,:);
                anodic_trace_LARP_nystagmusCorrected = LE_Vel_LARP_nystagmusCorrected(stim_fromBaseline > 0,:);
                anodic_trace_RALP_nystagmusCorrected = LE_Vel_RALP_nystagmusCorrected(stim_fromBaseline > 0,:);
                anodic_trace_Z_nystagmusCorrected = LE_Vel_Z_nystagmusCorrected(stim_fromBaseline > 0,:);
            end

            stimAxis = app.StimAxisDropDown.Value;

            % find dominant axis of response - not necessarily stim axis,
            % used to find time point to calculate response magnitude
            if matches(app.EarDropDown.Value,'left')
                idx_cath = find(median(cathodic_trace_LARP_nystagmusCorrected') >= -10 & median(cathodic_trace_RALP_nystagmusCorrected') >= -10 & median(cathodic_trace_Z_nystagmusCorrected') <= 10);
            else % is right ear implant
                idx_cath = find(median(cathodic_trace_LARP_nystagmusCorrected') <= 10 & median(cathodic_trace_RALP_nystagmusCorrected') <= 10 & median(cathodic_trace_Z_nystagmusCorrected') >= -10);
            end

            [~,idx] = max(sqrt(cathodic_trace_LARP_nystagmusCorrected(idx_cath).^2 + cathodic_trace_RALP_nystagmusCorrected(idx_cath).^2 + cathodic_trace_Z_nystagmusCorrected(idx_cath).^2));
            [~,dominantAxis_idx] = max(abs([cathodic_trace_LARP_nystagmusCorrected(idx_cath(idx)), cathodic_trace_RALP_nystagmusCorrected(idx_cath(idx)), cathodic_trace_Z_nystagmusCorrected(idx_cath(idx))]));

            switch dominantAxis_idx
                case 1
                    dominantAxis = "LARP";
                case 2
                    dominantAxis = "RALP";
                case 3 
                    dominantAxis = "Z";
            end

            % set stim axis vector - axis of stimulation, used to calculate
            % response misalignment 
            switch char(stimAxis)
                case "LARP"
                    if matches(app.EarDropDown.Value,'left')
                        stimAxis_vector_cathodic = [1 0 0];
                        stimAxis_vector_anodic = [-1 0 0];
                    else
                        stimAxis_vector_cathodic = [-1 0 0];
                        stimAxis_vector_anodic = [1 0 0];
                    end
                case "RALP"
                    if matches(app.EarDropDown.Value,'left')
                        stimAxis_vector_cathodic = [0 1 0];
                        stimAxis_vector_anodic = [0 -1 0];
                    else
                        stimAxis_vector_cathodic = [0 -1 0];
                        stimAxis_vector_anodic = [0 1 0];
                    end
                case "Z"
                    if matches(app.EarDropDown.Value,'left')
                        stimAxis_vector_cathodic = [0 0 -1];
                        stimAxis_vector_anodic = [0 0 1];
                    else
                        stimAxis_vector_cathodic = [0 0 1];
                        stimAxis_vector_anodic = [0 0 -1];
                    end
            end

            switch char(dominantAxis)
                case "LARP"
                    if ~isempty(cathodic_trace_LARP_nystagmusCorrected)
                        if matches(app.EarDropDown.Value,'left')
                            [~, idx] = max(cathodic_trace_LARP_nystagmusCorrected);
                        else % opposite for right ear
                            [~, idx] = min(cathodic_trace_LARP_nystagmusCorrected);
                        end
                        idx = sub2ind(size(cathodic_trace_LARP_nystagmusCorrected),idx,1:length(idx));
                        cathodic_vector_nystagmusCorrected = [cathodic_trace_LARP_nystagmusCorrected(idx);...
                            cathodic_trace_RALP_nystagmusCorrected(idx);...
                            cathodic_trace_Z_nystagmusCorrected(idx)...
                            ];
                        if matches(app.EarDropDown.Value,'left')
                            [~, idx] = max(cathodic_trace_LARP_nystagmusCorrected);
                            % LARPaxis_vector_cathodic = [1 0 0];
                        else % opposite for right ear
                            [~, idx] = min(cathodic_trace_LARP_nystagmusCorrected);
                            % LARPaxis_vector_cathodic = [-1 0 0];
                        end
                        maxEyeVel_cathodic_nystagmusCorrected = vecnorm(cathodic_vector_nystagmusCorrected);
                        for iCycle = 1:size(cathodic_vector_nystagmusCorrected,2)
                            misalignment_cathodic_nystagmusCorrected(iCycle) = rad2deg(atan2(norm(cross(cathodic_vector_nystagmusCorrected(:,iCycle),stimAxis_vector_cathodic)), dot(cathodic_vector_nystagmusCorrected(:,iCycle),stimAxis_vector_cathodic)));
                            % misalignment_cathodic_nystagmusCorrected(iCycle) = rad2deg(atan2(norm(cross(cathodic_vector_nystagmusCorrected(:,iCycle),LARPaxis_vector_cathodic)), dot(cathodic_vector_nystagmusCorrected(:,iCycle),LARPaxis_vector_cathodic)));
                        end
                    else
                        maxEyeVel_cathodic_nystagmusCorrected = NaN;
                        misalignment_cathodic_nystagmusCorrected = NaN;
                    end

                    % for thresholding, there is only a cathodic trapezoid
                    % (no anodic)
                    if ~isempty(anodic_trace_LARP_nystagmusCorrected)

                        if median(anodic_trace_LARP_nystagmusCorrected,'all') <= 0
                            [~, idx] = min(anodic_trace_LARP_nystagmusCorrected);
                            % for the left ear, 
                            % if the median is below zero, there is an
                            % inhibitory response, so find the point where
                            % that responses is maximized and calculate the
                            % magnitude and misalignment of the eye
                            % velocity
                            % if matches(app.EarDropDown.Value,'left')
                            %     LARPaxis_vector_anodic = [-1 0 0];
                            % else % opposite for right ear
                            %     LARPaxis_vector_anodic = [1 0 0];
                            % end
                        else
                            [~, idx] = max(anodic_trace_LARP_nystagmusCorrected);
                            % there is an excitatory response, so find the
                            % maximum in the excitatory
                            % direction, i.e. the max
                            % if matches(app.EarDropDown.Value,'left')
                            %     LARPaxis_vector_anodic = [-1 0 0];
                            % else % opposite for right ear
                            %     LARPaxis_vector_anodic = [1 0 0];
                            % end
                        end

                        idx = sub2ind(size(anodic_trace_LARP_nystagmusCorrected),idx,1:length(idx));
                        anodic_vector_nystagmusCorrected = [anodic_trace_LARP_nystagmusCorrected(idx);...
                            anodic_trace_RALP_nystagmusCorrected(idx);...
                            anodic_trace_Z_nystagmusCorrected(idx)...
                            ];
                        
                        maxEyeVel_anodic_nystagmusCorrected = vecnorm(anodic_vector_nystagmusCorrected);
                        % centralTendency_vec = mean(anodic_vector_nystagmusCorrected,2);
                        % centralTendency_theta = rad2deg(atan2(norm(cross(mean(anodic_vector_nystagmusCorrected,2),LARPaxis_vector_anodic)), dot(mean(anodic_vector_nystagmusCorrected,2),LARPaxis_vector_anodic)));
                        for iCycle = 1:size(anodic_vector_nystagmusCorrected,2)
                            misalignment_anodic_nystagmusCorrected(iCycle) = rad2deg(atan2(norm(cross(anodic_vector_nystagmusCorrected(:,iCycle),stimAxis_vector_anodic)), dot(anodic_vector_nystagmusCorrected(:,iCycle),stimAxis_vector_anodic)));
                            % misalignment_anodic_nystagmusCorrected(iCycle) = rad2deg(atan2(norm(cross(anodic_vector_nystagmusCorrected(:,iCycle),LARPaxis_vector_anodic)), dot(anodic_vector_nystagmusCorrected(:,iCycle),LARPaxis_vector_anodic)));
                            % from https://www.mathworks.com/matlabcentral/answers/16243-angle-between-two-vectors-in-3d
                            % misalignment_fromCentralTendency_anodic_nystagmusCorrected(iCycle) = rad2deg(atan2(norm(cross(anodic_vector_nystagmusCorrected(:,iCycle),centralTendency_vec)), dot(anodic_vector_nystagmusCorrected(:,iCycle),centralTendency_vec)));
                        end
                    else
                        maxEyeVel_anodic_nystagmusCorrected = NaN;
                        misalignment_anodic_nystagmusCorrected = NaN;
                    end
                case "RALP"
                    if ~isempty(cathodic_trace_RALP_nystagmusCorrected)
                        if matches(app.EarDropDown.Value,'left')
                            [~, idx] = max(cathodic_trace_RALP_nystagmusCorrected);
                        else % opposite for right ear
                            [~, idx] = min(cathodic_trace_RALP_nystagmusCorrected);
                        end
                        idx = sub2ind(size(cathodic_trace_RALP_nystagmusCorrected),idx,1:length(idx));
                        cathodic_vector_nystagmusCorrected = [cathodic_trace_LARP_nystagmusCorrected(idx);...
                            cathodic_trace_RALP_nystagmusCorrected(idx);...
                            cathodic_trace_Z_nystagmusCorrected(idx)...
                            ];
                        if matches(app.EarDropDown.Value,'left')
                            [~, idx] = max(cathodic_trace_RALP_nystagmusCorrected);
                            % RALPaxis_vector_cathodic = [0 1 0];
                        else % opposite for right ear
                            [~, idx] = min(cathodic_trace_RALP_nystagmusCorrected);
                            % RALPaxis_vector_cathodic = [0 -1 0];
                        end
                        maxEyeVel_cathodic_nystagmusCorrected = vecnorm(cathodic_vector_nystagmusCorrected);
                        for iCycle = 1:size(cathodic_vector_nystagmusCorrected,2)
                            misalignment_cathodic_nystagmusCorrected(iCycle) = rad2deg(atan2(norm(cross(cathodic_vector_nystagmusCorrected(:,iCycle),stimAxis_vector_cathodic)), dot(cathodic_vector_nystagmusCorrected(:,iCycle),stimAxis_vector_cathodic)));
                            % misalignment_cathodic_nystagmusCorrected(iCycle) = rad2deg(atan2(norm(cross(cathodic_vector_nystagmusCorrected(:,iCycle),RALPaxis_vector_cathodic)), dot(cathodic_vector_nystagmusCorrected(:,iCycle),RALPaxis_vector_cathodic)));
                        end
                    else
                        maxEyeVel_cathodic_nystagmusCorrected = NaN;
                        misalignment_cathodic_nystagmusCorrected = NaN;
                    end

                    % for thresholding, there is only a cathodic trapezoid
                    % (no anodic)
                    if ~isempty(anodic_trace_RALP_nystagmusCorrected)

                        if median(anodic_trace_RALP_nystagmusCorrected,'all') <= 0
                            [~, idx] = min(anodic_trace_RALP_nystagmusCorrected);
                            % for the left ear,
                            % if the median is below zero, there is an
                            % inhibitory response, so find the point where
                            % that responses is maximized and calculate the
                            % magnitude and misalignment of the eye
                            % velocity
                            % if matches(app.EarDropDown.Value,'left')
                            %     RALPaxis_vector_anodic = [0 -1 0];
                            % else % opposite for right ear (it is an excitatory response)
                            %     RALPaxis_vector_anodic = [0 1 0];
                            % end
                        else
                            [~, idx] = max(anodic_trace_RALP_nystagmusCorrected);
                            % there is an excitatory response, so the eye 
                            % is moving in the excitatory
                            % direction, i.e. the max
                            % if matches(app.EarDropDown.Value,'left')
                            %     RALPaxis_vector_anodic = [0 -1 0];
                            % else % opposite for right ear
                            %     RALPaxis_vector_anodic = [0 1 0];
                            % end
                        end
                        
                        idx = sub2ind(size(anodic_trace_RALP_nystagmusCorrected),idx,1:length(idx));
                        anodic_vector_nystagmusCorrected = [anodic_trace_LARP_nystagmusCorrected(idx);...
                            anodic_trace_RALP_nystagmusCorrected(idx);...
                            anodic_trace_Z_nystagmusCorrected(idx)...
                            ];
                       
                        maxEyeVel_anodic_nystagmusCorrected = vecnorm(anodic_vector_nystagmusCorrected);
                        % centralTendency_vec = mean(anodic_vector_nystagmusCorrected,2);
                        % centralTendency_theta = rad2deg(atan2(norm(cross(mean(anodic_vector_nystagmusCorrected,2),RALPaxis_vector_anodic)), dot(mean(anodic_vector_nystagmusCorrected,2),RALPaxis_vector_anodic)));
                        for iCycle = 1:size(anodic_vector_nystagmusCorrected,2)
                            misalignment_anodic_nystagmusCorrected(iCycle) = rad2deg(atan2(norm(cross(anodic_vector_nystagmusCorrected(:,iCycle),stimAxis_vector_anodic)), dot(anodic_vector_nystagmusCorrected(:,iCycle),stimAxis_vector_anodic)));
                            % misalignment_anodic_nystagmusCorrected(iCycle) = rad2deg(atan2(norm(cross(anodic_vector_nystagmusCorrected(:,iCycle),RALPaxis_vector_anodic)), dot(anodic_vector_nystagmusCorrected(:,iCycle),RALPaxis_vector_anodic)));
                            % from https://www.mathworks.com/matlabcentral/answers/16243-angle-between-two-vectors-in-3d
                            % misalignment_fromCentralTendency_anodic_nystagmusCorrected(iCycle) = rad2deg(atan2(norm(cross(anodic_vector_nystagmusCorrected(:,iCycle),centralTendency_vec)), dot(anodic_vector_nystagmusCorrected(:,iCycle),centralTendency_vec)));
                        end
                    else
                        maxEyeVel_anodic_nystagmusCorrected = NaN;
                        misalignment_anodic_nystagmusCorrected = NaN;
                    end
                case "Z"
                    if ~isempty(cathodic_trace_Z_nystagmusCorrected)
                        if matches(app.EarDropDown.Value,'left')
                            [~, idx] = min(cathodic_trace_Z_nystagmusCorrected);
                        else % opposite for right ear
                            [~, idx] = max(cathodic_trace_Z_nystagmusCorrected);
                        end
                        idx = sub2ind(size(cathodic_trace_Z_nystagmusCorrected),idx,1:length(idx));
                        cathodic_vector_nystagmusCorrected = [cathodic_trace_LARP_nystagmusCorrected(idx);...
                            cathodic_trace_RALP_nystagmusCorrected(idx);...
                            cathodic_trace_Z_nystagmusCorrected(idx)...
                            ];
                        if matches(app.EarDropDown.Value,'left')
                            [~, idx] = min(cathodic_trace_Z_nystagmusCorrected);
                            % Zaxis_vector_cathodic = [0 0 -1];
                        else % opposite for right ear
                            [~, idx] = max(cathodic_trace_Z_nystagmusCorrected);
                            % Zaxis_vector_cathodic = [0 0 1];
                        end
                        maxEyeVel_cathodic_nystagmusCorrected = vecnorm(cathodic_vector_nystagmusCorrected);
                        for iCycle = 1:size(cathodic_vector_nystagmusCorrected,2)
                            misalignment_cathodic_nystagmusCorrected(iCycle) = rad2deg(atan2(norm(cross(cathodic_vector_nystagmusCorrected(:,iCycle),stimAxis_vector_cathodic)), dot(cathodic_vector_nystagmusCorrected(:,iCycle),stimAxis_vector_cathodic)));
                            % misalignment_cathodic_nystagmusCorrected(iCycle) = rad2deg(atan2(norm(cross(cathodic_vector_nystagmusCorrected(:,iCycle),Zaxis_vector_cathodic)), dot(cathodic_vector_nystagmusCorrected(:,iCycle),Zaxis_vector_cathodic)));
                        end
                    else
                        maxEyeVel_cathodic_nystagmusCorrected = NaN;
                        misalignment_cathodic_nystagmusCorrected = NaN;
                    end

                    % for thresholding, there is only a cathodic trapezoid
                    % (no anodic)
                    if ~isempty(anodic_trace_Z_nystagmusCorrected)

                        if median(anodic_trace_Z_nystagmusCorrected,'all') >= 0
                            [~, idx] = max(anodic_trace_Z_nystagmusCorrected);
                            % for the left ear,
                            % if the median is above zero, there is an
                            % inhibitory response, so find the point where
                            % that responses is maximized and calculate the
                            % magnitude and misalignment of the eye
                            % velocity
                            % if matches(app.EarDropDown.Value,'left')
                            %     Zaxis_vector_anodic = [0 0 1];
                            % else % opposite for the right ear
                            %     Zaxis_vector_anodic = [0 0 -1];
                            % end
                        else
                            [~, idx] = min(anodic_trace_Z_nystagmusCorrected);
                            % there is an excitatory response, so find the
                            % maximum in the excitatory (negative)
                            % direction, i.e. the minimum
                            % if matches(app.EarDropDown.Value,'left')
                            %     Zaxis_vector_anodic = [0 0 1];
                            % else % opposite for the right ear
                            %     Zaxis_vector_anodic = [0 0 -1];
                            % end
                        end

                        idx = sub2ind(size(anodic_trace_Z_nystagmusCorrected),idx,1:length(idx));
                        anodic_vector_nystagmusCorrected = [anodic_trace_LARP_nystagmusCorrected(idx);...
                            anodic_trace_RALP_nystagmusCorrected(idx);...
                            anodic_trace_Z_nystagmusCorrected(idx)...
                            ];

                        maxEyeVel_anodic_nystagmusCorrected = vecnorm(anodic_vector_nystagmusCorrected);
                        centralTendency_vec = mean(anodic_vector_nystagmusCorrected,2);
                        % centralTendency_theta = rad2deg(atan2(norm(cross(mean(anodic_vector_nystagmusCorrected,2),Zaxis_vector_anodic)), dot(mean(anodic_vector_nystagmusCorrected,2),Zaxis_vector_anodic)));
                        for iCycle = 1:size(anodic_vector_nystagmusCorrected,2)
                            misalignment_anodic_nystagmusCorrected(iCycle) = rad2deg(atan2(norm(cross(anodic_vector_nystagmusCorrected(:,iCycle),stimAxis_vector_anodic)), dot(anodic_vector_nystagmusCorrected(:,iCycle),stimAxis_vector_anodic)));
                            % misalignment_anodic_nystagmusCorrected(iCycle) = rad2deg(atan2(norm(cross(anodic_vector_nystagmusCorrected(:,iCycle),Zaxis_vector_anodic)), dot(anodic_vector_nystagmusCorrected(:,iCycle),Zaxis_vector_anodic)));
                            % from https://www.mathworks.com/matlabcentral/answers/16243-angle-between-two-vectors-in-3d
                            misalignment_fromCentralTendency_anodic_nystagmusCorrected(iCycle) = rad2deg(atan2(norm(cross(anodic_vector_nystagmusCorrected(:,iCycle),centralTendency_vec)), dot(anodic_vector_nystagmusCorrected(:,iCycle),centralTendency_vec)));
                        end
                    else
                        maxEyeVel_anodic_nystagmusCorrected = NaN;
                        misalignment_anodic_nystagmusCorrected = NaN;
                    end

            end

            app.filtData(app.fileNum).EyeVel_Magnitude_Cathodic_avg_nystagmusCorrected = mean(maxEyeVel_cathodic_nystagmusCorrected);
            app.filtData(app.fileNum).EyeVel_Magnitude_Cathodic_std_nystagmusCorrected = std(maxEyeVel_cathodic_nystagmusCorrected);
            app.filtData(app.fileNum).EyeVel_Misalignment_Cathodic_avg_nystagmusCorrected = mean(misalignment_cathodic_nystagmusCorrected);
            app.filtData(app.fileNum).EyeVel_Misalignment_Cathodic_std_nystagmusCorrected = std(misalignment_cathodic_nystagmusCorrected);

            app.filtData(app.fileNum).EyeVel_Magnitude_Anodic_avg_nystagmusCorrected = mean(maxEyeVel_anodic_nystagmusCorrected);
            app.filtData(app.fileNum).EyeVel_Magnitude_Anodic_std_nystagmusCorrected = std(maxEyeVel_anodic_nystagmusCorrected);
            app.filtData(app.fileNum).EyeVel_Misalignment_Anodic_avg_nystagmusCorrected = mean(misalignment_anodic_nystagmusCorrected);
            app.filtData(app.fileNum).EyeVel_Misalignment_Anodic_std_nystagmusCorrected = std(misalignment_anodic_nystagmusCorrected);
        end
        
        function loadSegments(app)

            filepath = app.FileLocationEditField.Value;
            filepath_segments = fullfile(filepath,"Segments");
            filepath_cycles = fullfile(filepath,"Cycles");
            segment_foldernames = app.SelectafolderListBox.Items;
            
            [~,chosenFolder_idx] = find(matches(app.SelectafolderListBox.Items, app.SelectafolderListBox.Value));
            if isempty(segment_foldernames)
                disp('There are no segmented files in the Segments folder.')
                return
            else
                % load files in first folder by default
                segmentnames = {dir(fullfile(filepath_segments,segment_foldernames{chosenFolder_idx},'*.mat')).name}';
                app.SelectafolderListBox.Items = segment_foldernames;
                app.SelectafolderListBox.Value = segment_foldernames{chosenFolder_idx};
                app.SelectfileListBox.Items = segmentnames;
                app.SelectfileListBox.Value = segmentnames{1};
            end

            % load segmented data
            nSegments = length(segmentnames);
            app.rawData = cell(nSegments,1);
            for iSegment = 1:nSegments
                app.rawData{iSegment} = load(fullfile(filepath_segments,segment_foldernames{chosenFolder_idx},segmentnames{iSegment})).Data;
            end
            app.rawData = [app.rawData{:}];
            [~,app.fileNum] = find(matches(app.SelectfileListBox.Items,app.SelectfileListBox.Value));

            % populate struct for filtered data
            % the filtered data variable has:
            %   raw position data                           (LE_Position_X)
            %   filtered position data                      (LE_Position_X_filt)
            %   velocity data based on filtered position    (LE_Velocity_X)
            %   velocity data after further filtering       (LE_Velocity_X_filt)

            %             app.filtData = app.rawData;
            %             app.filtData(1).KeepCycleNums = [];


            % load any analyzed cycle data
%             app.filtData = cell(nSegments,1);


            newFields = [{'KeepCycleNums'                   },...
                {'NumCycles'                       },...
                {'LE_Velocity_LARP_filt'           },...
                {'LE_Velocity_RALP_filt'           },...
                {'LE_Velocity_X_filt'              },...
                {'LE_Velocity_Y_filt'              },...
                {'LE_Velocity_Z_filt'              },...
                {'RE_Velocity_LARP_filt'           },...
                {'RE_Velocity_RALP_filt'           },...
                {'RE_Velocity_X_filt'              },...
                {'RE_Velocity_Y_filt'              },...
                {'RE_Velocity_Z_filt'              },...
                {'FilteringParameters'             },...
                {'LE_Position_X_filt'              },...
                {'LE_Position_Y_filt'              },...
                {'LE_Position_Z_filt'              },...
                {'RE_Position_X_filt'              },...
                {'RE_Position_Y_filt'              },...
                {'RE_Position_Z_filt'              },...
                {'RE_Velocity_X_nystagmusCorrected'            },...
                {'RE_Velocity_Y_nystagmusCorrected'            },...
                {'RE_Velocity_Z_nystagmusCorrected'            },...
                {'RE_Velocity_LARP_nystagmusCorrected'         },...
                {'RE_Velocity_RALP_nystagmusCorrected'         },...
                {'LE_Velocity_X_nystagmusCorrected'            },...
                {'LE_Velocity_Y_nystagmusCorrected'            },...
                {'LE_Velocity_Z_nystagmusCorrected'            },...
                {'LE_Velocity_LARP_nystagmusCorrected'         },...
                {'LE_Velocity_RALP_nystagmusCorrected'         },...
                {'RE_Velocity_X_Cycles'            },...
                {'RE_Velocity_Y_Cycles'            },...
                {'RE_Velocity_Z_Cycles'            },...
                {'RE_Velocity_LARP_Cycles'         },...
                {'RE_Velocity_RALP_Cycles'         },...
                {'LE_Velocity_X_Cycles'            },...
                {'LE_Velocity_Y_Cycles'            },...
                {'LE_Velocity_Z_Cycles'            },...
                {'LE_Velocity_LARP_Cycles'         },...
                {'LE_Velocity_RALP_Cycles'         },...
                {'RE_Velocity_X_Cycles_nystagmusCorrected'            },...
                {'RE_Velocity_Y_Cycles_nystagmusCorrected'            },...
                {'RE_Velocity_Z_Cycles_nystagmusCorrected'            },...
                {'RE_Velocity_LARP_Cycles_nystagmusCorrected'         },...
                {'RE_Velocity_RALP_Cycles_nystagmusCorrected'         },...
                {'LE_Velocity_X_Cycles_nystagmusCorrected'            },...
                {'LE_Velocity_Y_Cycles_nystagmusCorrected'            },...
                {'LE_Velocity_Z_Cycles_nystagmusCorrected'            },...
                {'LE_Velocity_LARP_Cycles_nystagmusCorrected'         },...
                {'LE_Velocity_RALP_Cycles_nystagmusCorrected'         },...
                {'Waveform'                        },...
                {'RE_LARP_cycavg'                  },...
                {'RE_RALP_cycavg'                  },...
                {'RE_Z_cycavg'                     },...
                {'RE_LARP_cycstd'                  },...
                {'RE_RALP_cycstd'                  },...
                {'RE_Z_cycstd'                     },...
                {'LE_LARP_cycavg'                  },...
                {'LE_RALP_cycavg'                  },...
                {'LE_Z_cycavg'                     },...
                {'LE_LARP_cycstd'                  },...
                {'LE_RALP_cycstd'                  },...
                {'LE_Z_cycstd'                     },...
                {'EyeVel_Magnitude_Cathodic_avg'   },...
                {'EyeVel_Magnitude_Cathodic_std'   },...
                {'EyeVel_Misalignment_Cathodic_avg'},...
                {'EyeVel_Misalignment_Cathodic_std'},...
                {'EyeVel_Magnitude_Anodic_avg'     },...
                {'EyeVel_Magnitude_Anodic_std'     },...
                {'EyeVel_Misalignment_Anodic_avg'  },...
                {'EyeVel_Misalignment_Anodic_std'  },...
                {'RE_LARP_cycavg_nystagmusCorrected'                  },...
                {'RE_RALP_cycavg_nystagmusCorrected'                  },...
                {'RE_Z_cycavg_nystagmusCorrected'                     },...
                {'RE_LARP_cycstd_nystagmusCorrected'                  },...
                {'RE_RALP_cycstd_nystagmusCorrected'                  },...
                {'RE_Z_cycstd_nystagmusCorrected'                     },...
                {'LE_LARP_cycavg_nystagmusCorrected'                  },...
                {'LE_RALP_cycavg_nystagmusCorrected'                  },...
                {'LE_Z_cycavg_nystagmusCorrected'                     },...
                {'LE_LARP_cycstd_nystagmusCorrected'                  },...
                {'LE_RALP_cycstd_nystagmusCorrected'                  },...
                {'LE_Z_cycstd_nystagmusCorrected'                     },...
                {'EyeVel_Magnitude_Cathodic_avg_nystagmusCorrected'   },...
                {'EyeVel_Magnitude_Cathodic_std_nystagmusCorrected'   },...
                {'EyeVel_Misalignment_Cathodic_avg_nystagmusCorrected'},...
                {'EyeVel_Misalignment_Cathodic_std_nystagmusCorrected'},...
                {'EyeVel_Magnitude_Anodic_avg_nystagmusCorrected'     },...
                {'EyeVel_Magnitude_Anodic_std_nystagmusCorrected'     },...
                {'EyeVel_Misalignment_Anodic_avg_nystagmusCorrected'  },...
                {'EyeVel_Misalignment_Anodic_std_nystagmusCorrected'  },...
                {'PhaseShift'}];

            f = [fieldnames(app.rawData)',newFields];
    
            f{2,1} = {};
            app.filtData = struct(f{:});

            analyzedCycleNames = {dir(fullfile(filepath_cycles,segment_foldernames{chosenFolder_idx},'*.mat')).name}';
            if ~isempty(analyzedCycleNames)
                for iSegment = 1:length(segmentnames)
                    segment_filename = extractBefore(segmentnames{iSegment},'.mat');

                    segmentNum = find(contains(analyzedCycleNames,segment_filename));

                    if isempty(segmentNum) && contains(segmentnames{iSegment},'_[')
                        % this means the cycle name might not have
                        % contained datetime info, look for cycle data that
                        % matches without timestamps
                        segment_filename = extractBefore(segmentnames{iSegment},'_[');
                        segmentNum = find(contains(analyzedCycleNames,segment_filename));

                        
                        % Previously, cycle data was saved without 
                        % timestamps, so we need to confirm that
                        % un-dated cycle data belongs to the selected
                        % segment:
                        % sometimes this is not the case (e.g. for standard
                        % waveforms) when the same stimulus is given at
                        % different times
                        if ~contains(analyzedCycleNames(segmentNum),'[') % checking that the saved cycle does not already have a timestamp
                            dlgTitle    = 'User Question';
                            dlgQuestion = [{'There is a cycle file that may match this segment:', segmentnames{iSegment}, 'that does not have a timestamp:', ...
                                segment_filename,...
                                'Has this segment already been analyzed?'}];
                            choice = questdlg(dlgQuestion,dlgTitle,'Yes','No, this waveform was repeated and this is new data.','Yes');

                            if strcmp(choice,'Yes') || contains(analyzedCycleNames(segmentNum),'[')
                                % we should rename the file so it has
                                % the correct timestamp info
                                old_path_to_file = fullfile(filepath_cycles,segment_foldernames{chosenFolder_idx},segment_filename);
                                new_filename = fullfile(filepath_cycles,segment_foldernames{chosenFolder_idx},segmentnames{iSegment});
                                cmd = "rename " + '"' + old_path_to_file + '" "' + new_filename + '"';
                                system(cmd);
                            else
                                % this is not the correct cycle data
                                % for the segment file, so the cycle name
                                % should include the timestamp
                                segment_filename = extractBefore(segmentnames{iSegment},'.mat');
                                segmentNum = find(contains(analyzedCycleNames,segment_filename));
                            end

                        else
                            % The saved (potential match) cycle data has a timestamp so 
                            % this is not the correct cycle data
                            % for the current segment file. The new cycle name
                            % should include the timestamp
                            segment_filename = extractBefore(segmentnames{iSegment},'.mat');
                            segmentNum = find(contains(analyzedCycleNames,segment_filename));
                        end
                        
                    end

                    if ~isempty(segmentNum)
                        try
                            app.filtData(iSegment) = load(fullfile(filepath_cycles,segment_foldernames{chosenFolder_idx},analyzedCycleNames{segmentNum})).Data;
                        catch
                            filtData_temp = load(fullfile(filepath_cycles,segment_foldernames{chosenFolder_idx},analyzedCycleNames{segmentNum})).Data;

                            newFields_partial = setdiff(fieldnames(app.filtData), fieldnames(filtData_temp));

                            for iNewField = 1:length(newFields_partial)
                                filtData_temp.(newFields_partial{iNewField}) = [];
                            end

                            app.filtData(iSegment) = filtData_temp;
                        end
                    else
                        filtData_temp = app.rawData(iSegment);

                        for iNewField = 1:length(newFields)
                            filtData_temp.(newFields{iNewField}) = [];
                        end

                        app.filtData(iSegment) = filtData_temp;

                        % filtered data will be the set to the raw data to start
                        % filtered data for position
                        setFilterParameterstoDefault(app,iSegment,"velocity")
                        setFilterParameterstoDefault(app,iSegment,"position")

%                         app.filtData{iSegment}.KeepCycleNums = [];
                    end
                end
            else
                for iSegment = 1:length(segmentnames)
                    filtData_temp = app.rawData(iSegment);

                    for iNewField = 1:length(newFields)
                        filtData_temp.(newFields{iNewField}) = [];
                    end

                    app.filtData(iSegment) = filtData_temp;

                    % filtered data will be the set to the raw data to start
                    % filtered data for position
                    setFilterParameterstoDefault(app,iSegment,"velocity")
                    setFilterParameterstoDefault(app,iSegment,"position")
                end
            end
%             app.filtData = [app.filtData{:}];
%             app.filtData(1).KeepCycleNums = [];

            % make plots
            % X/Y/Z position data for right eye coil is showing as default
            plotEyePosition(app)
            plotEyeVelocity(app)
            plotEyeVelocity_QP(app)
            plotEyeAcceleration(app)
            plotAllCycles(app)
            plotCycleAverage(app)

            % link waveform x axis to plots by default
            linkaxes([app.AllCyclesAxes,app.WaveformAxes],'x')
            linkaxes([app.CycleAverageAxes,app.WaveformAxes_2],'x')
            linkaxes([app.EyeAccelerationAxes,app.EyeVelocityAxes_QP],'x')

            figure(app.UIFigure)
        end
        
        function out = catStruct(app,in)
            if isstruct(in)
                for f = fields(in)'
                    try
                        if ischar([in.(f{:})]) || isstring([in.(f{:})])
                            out.(f{:}) = catStruct(app,in(1).(f{:}));
                        else
                            out.(f{:}) = catStruct(app,[in.(f{:})]);
                        end
                    catch
                        if ischar(vertcat(in.(f{:}))) || isstring(vertcat(in.(f{:})))
                            out.(f{:}) = catStruct(app,in(1).(f{:}));
                        else
                            out.(f{:}) = catStruct(app,vertcat(in.(f{:})));
                        end
                    end
                end
            else
                if length(in)>100 % if it is a data trace
                    in = reshape(in(1:end-1,:),[],1);
                end
                out = in;
            end
        end
        
        function calculatePhaseShift(app,DataIn,Eye)

            if strmatch(Eye,'left') % left eye coil
                DataIn = app.filtData(app.fileNum).(['LE_Velocity_',app.StimAxisDropDown.Value,'_nystagmusCorrected']);
                filtData_cycles = app.filtData(app.fileNum).(['LE_Velocity_',app.StimAxisDropDown.Value,'_Cycles_nystagmusCorrected']);
                DataIn = filtData_cycles(:,str2double(app.filtData(app.fileNum).KeepCycleNums));
            end
            if strmatch(Eye,'right')  % right eye coil
                DataIn = app.filtData(app.fileNum).(['RE_Velocity_',app.StimAxisDropDown.Value,'_nystagmusCorrected']);
                filtData_cycles = app.filtData(app.fileNum).(['RE_Velocity_',app.StimAxisDropDown.Value,'_Cycles_nystagmusCorrected']);

                if ~isempty(filtData_cycles)
                DataIn_cycles = filtData_cycles(:,str2double(app.filtData(app.fileNum).KeepCycleNums));

                T = length(app.filtData(app.fileNum).Waveform);
                w = (2*pi)/T;
                nCyclesKeep = length(app.filtData(app.fileNum).KeepCycleNums);
                X = reshape(DataIn_cycles,1,[]);
                %             X = reshape(DataIn_cycles,1,[]);
                waveform = repmat(app.filtData(app.fileNum).Waveform,1,nCyclesKeep);
                offset = waveform(1);
                waveform = waveform - offset;
                % flip the waveform if it is a PFM experiment
                if contains(app.SelectfileListBox.Value,'PFM') 
                    waveform = -waveform;
                end
                A_wave = (max(waveform) - min(waveform))/2;
                [~,minidx] = min(waveform);

                phaseShift = [];
                for iCycle = 1:length(waveform)/T
                    A = abs(min(DataIn_cycles(:,iCycle)));
                    for idx = round(((iCycle-1) * T) + minidx) %((iCycle-1) * T)+1:((iCycle-1) * T)+T
                        phi = w*(idx) - asin(X(idx)/A);
                        phi_wave = w*(idx) - asin(waveform(idx)/A_wave);
                        phaseShift = [phaseShift rad2deg(phi_wave-phi)];
                    end
                end
                app.filtData(app.fileNum).PhaseShift = mean(real(phaseShift));
                end
            end
% 
%             t = 1:length(DataIn_cycles);

%             mdl = fittype('a*sin(b*x+c)','indep','x');
%             waveformInfo = split(app.SelectfileListBox.Value,{'_','Hz'});
%             frequency_ms = str2double(strrep(waveformInfo{end-1},'p','.'))/1000;
%             eyeVel = max(abs(mean(DataIn_cycles,2)));
%             fittedmdl = fit(t',DataIn_cycles(:,1),mdl,'start',[eyeVel,frequency_ms,rand()]);
%             phaseShift = fittedmdl.c;


%             Fs = 1000;            % Sampling frequency
%             T = 1/Fs;             % Sampling period
%             L = length(X);             % Length of signal
%             t = (0:L-1)*T;        % Time vector
% 
%             Y = fft(X);
%             figure,
%             plot(Fs/L*(0:L-1),abs(Y),"LineWidth",3)
%             title("Complex Magnitude of fft Spectrum")
%             xlabel("f (Hz)")
%             ylabel("|fft(X)|")
% 
%             figure,
%             plot(Fs/L*(-L/2:L/2-1),abs(fftshift(Y)),"LineWidth",3)
%             title("fft Spectrum in the Positive and Negative Frequencies")
%             xlabel("f (Hz)")
%             ylabel("|fft(X)|")
% 
%             t  = 1:length(X);                                              % Time Vector
%             s  = X                                                 % Signal Vector
%             Ts = 1000;                                         % Sampling Time
%             Fs = 1/Ts;                                                  % Sampling Frequency
%             Fn = Fs/2;                                                  % Nyquist Frequency
%             L  = length(s);
%             fts = fft(s)/L;                                             % Normalised Fourier Transform
%             Fv = linspace(0, 1, fix(L/2)+1)*Fn;                         % Frequency Vector
%             Iv = 1:length(Fv);                                          % Index Vector
%             amp_fts = abs(fts(Iv))*2;                                   % Spectrum Amplitude
%             phs_fts = angle(fts(Iv));                                   % Spectrum Phase
% 
%             nCyclesKeep = length(app.filtData(app.fileNum).KeepCycleNums);
%             waveform = repmat(app.filtData(app.fileNum).Waveform,1,nCyclesKeep);
%             [c,lags] = xcorr(X,waveform);
%             figure, stem(lags,c)
%             [~,iLag] = max(c);
%             rad2deg((360/lags(iLag))/pi)


        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadFilesButton
        function LoadFilesButtonPushed(app, event)
            filepath = uigetdir('Select the root folder for the experiment:');

            % make sure there is a segments folder
            filepath_segments = fullfile(filepath,"Segments");
            if ~isfolder(filepath_segments)
                mkdir(filepath_segments)
            end

            % display selected file location in gui
            app.FileLocationEditField.Value = filepath;

            % make sure there are segment files and display them
            d = dir(filepath_segments);
            isSubfolder = [d(:).isdir];
            segment_foldernames = {d(isSubfolder).name}';
            segment_foldernames(ismember(segment_foldernames,{'.','..'})) = [];
            app.SelectafolderListBox.Items = segment_foldernames;
            app.SelectafolderListBox.Value = segment_foldernames{1};
            
            if isempty(segment_foldernames)
                disp('There are no segmented files in the Segments folder.')
            else
                % load segmented data
                loadSegments(app)
            end

            % make sure there is a cycles folder
            filepath_cycles = fullfile(filepath,"Cycles");
            if ~isfolder(filepath_cycles)
                mkdir(filepath_cycles)
                for iFolder = 1:length(segment_foldernames)
                    mkdir(fullfile(filepath_cycles,segment_foldernames(iFolder)))
                end
            end
        end

        % Callback function: FileLocationEditField, FileLocationEditField
        function FileLocationEditFieldValueChanging(app, event)

        end

        % Value changed function: SelectfileListBox
        function SelectfileListBoxValueChanged(app, event)
            value = app.SelectfileListBox.Value;
            [~,app.fileNum] = find(matches(app.SelectfileListBox.Items,value));

            app.shownystagmuscorrecteddataCheckBox.Value = 0;
            app.plotcycleaverageCheckBox.Value = 0;

            % replot data
            plotEyePosition(app)
            plotEyeVelocity(app)
            plotEyeVelocity_QP(app)
            plotEyeAcceleration(app)
            plotAllCycles(app)
            plotCycleAverage(app)
        end

        % Value changed function: ZCheckBox
        function ZCheckBoxValueChanged(app, event)
            plotEyePosition(app)
        end

        % Value changed function: YCheckBox_2
        function YCheckBox_2ValueChanged(app, event)
            plotEyePosition(app)
        end

        % Value changed function: XCheckBox_2
        function XCheckBox_2ValueChanged(app, event)
            plotEyePosition(app)
        end

        % Value changed function: righteyecoilCheckBox
        function righteyecoilCheckBoxValueChanged(app, event)
            plotEyePosition(app)
        end

        % Value changed function: lefteyecoilCheckBox
        function lefteyecoilCheckBoxValueChanged(app, event)
            plotEyePosition(app)
        end

        % Value changed function: XCheckBox_3
        function XCheckBox_3ValueChanged(app, event)
            plotEyeVelocity(app)
        end

        % Value changed function: YCheckBox_3
        function YCheckBox_3ValueChanged(app, event)
            plotEyeVelocity(app)
        end

        % Value changed function: LARPCheckBox_3
        function LARPCheckBox_3ValueChanged(app, event)
            plotEyeVelocity(app)
        end

        % Value changed function: RALPCheckBox_3
        function RALPCheckBox_3ValueChanged(app, event)
            plotEyeVelocity(app)
        end

        % Value changed function: LHRHZCheckBox_5
        function LHRHZCheckBox_5ValueChanged(app, event)
            plotEyeVelocity(app)
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            % make sure filtering parameters tabs are closed
            CloseButton_PositionPushed(app)
            CloseButton_VelocityPushed(app)
            app.shownystagmuscorrecteddataCheckBox.Value = 0;
            app.plotcycleaverageCheckBox.Value = 0;

        end

        % Button pushed function: NextFileButton
        function NextFileButtonPushed(app, event)
            if app.fileNum < length(app.SelectfileListBox.Items)
                newFileNum = app.fileNum + 1;
                app.SelectfileListBox.Value = app.SelectfileListBox.Items{newFileNum};
                app.fileNum = newFileNum;

                app.shownystagmuscorrecteddataCheckBox.Value = 0;
                app.plotcycleaverageCheckBox.Value = 0;

                % plot data
                plotEyePosition(app)
                plotEyeVelocity(app)
                plotEyeVelocity_QP(app)
                plotEyeAcceleration(app)
                plotAllCycles(app)
                plotCycleAverage(app)
            end
        end

        % Button pushed function: PreviousFileButton
        function PreviousFileButtonPushed(app, event)
            if app.fileNum > 1
                newFileNum = app.fileNum - 1;
                app.SelectfileListBox.Value = app.SelectfileListBox.Items{newFileNum};
                app.fileNum = newFileNum;

                % plot correct plot for tab
                selectedTab = app.TabGroup.SelectedTab;
                switch char(selectedTab.Title)
                    case "2b. Position Filtering"
                        plotEyePosition(app)
                    case "2c. QP Removal"
                        plotEyeVelocity_QP(app)
                        plotEyeAcceleration(app)
                    case "2d. Velocity Filtering"
                        plotEyeVelocity(app)
                    case "2e. Cycle Filtering"
                        plotAllCycles(app)
                        plotCycleAverage(app)
                end
            end
        end

        % Callback function
        function filtertypeDropDown_4ValueChanged(app, event)
            value = app.filtertypeDropDown_4.Value;
            switch char(value)
                case "moving median"
                    app.filterorderEditField_2.Visible = 'off';
                    app.filterorderEditField_2Label.Visible = 'off';
                case "moving mean"
                    app.filterorderEditField_2.Visible = 'off';
                    app.filterorderEditField_2Label.Visible = 'off';
                case "Savitzky-Golay"
                    app.filterorderEditField_2.Visible = 'on';
                    app.filterorderEditField_2Label.Visible = 'on';
                case "iteratively reweighted least-squares smoother"
                    app.filterorderEditField_2.Visible = 'on';
                    app.filterorderEditField_2Label.Visible = 'on';
            end
        end

        % Callback function
        function ApplyFilteringButton_2Pushed(app, event)
            switch char(app.filtertypeDropDown_4.Value)
                case "moving median"
                    windowSize = str2double(app.windowsizeEditField_2.Value);

                    if contains(app.filtertypeDropDown_3.Value,'LE') % apply filtering to left eye data
                        if contains(app.filtertypeDropDown_3.Value,'X')
                            app.filtData(app.fileNum).LE_Position_X_filt = movmedian(app.filtData(app.fileNum).LE_Position_X_filt,windowSize);
                        elseif contains(app.filtertypeDropDown_3.Value,'Y')
                            app.filtData(app.fileNum).LE_Position_Y_filt = movmedian(app.filtData(app.fileNum).LE_Position_Y_filt,windowSize);
                        elseif contains(app.filtertypeDropDown_3.Value,'Z')
                            app.filtData(app.fileNum).LE_Position_Z_filt = movmedian(app.filtData(app.fileNum).LE_Position_Z_filt,windowSize);
                        end
                    else % apply filtering to right eye data
                        if contains(app.filtertypeDropDown_3.Value,'X')
                            app.filtData(app.fileNum).RE_Position_X_filt = movmedian(app.filtData(app.fileNum).RE_Position_X_filt,windowSize);
                        elseif contains(app.filtertypeDropDown_3.Value,'Y')
                            app.filtData(app.fileNum).RE_Position_Y_filt = movmedian(app.filtData(app.fileNum).RE_Position_Y_filt,windowSize);
                        elseif contains(app.filtertypeDropDown_3.Value,'Z')
                            app.filtData(app.fileNum).RE_Position_Z_filt = movmedian(app.filtData(app.fileNum).RE_Position_Z_filt,windowSize);
                        end
                    end

                    % update filter parameters
                    selectedData = regexprep(app.filtertypeDropDown_3.Value,{'-';' ';'[.]'},'');
                    app.filtData(app.fileNum).FilteringParameters.Position.(selectedData).WindowSize(1) = windowSize;

                case "moving mean"
                    windowSize = str2double(app.windowsizeEditField_2.Value);

                    if contains(app.filtertypeDropDown_3.Value,'LE') % apply filtering to left eye data
                        if contains(app.filtertypeDropDown_3.Value,'X')
                            app.filtData(app.fileNum).LE_Position_X_filt = movmean(app.filtData(app.fileNum).LE_Position_X_filt,windowSize);
                        elseif contains(app.filtertypeDropDown_3.Value,'Y')
                            app.filtData(app.fileNum).LE_Position_Y_filt = movmean(app.filtData(app.fileNum).LE_Position_Y_filt,windowSize);
                        elseif contains(app.filtertypeDropDown_3.Value,'Z')
                            app.filtData(app.fileNum).LE_Position_Z_filt = movmean(app.filtData(app.fileNum).LE_Position_Z_filt,windowSize);
                        end
                    else % apply filtering to right eye data
                        if contains(app.filtertypeDropDown_3.Value,'X')
                            app.filtData(app.fileNum).RE_Position_X_filt = movmean(app.filtData(app.fileNum).RE_Position_X_filt,windowSize);
                        elseif contains(app.filtertypeDropDown_3.Value,'Y')
                            app.filtData(app.fileNum).RE_Position_Y_filt = movmean(app.filtData(app.fileNum).RE_Position_Y_filt,windowSize);
                        elseif contains(app.filtertypeDropDown_3.Value,'Z')
                            app.filtData(app.fileNum).RE_Position_Z_filt = movmean(app.filtData(app.fileNum).RE_Position_Z_filt,windowSize);
                        end
                    end

                    % update filter parameters
                    selectedData = regexprep(app.filtertypeDropDown_3.Value,{'-';' ';'[.]'},'');
                    app.filtData(app.fileNum).FilteringParameters.Position.(selectedData).WindowSize(2) = windowSize;

                case "Savitzky-Golay"
                    windowSize = str2double(app.windowsizeEditField_2.Value);
                    filtOrder = str2double(app.filterorderEditField_2.Value);

                    if contains(app.filtertypeDropDown_3.Value,'LE') % apply filtering to left eye data
                        if contains(app.filtertypeDropDown_3.Value,'X')
                            app.filtData(app.fileNum).LE_Position_X_filt = sgolayfilt(app.filtData(app.fileNum).LE_Position_X_filt,filtOrder,windowSize);
                        elseif contains(app.filtertypeDropDown_3.Value,'Y')
                            app.filtData(app.fileNum).LE_Position_Y_filt = sgolayfilt(app.filtData(app.fileNum).LE_Position_Y_filt,filtOrder,windowSize);
                        elseif contains(app.filtertypeDropDown_3.Value,'Z')
                            app.filtData(app.fileNum).LE_Position_Z_filt = sgolayfilt(app.filtData(app.fileNum).LE_Position_Z_filt,filtOrder,windowSize);
                        end
                    else % apply filtering to right eye data
                        if contains(app.filtertypeDropDown_3.Value,'X')
                            app.filtData(app.fileNum).RE_Position_X_filt = sgolayfilt(app.filtData(app.fileNum).RE_Position_X_filt,filtOrder,windowSize);
                        elseif contains(app.filtertypeDropDown_3.Value,'Y')
                            app.filtData(app.fileNum).RE_Position_Y_filt = sgolayfilt(app.filtData(app.fileNum).RE_Position_Y_filt,filtOrder,windowSize);
                        elseif contains(app.filtertypeDropDown_3.Value,'Z')
                            app.filtData(app.fileNum).RE_Position_Z_filt = sgolayfilt(app.filtData(app.fileNum).RE_Position_Z_filt,filtOrder,windowSize);
                        end
                    end

                    % update filter parameters
                    selectedData = regexprep(app.filtertypeDropDown_3.Value,{'-';' ';'[.]'},'');
                    app.filtData(app.fileNum).FilteringParameters.Position.(selectedData).WindowSize(3) = windowSize;
                    app.filtData(app.fileNum).FilteringParameters.Position.(selectedData).FilterOrder(3) = filtOrder;

                case "iteratively reweighted least-squeares smoother"
                    % update filter parameters
                    selectedData = regexprep(app.filtertypeDropDown_3.Value,{'-';' ';'[.]'},'');
                    app.filtData(app.fileNum).FilteringParameters.Position.(selectedData).WindowSize(4) = windowSize;
                    app.filtData(app.fileNum).FilteringParameters.Position.(selectedData).FilterOrder(4) = filtOrder;
            end

            % update filtered data

            % This system code marks that no additional manipulations of the raw data
            % are required.
            data_rot = 1;
            % If, regardless of the system used to record the eye movement data,
            % the data is presented in Fick angles, change the DAQ_code to '5'
            DAQ_code = 5;
            OutputFormat = 1; % fick

            Data_In.Data_LE_Pos_X = app.filtData(app.fileNum).LE_Position_X_filt;
            Data_In.Data_LE_Pos_Y = app.filtData(app.fileNum).LE_Position_Y_filt;
            Data_In.Data_LE_Pos_Z = app.filtData(app.fileNum).LE_Position_Z_filt;

            Data_In.Data_RE_Pos_X = app.filtData(app.fileNum).RE_Position_X_filt;
            Data_In.Data_RE_Pos_Y = app.filtData(app.fileNum).RE_Position_Y_filt;
            Data_In.Data_RE_Pos_Z = app.filtData(app.fileNum).RE_Position_Z_filt;

            Data_In.Fs = app.filtData(app.fileNum).Fs;

            [New_Ang_Vel] = voma__processeyemovements([],[],[],[],0,data_rot,DAQ_code,OutputFormat,Data_In);

            app.filtData(app.fileNum).LE_Position_X_filt = New_Ang_Vel.LE_Pos_X;
            app.filtData(app.fileNum).LE_Position_Y_filt = New_Ang_Vel.LE_Pos_Y;
            app.filtData(app.fileNum).LE_Position_Z_filt = New_Ang_Vel.LE_Pos_Z;
            app.filtData(app.fileNum).LE_Velocity_LARP = New_Ang_Vel.LE_Vel_LARP;
            app.filtData(app.fileNum).LE_Velocity_RALP = New_Ang_Vel.LE_Vel_RALP;
            app.filtData(app.fileNum).LE_Velocity_X = New_Ang_Vel.LE_Vel_X;
            app.filtData(app.fileNum).LE_Velocity_Y = New_Ang_Vel.LE_Vel_Y;
            app.filtData(app.fileNum).LE_Velocity_Z = New_Ang_Vel.LE_Vel_Z;

            app.filtData(app.fileNum).RE_Position_X_filt = New_Ang_Vel.RE_Pos_X;
            app.filtData(app.fileNum).RE_Position_Y_filt = New_Ang_Vel.RE_Pos_Y;
            app.filtData(app.fileNum).RE_Position_Z_filt = New_Ang_Vel.RE_Pos_Z;
            app.filtData(app.fileNum).RE_Velocity_LARP = New_Ang_Vel.RE_Vel_LARP;
            app.filtData(app.fileNum).RE_Velocity_RALP = New_Ang_Vel.RE_Vel_RALP;
            app.filtData(app.fileNum).RE_Velocity_X = New_Ang_Vel.RE_Vel_X;
            app.filtData(app.fileNum).RE_Velocity_Y = New_Ang_Vel.RE_Vel_Y;
            app.filtData(app.fileNum).RE_Velocity_Z = New_Ang_Vel.RE_Vel_Z;

            % set velocity filtered data to equal velocity data
            % (RE_Velocity_Z_filt = RE_Velocity_Z)
            setFilterParameterstoDefault(app,app.fileNum,'velocity')
            if app.filtData(app.fileNum).FilteringParameters.Velocity.FilterAppliedFlag
                disp('Warning: The position filtering was changed, so the velocity filtering parameters were reset.')
            end

            % plot filtered data
            plotEyePosition(app)
            plotEyeVelocity(app)
            plotEyeVelocity_QP(app)
            plotEyeAcceleration(app)
            plotAllCycles(app)
            plotCycleAverage(app)

        end

        % Button pushed function: ResetFilteringButton_Position
        function ResetFilteringButton_PositionPushed(app, event)
            % set parameters to default
            setFilterParameterstoDefault(app,app.fileNum,'position')

            displayPositionFilteringParameters(app)

            % replot data
            plotEyePosition(app)
            plotEyeVelocity(app)
            plotEyeVelocity_QP(app)
            plotEyeAcceleration(app)
            plotAllCycles(app)
            plotCycleAverage(app)
        end

        % Callback function
        function righteyecoilCheckBox_2ValueChanged(app, event)
            plotEyeVelocity(app)
        end

        % Value changed function: righteyecoilCheckBox_2
        function righteyecoilCheckBox_2ValueChanged2(app, event)
            plotEyeVelocity(app)
        end

        % Value changed function: lefteyecoilCheckBox_2
        function lefteyecoilCheckBox_2ValueChanged(app, event)
            plotEyeVelocity(app)
        end

        % Value changed function: showrawdataCheckBox_2
        function showrawdataCheckBox_2ValueChanged(app, event)
            plotEyePosition(app)
        end

        % Value changed function: showunfiltereddataCheckBox
        function showunfiltereddataCheckBoxValueChanged(app, event)
            plotEyeVelocity(app)
        end

        % Value changed function: LARPCheckBox_2
        function LARPCheckBox_2ValueChanged(app, event)
            plotAllCycles(app)
            plotCycleAverage(app)
        end

        % Value changed function: RALPCheckBox_2
        function RALPCheckBox_2ValueChanged(app, event)
            plotAllCycles(app)
            plotCycleAverage(app)
        end

        % Value changed function: LHRHCheckBox_2
        function LHRHCheckBox_2ValueChanged(app, event)
            plotAllCycles(app)
            plotCycleAverage(app)
        end

        % Value changed function: linkxaxesCheckBox
        function linkxaxesCheckBoxValueChanged(app, event)
            value = app.linkxaxesCheckBox.Value;
            if value
                linkaxes([app.AllCyclesAxes,app.WaveformAxes],'x')
            else
                linkaxes([app.AllCyclesAxes,app.WaveformAxes],'off')
            end
        end

        % Value changed function: CyclesListBox
        function CyclesListBoxValueChanged(app, event)
            app.filtData(app.fileNum).KeepCycleNums =  app.CyclesListBox.Value;

            % recalculate cycle average
            calculateCycleAverage(app)
            plotCycleAverage(app)

            % update text for how many files are selected to be kept
            nCyclesKeep = length(app.CyclesListBox.Value);
            app.ncyclesLabel.Text = sprintf('n = %d cycles',nCyclesKeep);
        end

        % Value changed function: lefteyecoilCheckBox_3
        function lefteyecoilCheckBox_3ValueChanged(app, event)
            plotAllCycles(app)
            plotCycleAverage(app)
        end

        % Value changed function: righteyecoilCheckBox_3
        function righteyecoilCheckBox_3ValueChanged(app, event)
            plotAllCycles(app)
            plotCycleAverage(app)
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            currentfile = app.SelectfileListBox.Value;
            if contains(currentfile, 'PFMRamp') % PFM waveform
                if contains(currentfile,'Base')
                    waveformInfo = strsplit(currentfile,{'_','pps','x','Base'});
                    nCycles = str2double(waveformInfo{10});
                else
                    nCycles = 20;
                end

            elseif contains(currentfile, 'DCTrap') % iDC waveform

                waveformInfo = strsplit(currentfile,{'_','Offs','x','uA'});
                nCycles = str2double(waveformInfo{8});

            elseif contains(currentfile, 'DCSine')
                sineAmp1 = str2double(extractBetween(currentfile,'DCSine','_'));
                offset = str2double(extractBetween(currentfile,string(sineAmp1) + '_','_'));
                nCycles = str2double(extractBetween(currentfile,'_' + string(offset) + '_','x'));
            end
            app.CyclesListBox.Items = string([1:nCycles]');
            app.CyclesListBox.Value = app.CyclesListBox.Items;

            % reset the cycles that will be kept to all
            app.filtData(app.fileNum).KeepCycleNums =  app.CyclesListBox.Value;

            % update text for how many files are selected to be kept
            nCyclesKeep = length(app.CyclesListBox.Value);
            app.ncyclesLabel.Text = sprintf('n = %d cycles',nCyclesKeep);

            plotCycleAverage(app)
        end

        % Callback function
        function filtertypeDropDown_6ValueChanged(app, event)
            value = app.filtertypeDropDown_6.Value;
            switch char(value)
                case "moving median"
                    app.filterorderEditField_3.Visible = 'off';
                    app.filterorderEditField_3Label.Visible = 'off';
                case "moving mean"
                    app.filterorderEditField_3.Visible = 'off';
                    app.filterorderEditField_3Label.Visible = 'off';
                case "Savitzky-Golay"
                    app.filterorderEditField_3.Visible = 'on';
                    app.filterorderEditField_3Label.Visible = 'on';
                case "iteratively reweighted least-squares smoother"
                    app.filterorderEditField_3.Visible = 'on';
                    app.filterorderEditField_3Label.Visible = 'on';
                case "spline"
                    % no user inputs are needed for spline other than the
                    % data to be filtered
                    app.filterorderEditField_3.Visible = "off";
                    app.filterorderEditField_3Label.Visible = 'off';
                    app.windowsizeEditField_3.Visible = "off";
                    app.windowsizeEditField_3Label.Visible = 'off';
            end
        end

        % Callback function
        function ApplyFilteringButton_3Pushed(app, event)
            switch app.filtertypeDropDown_6.Value
                case "moving median"

                    windowSize = str2double(app.windowsizeEditField_3.Value);

                    if contains(app.filtertypeDropDown_5.Value,'LE') % apply filtering to left eye data
                        if contains(app.filtertypeDropDown_5.Value,'LARP')
                            app.filtData(app.fileNum).LE_Velocity_LARP_filt = movmedian(app.filtData(app.fileNum).LE_Velocity_LARP_filt,windowSize);
                        elseif contains(app.filtertypeDropDown_5.Value,'RALP')
                            app.filtData(app.fileNum).LE_Velocity_RALP_filt = movmedian(app.filtData(app.fileNum).LE_Velocity_RALP_filt,windowSize);
                        elseif contains(app.filtertypeDropDown_5.Value,'X')
                            app.filtData(app.fileNum).LE_Velocity_X_filt = movmedian(app.filtData(app.fileNum).LE_Velocity_X_filt,windowSize);
                        elseif contains(app.filtertypeDropDown_5.Value,'Y')
                            app.filtData(app.fileNum).LE_Velocity_Y_filt = movmedian(app.filtData(app.fileNum).LE_Velocity_Y_filt,windowSize);
                        elseif contains(app.filtertypeDropDown_5.Value,'Z')
                            app.filtData(app.fileNum).LE_Velocity_Z_filt = movmedian(app.filtData(app.fileNum).LE_Velocity_Z_filt,windowSize);
                        end
                    else % apply filtering to right eye data
                        if contains(app.filtertypeDropDown_5.Value,'LARP')
                            app.filtData(app.fileNum).RE_Velocity_LARP_filt = movmedian(app.filtData(app.fileNum).RE_Velocity_LARP_filt,windowSize);
                        elseif contains(app.filtertypeDropDown_5.Value,'RALP')
                            app.filtData(app.fileNum).RE_Velocity_RALP_filt = movmedian(app.filtData(app.fileNum).RE_Velocity_RALP_filt,windowSize);
                        elseif contains(app.filtertypeDropDown_5.Value,'X')
                            app.filtData(app.fileNum).RE_Velocity_X_filt = movmedian(app.filtData(app.fileNum).RE_Velocity_X_filt,windowSize);
                        elseif contains(app.filtertypeDropDown_5.Value,'Y')
                            app.filtData(app.fileNum).RE_Velocity_Y_filt = movmedian(app.filtData(app.fileNum).RE_Velocity_Y_filt,windowSize);
                        elseif contains(app.filtertypeDropDown_5.Value,'Z')
                            app.filtData(app.fileNum).RE_Velocity_Z_filt = movmedian(app.filtData(app.fileNum).RE_Velocity_Z_filt,windowSize);
                        end
                    end

                    % update filter parameters
                    selectedData = regexprep(app.filtertypeDropDown_5.Value,{'-';' ';'[.]'},'');
                    app.filtData(app.fileNum).FilteringParameters.Velocity.(selectedData).WindowSize(1) = windowSize;

                case "moving mean"

                    windowSize = str2double(app.windowsizeEditField_3.Value);

                    if contains(app.filtertypeDropDown_5.Value,'LE') % apply filtering to left eye data
                        if contains(app.filtertypeDropDown_5.Value,'LARP')
                            app.filtData(app.fileNum).LE_Velocity_LARP_filt = movmean(app.filtData(app.fileNum).LE_Velocity_LARP_filt,windowSize);
                        elseif contains(app.filtertypeDropDown_5.Value,'RALP')
                            app.filtData(app.fileNum).LE_Velocity_RALP_filt = movmean(app.filtData(app.fileNum).LE_Velocity_RALP_filt,windowSize);
                        elseif contains(app.filtertypeDropDown_5.Value,'X')
                            app.filtData(app.fileNum).LE_Velocity_X_filt = movmean(app.filtData(app.fileNum).LE_Velocity_X_filt,windowSize);
                        elseif contains(app.filtertypeDropDown_5.Value,'Y')
                            app.filtData(app.fileNum).LE_Velocity_Y_filt = movmean(app.filtData(app.fileNum).LE_Velocity_Y_filt,windowSize);
                        elseif contains(app.filtertypeDropDown_5.Value,'Z')
                            app.filtData(app.fileNum).LE_Velocity_Z_filt = movmean(app.filtData(app.fileNum).LE_Velocity_Z_filt,windowSize);
                        end
                    else % apply filtering to right eye data
                        if contains(app.filtertypeDropDown_5.Value,'LARP')
                            app.filtData(app.fileNum).RE_Velocity_LARP_filt = movmean(app.filtData(app.fileNum).RE_Velocity_LARP_filt,windowSize);
                        elseif contains(app.filtertypeDropDown_5.Value,'RALP')
                            app.filtData(app.fileNum).RE_Velocity_RALP_filt = movmean(app.filtData(app.fileNum).RE_Velocity_RALP_filt,windowSize);
                        elseif contains(app.filtertypeDropDown_5.Value,'X')
                            app.filtData(app.fileNum).RE_Velocity_X_filt = movmean(app.filtData(app.fileNum).RE_Velocity_X_filt,windowSize);
                        elseif contains(app.filtertypeDropDown_5.Value,'Y')
                            app.filtData(app.fileNum).RE_Velocity_Y_filt = movmean(app.filtData(app.fileNum).RE_Velocity_Y_filt,windowSize);
                        elseif contains(app.filtertypeDropDown_5.Value,'Z')
                            app.filtData(app.fileNum).RE_Velocity_Z_filt = movmean(app.filtData(app.fileNum).RE_Velocity_Z_filt,windowSize);
                        end
                    end

                    % update filter parameters
                    selectedData = regexprep(app.filtertypeDropDown_5.Value,{'-';' ';'[.]'},'');
                    app.filtData(app.fileNum).FilteringParameters.Velocity.(selectedData).WindowSize(2) = windowSize;

                case "Savitzky-Golay"

                    windowSize = str2double(app.windowsizeEditField_3.Value);
                    filtOrder = str2double(app.filterorderEditField_3.Value);

                    if contains(app.filtertypeDropDown_5.Value,'LE') % apply filtering to left eye data
                        if contains(app.filtertypeDropDown_5.Value,'LARP')
                            app.filtData(app.fileNum).LE_Velocity_LARP_filt = sgolayfilt(app.filtData(app.fileNum).LE_Velocity_LARP_filt,filtOrder,windowSize);
                        elseif contains(app.filtertypeDropDown_5.Value,'RALP')
                            app.filtData(app.fileNum).LE_Velocity_RALP_filt = sgolayfilt(app.filtData(app.fileNum).LE_Velocity_RALP_filt,filtOrder,windowSize);
                        elseif contains(app.filtertypeDropDown_5.Value,'X')
                            app.filtData(app.fileNum).LE_Velocity_X_filt = sgolayfilt(app.filtData(app.fileNum).LE_Velocity_X_filt,filtOrder,windowSize);
                        elseif contains(app.filtertypeDropDown_5.Value,'Y')
                            app.filtData(app.fileNum).LE_Velocity_Y_filt = sgolayfilt(app.filtData(app.fileNum).LE_Velocity_Y_filt,filtOrder,windowSize);
                        elseif contains(app.filtertypeDropDown_5.Value,'Z')
                            app.filtData(app.fileNum).LE_Velocity_Z_filt = sgolayfilt(app.filtData(app.fileNum).LE_Velocity_Z_filt,filtOrder,windowSize);
                        end
                    else % apply filtering to right eye data
                        if contains(app.filtertypeDropDown_5.Value,'LARP')
                            app.filtData(app.fileNum).RE_Velocity_LARP_filt = sgolayfilt(app.filtData(app.fileNum).RE_Velocity_LARP_filt,filtOrder,windowSize);
                        elseif contains(app.filtertypeDropDown_5.Value,'RALP')
                            app.filtData(app.fileNum).RE_Velocity_RALP_filt = sgolayfilt(app.filtData(app.fileNum).RE_Velocity_RALP_filt,filtOrder,windowSize);
                        elseif contains(app.filtertypeDropDown_5.Value,'X')
                            app.filtData(app.fileNum).RE_Velocity_X_filt = sgolayfilt(app.filtData(app.fileNum).RE_Velocity_X_filt,filtOrder,windowSize);
                        elseif contains(app.filtertypeDropDown_5.Value,'Y')
                            app.filtData(app.fileNum).RE_Velocity_Y_filt = sgolayfilt(app.filtData(app.fileNum).RE_Velocity_Y_filt,filtOrder,windowSize);
                        elseif contains(app.filtertypeDropDown_5.Value,'Z')
                            app.filtData(app.fileNum).RE_Velocity_Z_filt = sgolayfilt(app.filtData(app.fileNum).RE_Velocity_Z_filt,filtOrder,windowSize);
                        end
                    end

                    % update filter parameters
                    selectedData = regexprep(app.filtertypeDropDown_5.Value,{'-';' ';'[.]'},'');
                    app.filtData(app.fileNum).FilteringParameters.Velocity.(selectedData).WindowSize(3) = windowSize;
                    app.filtData(app.fileNum).FilteringParameters.Velocity.(selectedData).FilterOrder(3) = filtOrder;

                case "iteratively reweighted least-squares smoother"
                case "k-means clustering qpr"
                    %                     if contains(app.filtertypeDropDown_6.Value,'LE') % apply filtering to left eye data
                    %                         if contains(app.filtertypeDropDown_6.Value,'X')
                    %                             app.filtData(app.fileNum).LE_Position_X =
                    %                         elseif contains(app.filtertypeDropDown_6.Value,'Y')
                    %                             app.filtData(app.fileNum).LE_Position_Y =
                    %                         elseif contains(app.filtertypeDropDown_6.Value,'Z')
                    %                             app.filtData(app.fileNum).LE_Position_Z =
                    %                         end
                    %                     else % apply filtering to right eye data
                    %                         if contains(app.filtertypeDropDown_6.Value,'X')
                    %                             app.filtData(app.fileNum).RE_Position_X =
                    %                         elseif contains(app.filtertypeDropDown_6.Value,'Y')
                    %                             app.filtData(app.fileNum).RE_Position_Y =
                    %                         elseif contains(app.filtertypeDropDown_6.Value,'Z')
                    %                             app.filtData(app.fileNum).RE_Position_Z =
                    %                         end
                    %                     end


            end

            % replot data
            plotEyePosition(app)
            plotEyeVelocity(app)
            plotEyeVelocity_QP(app)
            plotEyeAcceleration(app)
            plotAllCycles(app)
            plotCycleAverage(app)
        end

        % Button pushed function: ResetFilteringButton_Velocity
        function ResetFilteringButton_VelocityPushed(app, event)
            % set parameters to default
            setFilterParameterstoDefault(app,app.fileNum,'velocity')

            displayVelocityFilteringParameters(app)

            % replot data
            plotEyePosition(app)
            plotEyeVelocity(app)
            plotEyeVelocity_QP(app)
            plotEyeAcceleration(app)
            plotAllCycles(app)
            plotCycleAverage(app)
        end

        % Callback function
        function filtertypeDropDown_3ValueChanged(app, event)
            selectedData = regexprep(app.filtertypeDropDown_3.Value,{'-';' ';'[.]'},'');
            app.PositionFilterParamsTable.Data = app.filtData(app.fileNum).FilteringParameters.Position.(selectedData);
            app.PositionFilterParamsTable_movingmedian.Data = app.filtData(app.fileNum).FilteringParameters.Position.(selectedData);
            app.PositionFilterParamsTable_movingmedian.ColumnName = app.filtData(app.fileNum).FilteringParameters.Position.(selectedData).Properties.VariableNames;
        end

        % Callback function
        function filtertypeDropDown_5ValueChanged(app, event)
            selectedData = regexprep(app.filtertypeDropDown_5.Value,{'-';' ';'[.]'},'');
            app.VelocityFilterParamsTable.Data = app.filtData(app.fileNum).FilteringParameters.Velocity.(selectedData);
            app.VelocityFilterParamsTable.ColumnName = app.filtData(app.fileNum).FilteringParameters.Velocity.(selectedData).Properties.VariableNames;
        end

        % Button pushed function: FilterTracesButton_Position
        function FilterTracesButton_PositionPushed(app, event)
            app.UIFigure.AutoResizeChildren = "off";
            app.PositionFilteringParametersPanel.Visible = 'on';
            % adjust size of app figure
            app.UIFigure.Position = [app.UIFigure.Position(1:2) 1050 899];

            displayPositionFilteringParameters(app)
        end

        % Button pushed function: CloseButton_Position
        function CloseButton_PositionPushed(app, event)
            app.UIFigure.AutoResizeChildren = "off";
            app.PositionFilteringParametersPanel.Visible = 'off';
            app.UIFigure.Position = [app.UIFigure.Position(1:2) 696 899];
        end

        % Value changed function: autoresizeGUIcomponentsCheckBox
        function autoresizeGUIcomponentsCheckBoxValueChanged(app, event)
            value = app.autoresizeGUIcomponentsCheckBox.Value;
            if value
                app.UIFigure.AutoResizeChildren = "on";
            end

            if ~value
                app.UIFigure.AutoResizeChildren = "off";
                app.UIFigure.Position = [500 100 696 899];
                app.TabGroup.Position = [181 15 503 656];
            end
        end

        % Cell edit callback: PositionFilterParamsTable_movingmedian
        function PositionFilterParamsTable_movingmedianCellEdit(app, event)

        end

        % Cell edit callback: PositionFilterParamsTable_movingmean
        function PositionFilterParamsTable_movingmeanCellEdit(app, event)

        end

        % Cell edit callback: PositionFilterParamsTable_savitzkygolay
        function PositionFilterParamsTable_savitzkygolayCellEdit(app, event)

        end

        % Cell edit callback: PositionFilterParamsTable_irlss
        function PositionFilterParamsTable_irlssCellEdit(app, event)

        end

        % Value changed function: applyPositionFilter1_CheckBox
        function applyPositionFilter1_CheckBoxValueChanged(app, event)

        end

        % Value changed function: applyPositionFilter2_CheckBox
        function applyPositionFilter2_CheckBoxValueChanged(app, event)

        end

        % Button pushed function: ApplyFilteringButton_4
        function ApplyFilteringButton_4Pushed(app, event)
            % filter data
            applyPositionFiltering(app)

            % calculate cycle average
            calculateCycleAverage(app)
            
            % make sure chosen parameters are recorded
            updateFilteringParameters(app,"position")

            % replot data
            plotEyePosition(app)
            plotEyeVelocity(app)
            plotEyeVelocity_QP(app)
            plotEyeAcceleration(app)
            plotAllCycles(app)
            plotCycleAverage(app)
        end

        % Button pushed function: FilterTracesButton_Velocity
        function FilterTracesButton_VelocityPushed(app, event)
            app.UIFigure.AutoResizeChildren = "off";
            app.VelocityFilteringParametersPanel.Visible = 'on';
            % adjust size of app figure
            app.UIFigure.Position = [app.UIFigure.Position(1:2) 1050 899];

            displayVelocityFilteringParameters(app)
        end

        % Button pushed function: CloseButton_Velocity
        function CloseButton_VelocityPushed(app, event)
            app.UIFigure.AutoResizeChildren = "off";
            app.VelocityFilteringParametersPanel.Visible = 'off';
            app.UIFigure.Position = [app.UIFigure.Position(1:2) 696 899];
        end

        % Button pushed function: ApplyFilteringButton_5
        function ApplyFilteringButton_5Pushed(app, event)
            % filter data
            applyVelocityFiltering(app)

            % calculate cycle average
            calculateCycleAverage(app)

            % make sure chosen parameters are recorded
            updateFilteringParameters(app,"velocity")

            % plot data with new filtering parameters
            plotEyePosition(app)
            plotEyeVelocity(app)
            plotEyeVelocity_QP(app)
            plotEyeAcceleration(app)
            plotAllCycles(app)
            plotCycleAverage(app)
            
        end

        % Button pushed function: SaveCyclesParametersButton
        function SaveCyclesParametersButtonPushed(app, event)
            % save filtered data & parameters into a mat file
            dataType = app.DataTypeDropDown.Value;
            currentfile = app.SelectfileListBox.Value;
            currentFolder = app.SelectafolderListBox.Value;
            str = extractBefore(currentfile,'.mat');
            cycleFileName = [str '_' dataType '_cycles'];
            savename = [cycleFileName '.mat'];
            filepath = app.FileLocationEditField.Value;
            Data = app.filtData(app.fileNum);
            save(fullfile(filepath,'Cycles', currentFolder, savename),'Data')

%             stimInfo = strsplit(str,{'_','Offs','x','Amp','Delta','Freq','Base'});

            if contains(currentfile,'DCTrap')
                Date = string(regexp(app.FileLocationEditField.Value,'\d{8}','match','once'));
                StimAxis = string(app.StimAxisDropDown.Value);
                WaveformType = string(app.DataTypeDropDown.Value);
                StimType = "DC";
%                 StimOffset = string(stimInfo{7});
%                 StimAmplitude1 = string(stimInfo{4});
%                 StimAmplitude2 = string(stimInfo{6});
%                 NumCycles = string(stimInfo{8});
%                 NumCyclesKeep = string(length(app.filtData(app.fileNum).KeepCycleNums));
%                 RampTime = string(stimInfo{13});
%                 Phase1Dur = string(stimInfo{9});
%                 Phase2Dur = string(stimInfo{11});
%                 IPG = string(stimInfo{10});

                waveformInfo = strsplit(currentfile,{'_','pps','x','Pulse','ms','DCTrap'});
                StimOffset = string(waveformInfo{4});
                StimAmplitude1 = string(waveformInfo{2});
                StimAmplitude2 = string(waveformInfo{3});
                NumCycles = string(waveformInfo{5});
                NumCyclesKeep = string(length(app.filtData(app.fileNum).KeepCycleNums));
                RampTime = string(waveformInfo{10});
                StimDur1 = string(waveformInfo{6});
                StimDur2 = string(waveformInfo{8});
                IPG = string(waveformInfo{7});
                StimGap = string(waveformInfo{9});

                StimRate1 = string();
                StimRate2 = string();
                Phase1Dur = string();
                Phase2Dur = string();

                ExperimentRecords_expSpecific = table(Date,StimAxis,WaveformType, ...
                    StimType,StimOffset,StimAmplitude1,StimAmplitude2,StimDur1,...
                    StimDur2,StimGap,StimRate1,StimRate2,NumCycles,NumCyclesKeep,...
                    RampTime,Phase1Dur,Phase2Dur,IPG);
            elseif contains(currentfile,'PFMRamp')
                Date = string(regexp(app.FileLocationEditField.Value,'\d{8}','match','once'));
                StimAxis = string(app.StimAxisDropDown.Value);
                WaveformType = string(app.DataTypeDropDown.Value);
                StimType = "PFM";
                % StimOffset = string(extractBetween(currentfile,'Base','pps'));
                waveformInfo = strsplit(currentfile,{'_','pps','x','Pulse','ms','PFM'});
                StimOffset = string(waveformInfo{13});
                % StimAmplitude2 = string(extractBetween(currentfile,'_K1_','_'));
                % pat = '_K1_' + StimAmplitude2 + '_';
                % StimAmplitude1 = string(extractBetween(currentfile,pat,'pps'));
                StimAmplitude1 = string(waveformInfo{4});
                StimAmplitude2 = string(waveformInfo{4});
                StimRate2 = string(extractBetween(currentfile,'_K1_','_'));
                StimRate1 = string(waveformInfo{16});
                NumCycles = string(extractBetween(currentfile,'PFMRamp_','x'));
                NumCyclesKeep = string(length(app.filtData(app.fileNum).KeepCycleNums));
                pat = NumCycles + 'x';
                RampTime = string(extractBetween(currentfile,pat,'_'));
                pat = 'x' + RampTime + '_';
                StimDur1 = string(extractBetween(currentfile,pat,'_'));
                StimDur2 = StimDur1;
                StimGap = string(waveformInfo{12});
                Phase1Dur = string(waveformInfo{5});
                Phase2Dur = string(waveformInfo{7});
                % pat = '_' + Stim1Dur;
                % IPG = string(extractBetween(currentfile,pat,'ms'));
                IPG = string(waveformInfo{6});

                % some early PFM files do not contain all waveform
                % parameters, they were preset
                if isempty(RampTime) 
                    RampTime = 50;
                    Phase1Dur = 150;
                    Phase2Dur = 150;
                    IPG = 750;
                end

                ExperimentRecords_expSpecific = table(Date,StimAxis,WaveformType, ...
                    StimType,StimOffset,StimAmplitude1,StimAmplitude2,StimDur1,...
                    StimDur2,StimGap,StimRate1,StimRate2,NumCycles,NumCyclesKeep,...
                    RampTime,Phase1Dur,Phase2Dur,IPG);
            elseif contains(currentfile, 'PFMSine')
                Date = string(regexp(app.FileLocationEditField.Value,'\d{8}','match','once'));
                StimAxis = string(app.StimAxisDropDown.Value);
                WaveformType = string(app.DataTypeDropDown.Value);
                StimType = "PFM";
                StimOffset = string(stimInfo{12});
                StimAmplitude = string(stimInfo{14});
                NumCycles = string(stimInfo{10});
                NumCyclesKeep = string(length(app.filtData(app.fileNum).KeepCycleNums));
                Frequency = strrep(string(stimInfo{13}),'p','.');

                ExperimentRecords_expSpecific = table(Date,StimAxis,WaveformType, ...
                    StimType,StimOffset,StimAmplitude, ...
                    NumCycles,NumCyclesKeep,Frequency);
            elseif contains(currentfile, 'DCSine')
                Date = string(regexp(app.FileLocationEditField.Value,'\d{8}','match','once'));
                StimAxis = string(app.StimAxisDropDown.Value);
                WaveformType = string(app.DataTypeDropDown.Value);
                StimType = "DC";

                StimAmplitude = str2double(extractBetween(currentfile,'DCSine','_'));
                StimOffset = str2double(extractBetween(currentfile,string(StimAmplitude) + '_','_'));
                NumCycles = str2double(extractBetween(currentfile,'_' + string(StimOffset) + '_','x'));
                WaveformLength = str2double(extractBetween(currentfile, string(NumCycles) + 'x','_'));

                % StimOffset = string(stimInfo{6});
                % StimAmplitude = string(stimInfo{5});
                % NumCycles = string(stimInfo{7});
                NumCyclesKeep = string(length(app.filtData(app.fileNum).KeepCycleNums));
                Frequency = str2double(strrep(extractBetween(currentfile,'x' + string(WaveformLength) + '_','_'),'p','.'));
                % Frequency = strrep(string(stimInfo{9}),'p','.');

                ExperimentRecords_expSpecific = table(Date,StimAxis,WaveformType, ...
                    StimType,StimOffset,StimAmplitude, ...
                NumCycles,NumCyclesKeep,Frequency);
            end
            EyeVel_Magnitude_Cathodic_avg = string(app.filtData(app.fileNum).EyeVel_Magnitude_Cathodic_avg);
            EyeVel_Magnitude_Cathodic_std = string(app.filtData(app.fileNum).EyeVel_Magnitude_Cathodic_std);
            EyeVel_Magnitude_Anodic_avg = string(app.filtData(app.fileNum).EyeVel_Magnitude_Anodic_avg);
            EyeVel_Magnitude_Anodic_std = string(app.filtData(app.fileNum).EyeVel_Magnitude_Anodic_std);
            EyeVel_Misalignment_Cathodic_avg = string(app.filtData(app.fileNum).EyeVel_Misalignment_Cathodic_avg);
            EyeVel_Misalignment_Cathodic_std = string(app.filtData(app.fileNum).EyeVel_Misalignment_Cathodic_std);
            EyeVel_Misalignment_Anodic_avg = string(app.filtData(app.fileNum).EyeVel_Misalignment_Anodic_avg);
            EyeVel_Misalignment_Anodic_std = string(app.filtData(app.fileNum).EyeVel_Misalignment_Anodic_std);
            
            EyeVel_Magnitude_Cathodic_avg_nystagmusCorrected = string(app.filtData(app.fileNum).EyeVel_Magnitude_Cathodic_avg_nystagmusCorrected);
            EyeVel_Magnitude_Cathodic_std_nystagmusCorrected = string(app.filtData(app.fileNum).EyeVel_Magnitude_Cathodic_std_nystagmusCorrected);
            EyeVel_Magnitude_Anodic_avg_nystagmusCorrected = string(app.filtData(app.fileNum).EyeVel_Magnitude_Anodic_avg_nystagmusCorrected);
            EyeVel_Magnitude_Anodic_std_nystagmusCorrected = string(app.filtData(app.fileNum).EyeVel_Magnitude_Anodic_std_nystagmusCorrected);
            EyeVel_Misalignment_Cathodic_avg_nystagmusCorrected = string(app.filtData(app.fileNum).EyeVel_Misalignment_Cathodic_avg_nystagmusCorrected);
            EyeVel_Misalignment_Cathodic_std_nystagmusCorrected = string(app.filtData(app.fileNum).EyeVel_Misalignment_Cathodic_std_nystagmusCorrected);
            EyeVel_Misalignment_Anodic_avg_nystagmusCorrected = string(app.filtData(app.fileNum).EyeVel_Misalignment_Anodic_avg_nystagmusCorrected);
            EyeVel_Misalignment_Anodic_std_nystagmusCorrected = string(app.filtData(app.fileNum).EyeVel_Misalignment_Anodic_std_nystagmusCorrected);
            PhaseShift = string(app.filtData(app.fileNum).PhaseShift);

            FileLocation = string(app.FileLocationEditField.Value);
            CycleName = string(cycleFileName);


            if isempty(EyeVel_Misalignment_Anodic_avg)
                EyeVel_Misalignment_Anodic_avg = "";
                EyeVel_Misalignment_Anodic_std = "";
                EyeVel_Misalignment_Anodic_avg_nystagmusCorrected = "";
                EyeVel_Misalignment_Anodic_std_nystagmusCorrected = "";
            end

            ExperimentRecords_eyeVelocity = table(EyeVel_Magnitude_Cathodic_avg, ...
                EyeVel_Magnitude_Cathodic_std,EyeVel_Magnitude_Anodic_avg, ...
                EyeVel_Magnitude_Anodic_std,EyeVel_Misalignment_Cathodic_avg, ...
                EyeVel_Misalignment_Cathodic_std,EyeVel_Misalignment_Anodic_avg, ...
                EyeVel_Misalignment_Anodic_std, ...
                EyeVel_Magnitude_Cathodic_avg_nystagmusCorrected, ...
                EyeVel_Magnitude_Cathodic_std_nystagmusCorrected, ...
                EyeVel_Magnitude_Anodic_avg_nystagmusCorrected, ...
                EyeVel_Magnitude_Anodic_std_nystagmusCorrected, ...
                EyeVel_Misalignment_Cathodic_avg_nystagmusCorrected, ...
                EyeVel_Misalignment_Cathodic_std_nystagmusCorrected, ...
                EyeVel_Misalignment_Anodic_avg_nystagmusCorrected, ...
                EyeVel_Misalignment_Anodic_std_nystagmusCorrected, ...
                PhaseShift,...
                FileLocation,CycleName);

            ExperimentRecords = [ExperimentRecords_expSpecific ExperimentRecords_eyeVelocity];

            dlgTitle    = 'User Question';
            dlgQuestion = 'Does an Experimental Records spreadsheet already exist?';
            choice = questdlg(dlgQuestion,dlgTitle,'Yes','Make One','Yes');

            if contains(choice,'Make One')
                prompt = {'Please enter a name for the experimental records file:'};
                dlgtitle = 'Input';
                fieldsize = [1 45];
                definput = {'ExperimentRecords'};
                filename = inputdlg(prompt,dlgtitle,fieldsize,definput);
                table_savename = fullfile(FileLocation,'Cycles',strcat(filename, '.csv'));
                if isfile(table_savename)
                    msgbox(["There is already a file with this name saved in the selected directory:"; ...
                        table_savename])
                else
                    writetable(ExperimentRecords,table_savename)
                end
            else
                [filename,fileLoc] = uigetfile('*.csv', ...
                    'Please choose the file you would like to append to:', ...
                    fullfile(FileLocation,'Cycles'));
                table_savename = fullfile(fileLoc,filename);
                writetable(ExperimentRecords,table_savename,'WriteMode','Append',...
                    'WriteVariableNames',false,'WriteRowNames',true)
            end


        end

        % Value changed function: plotcycleaverageCheckBox
        function plotcycleaverageCheckBoxValueChanged(app, event)
            plotCycleAverage(app)
        end

        % Value changed function: shownystagmuscorrecteddataCheckBox
        function shownystagmuscorrecteddataCheckBoxValueChanged(app, event)
            % check to make sure there is nystagmus data calculated and
            % calculate if not
            RE_LARP_nystagmusCorrected = app.filtData(app.fileNum).RE_Velocity_LARP_nystagmusCorrected;
            LE_LARP_nystagmusCorrected = app.filtData(app.fileNum).LE_Velocity_LARP_nystagmusCorrected;
            if isempty(RE_LARP_nystagmusCorrected) && isempty(LE_LARP_nystagmusCorrected)
                correctnystagmus(app,app.fileNum)
                calculateCycleAverage(app)
            end
            
            plotAllCycles(app)
            plotCycleAverage(app)
        end

        % Button pushed function: NystagmusRemovalButton
        function NystagmusRemovalButtonPushed(app, event)
            correctnystagmus(app,app.fileNum)
            calculateCycleAverage_nystagmusCorrected(app)

            plotAllCycles(app)
            plotCycleAverage(app)
        end

        % Value changed function: SelectafolderListBox
        function SelectafolderListBoxValueChanged(app, event)
            loadSegments(app)
        end

        % Button pushed function: CombineIdenticalStimuliButton
        function CombineIdenticalStimuliButtonPushed(app, event)
            filepath = app.FileLocationEditField.Value;
            filepath_segments = fullfile(filepath,"Segments");
            segment_foldername = app.SelectafolderListBox.Value;

            segmentnames = {dir(fullfile(filepath_segments,segment_foldername,'*.mat')).name}';
            [segmentnames_unique,uniqueStim_idx] = unique(extractBefore(segmentnames,'_['));
            if isempty(segmentnames_unique{1}) % sometimes there is no time marker on flags (so no brackets)
                pat = '(' + digitsPattern(1,3) + ').mat';
                [segmentnames_unique,uniqueStim_idx] = unique(extractBefore(segmentnames,pat));
            end

            % make new folder to store original segments
            mkdir(filepath_segments,[segment_foldername ' - original segmentation'])

            for iStim = 1:length(segmentnames_unique)
                
                if iStim == length(segmentnames_unique)
                    fileNums = uniqueStim_idx(iStim):length(segmentnames);
                else
                    fileNums = uniqueStim_idx(iStim):uniqueStim_idx(iStim+1)-1;
                end

                iCount = 0;
                
                for iFile = fileNums
                    iCount = iCount + 1;
                    data_temp(iCount) = load(fullfile(filepath_segments,segment_foldername,segmentnames{iFile})).Data;

                    % move original segments to new folder
                    movefile(fullfile(filepath_segments,segment_foldername,segmentnames{iFile}),...
                        fullfile(filepath_segments,[segment_foldername ' - original segmentation']))
                end
                Data = catStruct(app,data_temp);
                f = fields(data_temp);
                data_temp = struct();
                for k = 1:numel(f)
                    data_temp.(f{k}) = [];
                end

                % save new segment
                if contains(segmentnames_unique{iStim},'DCSine')
                    % is DC waveform
                    pat = 'Offs' + digitsPattern(1,3) + 'uA_';
                    nCyclesPerFile = str2double(extractBetween(segmentnames_unique{iStim},pat,'x'));
                    nFiles = length(Data.start_t);
                    nCycles = string(nCyclesPerFile*nFiles);
                    segmentname_new = strrep(segmentnames_unique{iStim},['_',num2str(nCyclesPerFile),'x'],['_',char(nCycles),'x']);
                elseif contains(segmentnames_unique{iStim},'DCTrap') 
                    pat = '-' + digitsPattern(1,3) + '_' + digitsPattern(1,3) + '_' + digitsPattern(1,3) + '_' ;
                    nCyclesPerFile = str2double(extractBetween(segmentnames_unique{iStim},pat,'x'));
                    nFiles = length(Data.start_t);
                    nCycles = string(nCyclesPerFile*nFiles);
                    segmentname_new = strrep(segmentnames_unique{iStim},['_',num2str(nCyclesPerFile),'x'],['_',char(nCycles),'x']);
                elseif contains(segmentnames_unique{iStim},'PFMSine') 
                    nCyclesPerFile = str2double(extractBetween(segmentnames_unique{iStim},'PFMSine_','x'));
                    nFiles = length(Data.start_t);
                    nCycles = string(nCyclesPerFile*nFiles);
                    segmentname_new = strrep(segmentnames_unique{iStim},['_',num2str(nCyclesPerFile),'x'],['_',char(nCycles),'x']);
                elseif contains(segmentnames_unique{iStim},'PFMRamp')
                    nCyclesPerFile = str2double(extractBetween(segmentnames_unique{iStim},'PFMRamp_','x'));
                    nFiles = length(Data.start_t);
                    nCycles = string(nCyclesPerFile*nFiles);
                    segmentname_new = strrep(segmentnames_unique{iStim},['_',num2str(nCyclesPerFile),'x'],['_',char(nCycles),'x']);
                end
                savename = [segmentname_new '.mat'];
                save(fullfile(filepath_segments,segment_foldername,savename),'Data')
            end

            loadSegments(app)
            
        end

        % Button pushed function: FilterTracesButton_Velocity_2
        function FilterTracesButton_Velocity_2Pushed(app, event)
            
            % set variables for waveform, coil data, and QP params
            QPlength = app.QPlengthmsEditField.Value;
            QPthreshold = app.thresholddps2EditField.Value;
            waveform = app.filtData(app.fileNum).Waveform;
            nCycles = app.filtData(app.fileNum).NumCycles;
            waveform_allCycles = reshape(repmat(waveform,1,nCycles),1,[]);

            RE_Vel_X = app.filtData(app.fileNum).RE_Velocity_X;
            RE_Vel_Y = app.filtData(app.fileNum).RE_Velocity_Y;
            RE_Vel_Z = app.filtData(app.fileNum).RE_Velocity_Z;
            RE_Vel_LARP = app.filtData(app.fileNum).RE_Velocity_LARP;
            RE_Vel_RALP = app.filtData(app.fileNum).RE_Velocity_RALP;

            LE_Vel_X = app.filtData(app.fileNum).LE_Velocity_X;
            LE_Vel_Y = app.filtData(app.fileNum).LE_Velocity_Y;
            LE_Vel_Z = app.filtData(app.fileNum).LE_Velocity_Z;
            LE_Vel_LARP = app.filtData(app.fileNum).LE_Velocity_LARP;
            LE_Vel_RALP = app.filtData(app.fileNum).LE_Velocity_RALP;   

            if app.righteyecoilCheckBox.Value                
                x = RE_Vel_X;
                y = RE_Vel_Y;
                larp = RE_Vel_LARP;
                ralp = RE_Vel_RALP;
                lhrh = RE_Vel_Z;
            else % app.lefteyecoilCheckBox.Value
                x = LE_Vel_X;
                y = LE_Vel_Y;
                larp = LE_Vel_LARP;
                ralp = LE_Vel_RALP;
                lhrh = LE_Vel_Z;
            end
            t = (1:length(RE_Vel_X))/1000;

            
            % remove QPs

            stimOffset = waveform_allCycles(end);
            stimOnInds = find(diff([stimOffset waveform_allCycles]) == waveform_allCycles(1));
            temp = zeros(length(stimOnInds)*51,1);
            for i = 0:50
                temp(i*length(stimOnInds) + 1:(i+1)*length(stimOnInds)) = stimOnInds + i;
            end
            stimOnInds = temp;
            stimOffInds = find(diff([stimOffset waveform_allCycles]) == -waveform_allCycles(1)); 
            temp = zeros(length(stimOffInds)*51,1);
            for i = 0:50
                temp(i*length(stimOffInds) + 1:(i+1)*length(stimOffInds)) = stimOffInds + i;
            end
            stimOffInds = temp;

            stimAxis = app.StimAxisDropDown.Value;
            switch stimAxis
                case "LARP"
                    chosenTrace = larp;
                case "RALP"
                    chosenTrace = ralp;
                case "Z"
                    chosenTrace = lhrh;
            end
            
            % trace_I = cat(1,0,diff(movmedian(chosenTrace,5)));

            % eyeAcc = diff(...
            %     sqrt((x).^2 + (y).^2 + (lhrh).^2));
            trace_I = abs(diff(movmedian(chosenTrace,5)));
            trace_QPonly = trace_I;
            trace_QPonly([stimOnInds stimOffInds]) = 0;

            QPstart = find(trace_QPonly >= QPthreshold) - 5;
            
            QPend = QPstart + QPlength;
            desacc_inds = [];
            for iQP = 1:length(QPstart)
                temp = QPstart(iQP):QPend(iQP);
                desacc_inds = [desacc_inds temp];
            end
            desacc_inds = unique(desacc_inds);
            desacc_inds = desacc_inds(desacc_inds <= length(chosenTrace));
            desacc_bool = false(length(chosenTrace),1);
            desacc_bool(desacc_inds) = 1;
            % desacc_inds = find(trace_diff >= threshold);
            % desacc_inds = unique([find(trace_diff >= threshold), find(trace_diff >= threshold) + 1, find(trace_diff >= threshold) - 1]);
            % desacc_inds = desacc_inds(desacc_inds > 0 & desacc_inds <= 5000);
            % desacc_bool(desacc_bool) = 1;

            larp_desacc = larp;
            larp_desacc(desacc_bool) = [];

            larp_noQPs = nan(size(larp));
            larp_noQPs(desacc_bool == 0) = larp_desacc;
            % larp_smooth(trace_diff <= threshold) = voma__irlssmooth(larp_desacc,20);

            ralp_desacc = ralp;
            ralp_desacc(desacc_inds) = [];

            ralp_noQPs = nan(size(ralp));
            ralp_noQPs(desacc_bool == 0) = ralp_desacc;
            % ralp_smooth(trace_diff <= threshold) = voma__irlssmooth(ralp_desacc,20);

            lhrh_desacc = lhrh;
            lhrh_desacc(desacc_bool) = [];

            lhrh_noQPs = nan(size(lhrh));
            lhrh_noQPs(desacc_bool == 0) = lhrh_desacc;
            % lhrh_smooth(trace_diff <= threshold) = voma__irlssmooth(lhrh_desacc,20);

            x_desacc = x;
            x_desacc(desacc_bool) = [];

            x_noQPs = nan(size(x));
            x_noQPs(desacc_bool == 0) = x_desacc;

            y_desacc = y;
            y_desacc(desacc_bool) = [];

            y_noQPs = nan(size(y));
            y_noQPs(desacc_bool == 0) = y_desacc;

            if app.righteyecoilCheckBox.Value
                app.filtData(app.fileNum).RE_Velocity_X_filt = x_noQPs;
                app.filtData(app.fileNum).RE_Velocity_Y_filt = y_noQPs;
                app.filtData(app.fileNum).RE_Velocity_Z_filt = lhrh_noQPs;
                app.filtData(app.fileNum).RE_Velocity_LARP_filt = larp_noQPs;
                app.filtData(app.fileNum).RE_Velocity_RALP_filt = ralp_noQPs;
            else
                app.filtData(app.fileNum).LE_Velocity_X_filt = x_noQPs;
                app.filtData(app.fileNum).LE_Velocity_Y_filt = y_noQPs;
                app.filtData(app.fileNum).LE_Velocity_Z_filt = lhrh_noQPs;
                app.filtData(app.fileNum).LE_Velocity_LARP_filt = larp_noQPs;
                app.filtData(app.fileNum).LE_Velocity_RALP_filt = ralp_noQPs;
            end

            plotEyeVelocity(app)
            plotEyeVelocity_QP(app)
            plotEyeAcceleration(app)
            plotAllCycles(app)
            plotCycleAverage(app)

        end

        % Value changed function: lefteyecoilCheckBox_4
        function lefteyecoilCheckBox_4ValueChanged(app, event)
            plotEyeVelocity_QP(app)
        end

        % Value changed function: righteyecoilCheckBox_4
        function righteyecoilCheckBox_4ValueChanged(app, event)
            plotEyeVelocity_QP(app)
        end

        % Value changed function: XCheckBox_4
        function XCheckBox_4ValueChanged(app, event)
            plotEyeVelocity_QP(app)
        end

        % Value changed function: YCheckBox_4
        function YCheckBox_4ValueChanged(app, event)
            plotEyeVelocity_QP(app)
        end

        % Value changed function: LARPCheckBox_4
        function LARPCheckBox_4ValueChanged(app, event)
            plotEyeVelocity_QP(app)
        end

        % Value changed function: RALPCheckBox_4
        function RALPCheckBox_4ValueChanged(app, event)
            plotEyeVelocity_QP(app)
        end

        % Value changed function: LHRHZCheckBox_6
        function LHRHZCheckBox_6ValueChanged(app, event)
            plotEyeVelocity_QP(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CycleAverageAxes = uiaxes(app.UIFigure);
            title(app.CycleAverageAxes, 'Cycle Average')
            ylabel(app.CycleAverageAxes, 'eye velocity (dps)')
            zlabel(app.CycleAverageAxes, 'Z')
            app.CycleAverageAxes.XTickLabel = '';
            app.WaveformAxes_2 = uiaxes(app.UIFigure);
            title(app.WaveformAxes_2, 'Waveform')
            xlabel(app.WaveformAxes_2, 'time (ms)')
            ylabel(app.WaveformAxes_2, 'current (uA)')
            zlabel(app.WaveformAxes_2, 'Z')
            app.PositionFilteringParametersPanel = uipanel(app.UIFigure);
            app.PositionFilteringParametersPanel.Title = 'Position Filtering Parameters';
            app.PositionFilteringParametersPanel.Visible = 'off';
            app.PositionFilterParamsTable_movingmedian = uitable(app.PositionFilteringParametersPanel);
            app.PositionFilterParamsTable_movingmedian.ColumnName = '';
            app.PositionFilterParamsTable_movingmedian.RowName = {};
            app.PositionFilterParamsTable_movingmedian.CellEditCallback = createCallbackFcn(app, @PositionFilterParamsTable_movingmedianCellEdit, true);
            app.PositionFilterParamsTable_movingmedian.Multiselect = 'off';
            app.PositionFilterParamsTable_movingmean = uitable(app.PositionFilteringParametersPanel);
            app.PositionFilterParamsTable_movingmean.ColumnName = '';
            app.PositionFilterParamsTable_movingmean.RowName = {};
            app.PositionFilterParamsTable_movingmean.CellEditCallback = createCallbackFcn(app, @PositionFilterParamsTable_movingmeanCellEdit, true);
            app.PositionFilterParamsTable_movingmean.Multiselect = 'off';
            app.PositionFilterParamsTable_savitzkygolay = uitable(app.PositionFilteringParametersPanel);
            app.PositionFilterParamsTable_savitzkygolay.ColumnName = '';
            app.PositionFilterParamsTable_savitzkygolay.RowName = {};
            app.PositionFilterParamsTable_savitzkygolay.CellEditCallback = createCallbackFcn(app, @PositionFilterParamsTable_savitzkygolayCellEdit, true);
            app.PositionFilterParamsTable_savitzkygolay.Multiselect = 'off';
            app.PositionFilterParamsTable_irlss = uitable(app.PositionFilteringParametersPanel);
            app.PositionFilterParamsTable_irlss.ColumnName = '';
            app.PositionFilterParamsTable_irlss.RowName = {};
            app.PositionFilterParamsTable_irlss.CellEditCallback = createCallbackFcn(app, @PositionFilterParamsTable_irlssCellEdit, true);
            app.PositionFilterParamsTable_irlss.Multiselect = 'off';
            app.movingmedianLabel_3 = uilabel(app.PositionFilteringParametersPanel);
            app.movingmedianLabel_3.Text = 'moving median';
            app.movingmeanLabel_3 = uilabel(app.PositionFilteringParametersPanel);
            app.movingmeanLabel_3.Text = 'moving mean';
            app.SavitzkyGolayLabel = uilabel(app.PositionFilteringParametersPanel);
            app.SavitzkyGolayLabel.Text = 'Savitzky-Golay';
            app.irlssLabel = uilabel(app.PositionFilteringParametersPanel);
            app.irlssLabel.Text = 'irlss';
            app.applyPositionFilter1_CheckBox = uicheckbox(app.PositionFilteringParametersPanel);
            app.applyPositionFilter1_CheckBox.ValueChangedFcn = createCallbackFcn(app, @applyPositionFilter1_CheckBoxValueChanged, true);
            app.applyPositionFilter1_CheckBox.Text = '';
            app.applyPositionFilter2_CheckBox = uicheckbox(app.PositionFilteringParametersPanel);
            app.applyPositionFilter2_CheckBox.ValueChangedFcn = createCallbackFcn(app, @applyPositionFilter2_CheckBoxValueChanged, true);
            app.applyPositionFilter2_CheckBox.Text = '';
            app.applyPositionFilter3_CheckBox = uicheckbox(app.PositionFilteringParametersPanel);
            app.applyPositionFilter3_CheckBox.Text = '';
            app.applyPositionFilter4_CheckBox = uicheckbox(app.PositionFilteringParametersPanel);
            app.applyPositionFilter4_CheckBox.Text = '';
            app.applyfilterLabel = uilabel(app.PositionFilteringParametersPanel);
            app.applyfilterLabel.Text = 'apply filter';
            app.applyfilterLabel_2 = uilabel(app.PositionFilteringParametersPanel);
            app.applyfilterLabel_2.Text = 'apply filter';
            app.applyfilterLabel_4 = uilabel(app.PositionFilteringParametersPanel);
            app.applyfilterLabel_4.Text = 'apply filter';
            app.applyfilterLabel_5 = uilabel(app.PositionFilteringParametersPanel);
            app.applyfilterLabel_5.Text = 'apply filter';
            app.CloseButton_Position = uibutton(app.PositionFilteringParametersPanel, 'push');
            app.CloseButton_Position.ButtonPushedFcn = createCallbackFcn(app, @CloseButton_PositionPushed, true);
            app.CloseButton_Position.Text = 'Close';
            app.ApplyFilteringButton_4 = uibutton(app.PositionFilteringParametersPanel, 'push');
            app.ApplyFilteringButton_4.ButtonPushedFcn = createCallbackFcn(app, @ApplyFilteringButton_4Pushed, true);
            app.ApplyFilteringButton_4.Text = 'Apply Filtering';
            app.LoadFilesButton = uibutton(app.UIFigure, 'push');
            app.LoadFilesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFilesButtonPushed, true);
            app.LoadFilesButton.Text = 'Load Files';
            app.FileLocationEditField = uieditfield(app.UIFigure, 'text');
            app.FileLocationEditField.ValueChangedFcn = createCallbackFcn(app, @FileLocationEditFieldValueChanging, true);
            app.FileLocationEditField.ValueChangingFcn = createCallbackFcn(app, @FileLocationEditFieldValueChanging, true);
            app.FileLocationEditField.Editable = 'off';
            app.CycleAnalysisGUILabel = uilabel(app.UIFigure);
            app.CycleAnalysisGUILabel.Text = 'Cycle Analysis GUI ';
            app.createdbyKNM2024Label = uilabel(app.UIFigure);
            app.createdbyKNM2024Label.Text = 'created by KNM 2024';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '1.';
            app.NextFileButton = uibutton(app.UIFigure, 'push');
            app.NextFileButton.ButtonPushedFcn = createCallbackFcn(app, @NextFileButtonPushed, true);
            app.NextFileButton.Text = 'Next File';
            app.PreviousFileButton = uibutton(app.UIFigure, 'push');
            app.PreviousFileButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousFileButtonPushed, true);
            app.PreviousFileButton.Text = 'Previous File';
            app.SelectfileListBoxLabel = uilabel(app.UIFigure);
            app.SelectfileListBoxLabel.Text = 'Select file:';
            app.SelectfileListBox = uilistbox(app.UIFigure);
            app.SelectfileListBox.ValueChangedFcn = createCallbackFcn(app, @SelectfileListBoxValueChanged, true);
            app.VelocityFilteringParametersPanel = uipanel(app.UIFigure);
            app.VelocityFilteringParametersPanel.Title = 'Velocity Filtering Parameters';
            app.VelocityFilteringParametersPanel.Visible = 'off';
            app.VelocityFilterParamsTable_movingmedian = uitable(app.VelocityFilteringParametersPanel);
            app.VelocityFilterParamsTable_movingmedian.ColumnName = '';
            app.VelocityFilterParamsTable_movingmedian.RowName = {};
            app.VelocityFilterParamsTable_movingmedian.Multiselect = 'off';
            app.VelocityFilterParamsTable_movingmean = uitable(app.VelocityFilteringParametersPanel);
            app.VelocityFilterParamsTable_movingmean.ColumnName = '';
            app.VelocityFilterParamsTable_movingmean.RowName = {};
            app.VelocityFilterParamsTable_movingmean.Multiselect = 'off';
            app.VelocityFilterParamsTable_savitzkygolay = uitable(app.VelocityFilteringParametersPanel);
            app.VelocityFilterParamsTable_savitzkygolay.ColumnName = '';
            app.VelocityFilterParamsTable_savitzkygolay.RowName = {};
            app.VelocityFilterParamsTable_savitzkygolay.Multiselect = 'off';
            app.VelocityFilterParamsTable_irlss = uitable(app.VelocityFilteringParametersPanel);
            app.VelocityFilterParamsTable_irlss.ColumnName = '';
            app.VelocityFilterParamsTable_irlss.RowName = {};
            app.VelocityFilterParamsTable_irlss.Multiselect = 'off';
            app.movingmeanLabel_2 = uilabel(app.VelocityFilteringParametersPanel);
            app.movingmeanLabel_2.Text = 'moving mean';
            app.movingmedianLabel_2 = uilabel(app.VelocityFilteringParametersPanel);
            app.movingmedianLabel_2.Text = 'moving median';
            app.SavitzkyGolayLabel_2 = uilabel(app.VelocityFilteringParametersPanel);
            app.SavitzkyGolayLabel_2.Text = 'Savitzky-Golay';
            app.irlssqprLabel = uilabel(app.VelocityFilteringParametersPanel);
            app.irlssqprLabel.Text = 'irlss qpr';
            app.applyVelocityFilter1_CheckBox = uicheckbox(app.VelocityFilteringParametersPanel);
            app.applyVelocityFilter1_CheckBox.Text = '';
            app.applyVelocityFilter2_CheckBox = uicheckbox(app.VelocityFilteringParametersPanel);
            app.applyVelocityFilter2_CheckBox.Text = '';
            app.applyVelocityFilter3_CheckBox = uicheckbox(app.VelocityFilteringParametersPanel);
            app.applyVelocityFilter3_CheckBox.Text = '';
            app.applyVelocityFilter4_CheckBox = uicheckbox(app.VelocityFilteringParametersPanel);
            app.applyVelocityFilter4_CheckBox.Text = '';
            app.applyfilterLabel_6 = uilabel(app.VelocityFilteringParametersPanel);
            app.applyfilterLabel_6.Text = 'apply filter';
            app.applyfilterLabel_7 = uilabel(app.VelocityFilteringParametersPanel);
            app.applyfilterLabel_7.Text = 'apply filter';
            app.applyfilterLabel_8 = uilabel(app.VelocityFilteringParametersPanel);
            app.applyfilterLabel_8.Text = 'apply filter';
            app.applyfilterLabel_9 = uilabel(app.VelocityFilteringParametersPanel);
            app.applyfilterLabel_9.Text = 'apply filter';
            app.VelocityFilterParamsTable_spline = uitable(app.VelocityFilteringParametersPanel);
            app.VelocityFilterParamsTable_spline.ColumnName = '';
            app.VelocityFilterParamsTable_spline.RowName = {};
            app.VelocityFilterParamsTable_spline.Multiselect = 'off';
            app.splineLabel = uilabel(app.VelocityFilteringParametersPanel);
            app.splineLabel.Text = 'spline';
            app.applyVelocityFilter5_CheckBox = uicheckbox(app.VelocityFilteringParametersPanel);
            app.applyVelocityFilter5_CheckBox.Text = '';
            app.applyfilterLabel_10 = uilabel(app.VelocityFilteringParametersPanel);
            app.applyfilterLabel_10.Text = 'apply filter';
            app.CloseButton_Velocity = uibutton(app.VelocityFilteringParametersPanel, 'push');
            app.CloseButton_Velocity.ButtonPushedFcn = createCallbackFcn(app, @CloseButton_VelocityPushed, true);
            app.CloseButton_Velocity.Text = 'Close';
            app.ApplyFilteringButton_5 = uibutton(app.VelocityFilteringParametersPanel, 'push');
            app.ApplyFilteringButton_5.ButtonPushedFcn = createCallbackFcn(app, @ApplyFilteringButton_5Pushed, true);
            app.ApplyFilteringButton_5.Text = 'Apply Filtering';
            app.NystagmusRemovalButton = uibutton(app.VelocityFilteringParametersPanel, 'push');
            app.NystagmusRemovalButton.ButtonPushedFcn = createCallbackFcn(app, @NystagmusRemovalButtonPushed, true);
            app.NystagmusRemovalButton.Text = 'Nystagmus Removal';
            app.autoresizeGUIcomponentsCheckBox = uicheckbox(app.UIFigure);
            app.autoresizeGUIcomponentsCheckBox.ValueChangedFcn = createCallbackFcn(app, @autoresizeGUIcomponentsCheckBoxValueChanged, true);
            app.autoresizeGUIcomponentsCheckBox.Text = '';
            app.autoresizeGUIcomponentsLabel = uilabel(app.UIFigure);
            app.autoresizeGUIcomponentsLabel.Text = 'auto-resize GUI components';
            app.SaveCyclesParametersButton = uibutton(app.UIFigure, 'push');
            app.SaveCyclesParametersButton.ButtonPushedFcn = createCallbackFcn(app, @SaveCyclesParametersButtonPushed, true);
            app.SaveCyclesParametersButton.Text = 'Save Cycles & Parameters';
            app.fLabel = uilabel(app.UIFigure);
            app.fLabel.Text = '2f.';
            app.plotcycleaverageCheckBox = uicheckbox(app.UIFigure);
            app.plotcycleaverageCheckBox.ValueChangedFcn = createCallbackFcn(app, @plotcycleaverageCheckBoxValueChanged, true);
            app.plotcycleaverageCheckBox.Text = {'plot cycle'; 'average'};
            app.DataTypeLabel = uilabel(app.UIFigure);
            app.DataTypeLabel.Text = 'Data Type:';
            app.DataTypeDropDown = uidropdown(app.UIFigure);
            app.DataTypeDropDown.Items = {'TrapezoidStim', 'SineStim'};
            app.DataTypeDropDown.Value = 'TrapezoidStim';
            app.StimAxisDropDownLabel = uilabel(app.UIFigure);
            app.StimAxisDropDownLabel.Text = 'Stim Axis:';
            app.StimAxisDropDown = uidropdown(app.UIFigure);
            app.StimAxisDropDown.Items = {'LARP', 'RALP', 'Z'};
            app.StimAxisDropDown.Value = 'Z';
            app.CreateCycleFiguresButton = uibutton(app.UIFigure, 'push');
            app.CreateCycleFiguresButton.Text = 'Create Cycle Figures';
            app.SelectafolderListBoxLabel = uilabel(app.UIFigure);
            app.SelectafolderListBoxLabel.Text = 'Select a folder:';
            app.SelectafolderListBox = uilistbox(app.UIFigure);
            app.SelectafolderListBox.ValueChangedFcn = createCallbackFcn(app, @SelectafolderListBoxValueChanged, true);
            app.aLabel = uilabel(app.UIFigure);
            app.aLabel.Text = '2a.';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.bPositionFilteringTab = uitab(app.TabGroup);
            app.bPositionFilteringTab.Title = '2b. Position Filtering';
            app.EyePositionAxes = uiaxes(app.bPositionFilteringTab);
            title(app.EyePositionAxes, 'Eye Position')
            xlabel(app.EyePositionAxes, 'time (s)')
            ylabel(app.EyePositionAxes, 'eye position (degs)')
            zlabel(app.EyePositionAxes, 'Z')
            app.XCheckBox_2 = uicheckbox(app.bPositionFilteringTab);
            app.XCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @XCheckBox_2ValueChanged, true);
            app.XCheckBox_2.Text = 'X';
            app.XCheckBox_2.Value = true;
            app.YCheckBox_2 = uicheckbox(app.bPositionFilteringTab);
            app.YCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @YCheckBox_2ValueChanged, true);
            app.YCheckBox_2.Text = 'Y';
            app.YCheckBox_2.Value = true;
            app.DisplayLabel = uilabel(app.bPositionFilteringTab);
            app.DisplayLabel.Text = 'Display:';
            app.ZCheckBox = uicheckbox(app.bPositionFilteringTab);
            app.ZCheckBox.ValueChangedFcn = createCallbackFcn(app, @ZCheckBoxValueChanged, true);
            app.ZCheckBox.Text = 'Z';
            app.ZCheckBox.Value = true;
            app.lefteyecoilCheckBox = uicheckbox(app.bPositionFilteringTab);
            app.lefteyecoilCheckBox.ValueChangedFcn = createCallbackFcn(app, @lefteyecoilCheckBoxValueChanged, true);
            app.lefteyecoilCheckBox.Text = 'left eye coil';
            app.righteyecoilCheckBox = uicheckbox(app.bPositionFilteringTab);
            app.righteyecoilCheckBox.ValueChangedFcn = createCallbackFcn(app, @righteyecoilCheckBoxValueChanged, true);
            app.righteyecoilCheckBox.Text = 'right eye coil';
            app.righteyecoilCheckBox.Value = true;
            app.showrawdataCheckBox_2 = uicheckbox(app.bPositionFilteringTab);
            app.showrawdataCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @showrawdataCheckBox_2ValueChanged, true);
            app.showrawdataCheckBox_2.Text = 'show raw data';
            app.ResetFilteringButton_Position = uibutton(app.bPositionFilteringTab, 'push');
            app.ResetFilteringButton_Position.ButtonPushedFcn = createCallbackFcn(app, @ResetFilteringButton_PositionPushed, true);
            app.ResetFilteringButton_Position.Text = 'Reset Filtering';
            app.FilterTracesButton_Position = uibutton(app.bPositionFilteringTab, 'push');
            app.FilterTracesButton_Position.ButtonPushedFcn = createCallbackFcn(app, @FilterTracesButton_PositionPushed, true);
            app.FilterTracesButton_Position.Text = 'Filter Traces';
            app.cQPRemovalTab = uitab(app.TabGroup);
            app.cQPRemovalTab.Title = '2c. QP Removal';
            app.EyeAccelerationAxes = uiaxes(app.cQPRemovalTab);
            title(app.EyeAccelerationAxes, 'Eye Acceleration')
            xlabel(app.EyeAccelerationAxes, 'time (s)')
            ylabel(app.EyeAccelerationAxes, 'eye acceleration (dps2)')
            zlabel(app.EyeAccelerationAxes, 'Z')
            app.EyeVelocityAxes_QP = uiaxes(app.cQPRemovalTab);
            title(app.EyeVelocityAxes_QP, 'Eye Velocity')
            xlabel(app.EyeVelocityAxes_QP, 'time (s)')
            ylabel(app.EyeVelocityAxes_QP, 'eye velocity (dps)')
            zlabel(app.EyeVelocityAxes_QP, 'Z')
            app.XCheckBox_4 = uicheckbox(app.cQPRemovalTab);
            app.XCheckBox_4.ValueChangedFcn = createCallbackFcn(app, @XCheckBox_4ValueChanged, true);
            app.XCheckBox_4.Text = 'X';
            app.XCheckBox_4.Value = true;
            app.YCheckBox_4 = uicheckbox(app.cQPRemovalTab);
            app.YCheckBox_4.ValueChangedFcn = createCallbackFcn(app, @YCheckBox_4ValueChanged, true);
            app.YCheckBox_4.Text = 'Y';
            app.YCheckBox_4.Value = true;
            app.LARPCheckBox_4 = uicheckbox(app.cQPRemovalTab);
            app.LARPCheckBox_4.ValueChangedFcn = createCallbackFcn(app, @LARPCheckBox_4ValueChanged, true);
            app.LARPCheckBox_4.Text = 'LARP';
            app.LARPCheckBox_4.Value = true;
            app.RALPCheckBox_4 = uicheckbox(app.cQPRemovalTab);
            app.RALPCheckBox_4.ValueChangedFcn = createCallbackFcn(app, @RALPCheckBox_4ValueChanged, true);
            app.RALPCheckBox_4.Text = 'RALP';
            app.RALPCheckBox_4.Value = true;
            app.LHRHZCheckBox_6 = uicheckbox(app.cQPRemovalTab);
            app.LHRHZCheckBox_6.ValueChangedFcn = createCallbackFcn(app, @LHRHZCheckBox_6ValueChanged, true);
            app.LHRHZCheckBox_6.Text = 'LHRH/Z';
            app.LHRHZCheckBox_6.Value = true;
            app.righteyecoilCheckBox_4 = uicheckbox(app.cQPRemovalTab);
            app.righteyecoilCheckBox_4.ValueChangedFcn = createCallbackFcn(app, @righteyecoilCheckBox_4ValueChanged, true);
            app.righteyecoilCheckBox_4.Text = 'right eye coil';
            app.righteyecoilCheckBox_4.Value = true;
            app.lefteyecoilCheckBox_4 = uicheckbox(app.cQPRemovalTab);
            app.lefteyecoilCheckBox_4.ValueChangedFcn = createCallbackFcn(app, @lefteyecoilCheckBox_4ValueChanged, true);
            app.lefteyecoilCheckBox_4.Text = 'left eye coil';
            app.DisplayLabel_4 = uilabel(app.cQPRemovalTab);
            app.DisplayLabel_4.Text = 'Display:';
            app.QPlengthmsEditFieldLabel = uilabel(app.cQPRemovalTab);
            app.QPlengthmsEditFieldLabel.Text = 'QP length (ms)';
            app.QPlengthmsEditField = uieditfield(app.cQPRemovalTab, 'numeric');
            app.QPlengthmsEditField.Value = 50;
            app.thresholddps2EditFieldLabel = uilabel(app.cQPRemovalTab);
            app.thresholddps2EditFieldLabel.Text = 'threshold (dps2)';
            app.thresholddps2EditField = uieditfield(app.cQPRemovalTab, 'numeric');
            app.ResetFilteringButton_Velocity_2 = uibutton(app.cQPRemovalTab, 'push');
            app.ResetFilteringButton_Velocity_2.Text = 'Reset Filtering';
            app.FilterTracesButton_Velocity_2 = uibutton(app.cQPRemovalTab, 'push');
            app.FilterTracesButton_Velocity_2.ButtonPushedFcn = createCallbackFcn(app, @FilterTracesButton_Velocity_2Pushed, true);
            app.FilterTracesButton_Velocity_2.Text = 'Filter Traces';
            app.dVelocityFilteringTab = uitab(app.TabGroup);
            app.dVelocityFilteringTab.Title = '2d. Velocity Filtering';
            app.EyeVelocityAxes = uiaxes(app.dVelocityFilteringTab);
            title(app.EyeVelocityAxes, 'Eye Velocity')
            xlabel(app.EyeVelocityAxes, 'time (s)')
            ylabel(app.EyeVelocityAxes, 'eye velocity (dps)')
            zlabel(app.EyeVelocityAxes, 'Z')
            app.XCheckBox_3 = uicheckbox(app.dVelocityFilteringTab);
            app.XCheckBox_3.ValueChangedFcn = createCallbackFcn(app, @XCheckBox_3ValueChanged, true);
            app.XCheckBox_3.Text = 'X';
            app.XCheckBox_3.Value = true;
            app.YCheckBox_3 = uicheckbox(app.dVelocityFilteringTab);
            app.YCheckBox_3.ValueChangedFcn = createCallbackFcn(app, @YCheckBox_3ValueChanged, true);
            app.YCheckBox_3.Text = 'Y';
            app.YCheckBox_3.Value = true;
            app.LARPCheckBox_3 = uicheckbox(app.dVelocityFilteringTab);
            app.LARPCheckBox_3.ValueChangedFcn = createCallbackFcn(app, @LARPCheckBox_3ValueChanged, true);
            app.LARPCheckBox_3.Text = 'LARP';
            app.LARPCheckBox_3.Value = true;
            app.RALPCheckBox_3 = uicheckbox(app.dVelocityFilteringTab);
            app.RALPCheckBox_3.ValueChangedFcn = createCallbackFcn(app, @RALPCheckBox_3ValueChanged, true);
            app.RALPCheckBox_3.Text = 'RALP';
            app.RALPCheckBox_3.Value = true;
            app.LHRHZCheckBox_5 = uicheckbox(app.dVelocityFilteringTab);
            app.LHRHZCheckBox_5.ValueChangedFcn = createCallbackFcn(app, @LHRHZCheckBox_5ValueChanged, true);
            app.LHRHZCheckBox_5.Text = 'LHRH/Z';
            app.LHRHZCheckBox_5.Value = true;
            app.showunfiltereddataCheckBox = uicheckbox(app.dVelocityFilteringTab);
            app.showunfiltereddataCheckBox.ValueChangedFcn = createCallbackFcn(app, @showunfiltereddataCheckBoxValueChanged, true);
            app.showunfiltereddataCheckBox.Text = 'show unfiltered data';
            app.righteyecoilCheckBox_2 = uicheckbox(app.dVelocityFilteringTab);
            app.righteyecoilCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @righteyecoilCheckBox_2ValueChanged2, true);
            app.righteyecoilCheckBox_2.Text = 'right eye coil';
            app.righteyecoilCheckBox_2.Value = true;
            app.lefteyecoilCheckBox_2 = uicheckbox(app.dVelocityFilteringTab);
            app.lefteyecoilCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @lefteyecoilCheckBox_2ValueChanged, true);
            app.lefteyecoilCheckBox_2.Text = 'left eye coil';
            app.ResetFilteringButton_Velocity = uibutton(app.dVelocityFilteringTab, 'push');
            app.ResetFilteringButton_Velocity.ButtonPushedFcn = createCallbackFcn(app, @ResetFilteringButton_VelocityPushed, true);
            app.ResetFilteringButton_Velocity.Text = 'Reset Filtering';
            app.DisplayLabel_2 = uilabel(app.dVelocityFilteringTab);
            app.DisplayLabel_2.Text = 'Display:';
            app.FilterTracesButton_Velocity = uibutton(app.dVelocityFilteringTab, 'push');
            app.FilterTracesButton_Velocity.ButtonPushedFcn = createCallbackFcn(app, @FilterTracesButton_VelocityPushed, true);
            app.FilterTracesButton_Velocity.Text = 'Filter Traces';
            app.eCycleFilteringTab = uitab(app.TabGroup);
            app.eCycleFilteringTab.Title = '2e. Cycle Filtering';
            app.AllCyclesAxes = uiaxes(app.eCycleFilteringTab);
            title(app.AllCyclesAxes, 'All Cycles')
            ylabel(app.AllCyclesAxes, 'eye velocity (dps)')
            zlabel(app.AllCyclesAxes, 'Z')
            app.AllCyclesAxes.XTickLabel = '';
            app.WaveformAxes = uiaxes(app.eCycleFilteringTab);
            title(app.WaveformAxes, 'Waveform')
            xlabel(app.WaveformAxes, 'time (s)')
            ylabel(app.WaveformAxes, 'current (uA)')
            zlabel(app.WaveformAxes, 'Z')
            app.LARPCheckBox_2 = uicheckbox(app.eCycleFilteringTab);
            app.LARPCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @LARPCheckBox_2ValueChanged, true);
            app.LARPCheckBox_2.Text = 'LARP';
            app.LARPCheckBox_2.Value = true;
            app.RALPCheckBox_2 = uicheckbox(app.eCycleFilteringTab);
            app.RALPCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @RALPCheckBox_2ValueChanged, true);
            app.RALPCheckBox_2.Text = 'RALP';
            app.RALPCheckBox_2.Value = true;
            app.LHRHCheckBox_2 = uicheckbox(app.eCycleFilteringTab);
            app.LHRHCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @LHRHCheckBox_2ValueChanged, true);
            app.LHRHCheckBox_2.Text = 'LHRH';
            app.LHRHCheckBox_2.Value = true;
            app.CyclesListBoxLabel = uilabel(app.eCycleFilteringTab);
            app.CyclesListBoxLabel.Text = 'Cycles:';
            app.CyclesListBox = uilistbox(app.eCycleFilteringTab);
            app.CyclesListBox.Multiselect = 'on';
            app.CyclesListBox.ValueChangedFcn = createCallbackFcn(app, @CyclesListBoxValueChanged, true);
            app.CyclesListBox.Value = {'Item 1'};
            app.ResetButton = uibutton(app.eCycleFilteringTab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.righteyecoilCheckBox_3 = uicheckbox(app.eCycleFilteringTab);
            app.righteyecoilCheckBox_3.ValueChangedFcn = createCallbackFcn(app, @righteyecoilCheckBox_3ValueChanged, true);
            app.righteyecoilCheckBox_3.Text = 'right eye coil';
            app.righteyecoilCheckBox_3.Value = true;
            app.lefteyecoilCheckBox_3 = uicheckbox(app.eCycleFilteringTab);
            app.lefteyecoilCheckBox_3.ValueChangedFcn = createCallbackFcn(app, @lefteyecoilCheckBox_3ValueChanged, true);
            app.lefteyecoilCheckBox_3.Text = 'left eye coil';
            app.linkxaxesCheckBox = uicheckbox(app.eCycleFilteringTab);
            app.linkxaxesCheckBox.ValueChangedFcn = createCallbackFcn(app, @linkxaxesCheckBoxValueChanged, true);
            app.linkxaxesCheckBox.Text = 'link x axes';
            app.linkxaxesCheckBox.Value = true;
            app.DisplayLabel_3 = uilabel(app.eCycleFilteringTab);
            app.DisplayLabel_3.Text = 'Display:';
            app.ncyclesLabel = uilabel(app.eCycleFilteringTab);
            app.ncyclesLabel.Text = 'n = 20 cycles';
            app.shownystagmuscorrecteddataCheckBox = uicheckbox(app.eCycleFilteringTab);
            app.shownystagmuscorrecteddataCheckBox.ValueChangedFcn = createCallbackFcn(app, @shownystagmuscorrecteddataCheckBoxValueChanged, true);
            app.shownystagmuscorrecteddataCheckBox.Text = {'show nystagmus'; 'corrected data'};
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '3.';
            app.UpdateCumulativeFigureLabel = uilabel(app.UIFigure);
            app.UpdateCumulativeFigureLabel.Text = 'Update Cumulative Figure';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '2.';
            app.AnalyzeDataLabel = uilabel(app.UIFigure);
            app.AnalyzeDataLabel.Text = 'Analyze Data';
            app.CombineIdenticalStimuliButton = uibutton(app.UIFigure, 'push');
            app.CombineIdenticalStimuliButton.ButtonPushedFcn = createCallbackFcn(app, @CombineIdenticalStimuliButtonPushed, true);
            app.CombineIdenticalStimuliButton.Text = {'Combine Identical'; 'Stimuli'};
            app.EarLabel = uilabel(app.UIFigure);
            app.EarLabel.Text = 'Ear:';
            app.EarDropDown = uidropdown(app.UIFigure);
            app.EarDropDown.Items = {'right', 'left'};
            app.EarDropDown.Value = 'right';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = coilanalysis_cyclefiltering

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end