classdef coilanalysis_plotdata < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        SelectXandYfromtableButton     matlab.ui.control.StateButton
        PlotButton                     matlab.ui.control.Button
        ChooseBaselineTypeDropDown     matlab.ui.control.DropDown
        ChooseBaselineTypeDropDownLabel  matlab.ui.control.Label
        ChooseDatesListBox             matlab.ui.control.ListBox
        ChooseDatesListBoxLabel        matlab.ui.control.Label
        StandardendLabel               matlab.ui.control.Label
        StandardstartLabel             matlab.ui.control.Label
        EditField_3                    matlab.ui.control.EditField
        EditField_2                    matlab.ui.control.EditField
        ChooseColorMapDropDown         matlab.ui.control.DropDown
        ChooseColorMapLabel            matlab.ui.control.Label
        TypeofPlotLabel                matlab.ui.control.Label
        ButtonGroup                    matlab.ui.container.ButtonGroup
        OtherButton                    matlab.ui.control.ToggleButton
        CycleAverageandWaveformButton  matlab.ui.control.ToggleButton
        EyeVelocityandMisalignmentButton  matlab.ui.control.ToggleButton
        EditField                      matlab.ui.control.EditField
        LoadTableButton                matlab.ui.control.Button
        SensitivityCorrectedusingstandardsCheckBox  matlab.ui.control.CheckBox
        NystagmusCorrectedCheckBox     matlab.ui.control.CheckBox
        ChooseYDataListBox             matlab.ui.control.ListBox
        ChooseYDataListBoxLabel        matlab.ui.control.Label
        ChooseXDataListBox             matlab.ui.control.ListBox
        ChooseXDataListBoxLabel        matlab.ui.control.Label
        RightPanel                     matlab.ui.container.Panel
        TabGroup                       matlab.ui.container.TabGroup
        Tab                            matlab.ui.container.Tab
        UIAxes_6                       matlab.ui.control.UIAxes
        UIAxes_3                       matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
        Tab_2                          matlab.ui.container.Tab
        DoneButton                     matlab.ui.control.Button
        RemoveRemainingQPsCheckBox     matlab.ui.control.CheckBox
        Show95CICheckBox               matlab.ui.control.CheckBox
        ChooseStimAmplitudeListBox     matlab.ui.control.ListBox
        ChooseStimAmplitudeListBoxLabel  matlab.ui.control.Label
        ChooseBaselineListBox          matlab.ui.control.ListBox
        ChooseBaselineListBoxLabel     matlab.ui.control.Label
        UIAxes_21                      matlab.ui.control.UIAxes
        UIAxes_20                      matlab.ui.control.UIAxes
        UIAxes_19                      matlab.ui.control.UIAxes
        UIAxes_17                      matlab.ui.control.UIAxes
        UIAxes_16                      matlab.ui.control.UIAxes
        UIAxes_15                      matlab.ui.control.UIAxes
        UIAxes_14                      matlab.ui.control.UIAxes
        UIAxes_13                      matlab.ui.control.UIAxes
        UIAxes_12                      matlab.ui.control.UIAxes
        UIAxes_11                      matlab.ui.control.UIAxes
        UIAxes_4                       matlab.ui.control.UIAxes
        UIAxes_2                       matlab.ui.control.UIAxes
        UIAxes_10                      matlab.ui.control.UIAxes
        UIAxes_9                       matlab.ui.control.UIAxes
        Tab_3                          matlab.ui.container.Tab
        UIAxes_5                       matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    % Properties that correspond to public variables
    properties (Access = public)
        Data % data from selected table
        StandardData % data from standard data table
        PlotData % plotted data
        PlotColors % colormap for plotting
    end
    
    methods (Access = private)

        function plotEyeVelvStim(app)
            cla(app.UIAxes,"reset")
            cla(app.UIAxes_3,"reset")
            
            stimoffset = str2double(extractBefore({app.Data.StimOffset}','uA'));
            unique_baselines = unique(stimoffset);
            [~,baseIdx] = sort(abs(unique_baselines));
            unique_baselines = unique_baselines(baseIdx);

            for iBaseline = 1:length(unique_baselines)
                baseline_idx = stimoffset == unique_baselines(iBaseline);
                stimamp1 = [app.Data.StimAmplitude1]';
                [excitatory_stim,amp1Idx] = sort(stimamp1(baseline_idx));
                stimamp2 = [app.Data.StimAmplitude2]';
                [inhibitory_stim,amp2Idx] = sort(stimamp2(baseline_idx));
                current_fromBaseline = [excitatory_stim' inhibitory_stim'];
                current = current_fromBaseline + unique_baselines(iBaseline);
                [modulation, modIdx] = sort(-[excitatory_stim'/max(-excitatory_stim) inhibitory_stim'/max(inhibitory_stim)] * 100);

                % eye velocity
                if app.NystagmusCorrectedCheckBox.Value % show nystagmus corrected data
                    excitatoryEyeVelData = [app.Data.EyeVel_Magnitude_Cathodic_avg_nystagmusCorrected];
                    inhibitoryEyeVelData = [app.Data.EyeVel_Magnitude_Anodic_avg_nystagmusCorrected];
                    excitatoryEyeVelData_std = [app.Data.EyeVel_Magnitude_Cathodic_std_nystagmusCorrected];
                    inhibitoryEyeVelData_std = [app.Data.EyeVel_Magnitude_Anodic_std_nystagmusCorrected];
                else % show data without nystagmus correction
                    excitatoryEyeVelData = [app.Data.EyeVel_Magnitude_Cathodic_avg];
                    inhibitoryEyeVelData = [app.Data.EyeVel_Magnitude_Anodic_avg];
                    excitatoryEyeVelData_std = [app.Data.EyeVel_Magnitude_Cathodic_std];
                    inhibitoryEyeVelData_std = [app.Data.EyeVel_Magnitude_Anodic_std];
                end

                excitatoryResponse = excitatoryEyeVelData(baseline_idx);
                inhibitoryResponse = -inhibitoryEyeVelData(baseline_idx);
                excitatoryResponse_std = excitatoryEyeVelData_std(baseline_idx);
                inhibitoryResponse_std = inhibitoryEyeVelData_std(baseline_idx);

                eyeVel_vcurrent = [excitatoryResponse(amp1Idx) inhibitoryResponse(amp2Idx)];
                eyeVel_vmodulation = eyeVel_vcurrent(modIdx);
                eyeVel_vcurrent_std = [excitatoryResponse_std(amp1Idx) inhibitoryResponse_std(amp2Idx)];
                eyeVel_vmodulation_std = eyeVel_vcurrent_std(modIdx);

                % misalignment
                if app.NystagmusCorrectedCheckBox.Value % show nystagmus corrected data
                    excitatoryMisalignmentData = [app.Data.EyeVel_Misalignment_Cathodic_avg_nystagmusCorrected];
                    inhibitoryMisalignmentData = [app.Data.EyeVel_Misalignment_Anodic_avg_nystagmusCorrected];
                    excitatoryMisalignmentData_std = [app.Data.EyeVel_Misalignment_Cathodic_std_nystagmusCorrected];
                    inhibitoryMisalignmentData_std = [app.Data.EyeVel_Misalignment_Anodic_std_nystagmusCorrected];
                else % show data without nystagmus correction
                    excitatoryMisalignmentData = [app.Data.EyeVel_Misalignment_Cathodic_avg];
                    inhibitoryMisalignmentData = [app.Data.EyeVel_Misalignment_Anodic_avg];
                    excitatoryMisalignmentData_std = [app.Data.EyeVel_Misalignment_Cathodic_std];
                    inhibitoryMisalignmentData_std = [app.Data.EyeVel_Misalignment_Anodic_std];
                end

                excitatoryResponse_Misalignment = excitatoryMisalignmentData(baseline_idx);
                inhibitoryResponse_Misalignment = inhibitoryMisalignmentData(baseline_idx);
                excitatoryResponse_Misalignment_std = excitatoryMisalignmentData_std(baseline_idx);
                inhibitoryResponse_Misalignment_std = inhibitoryMisalignmentData_std(baseline_idx);

                misalignment_vcurrent = [excitatoryResponse_Misalignment(amp1Idx) inhibitoryResponse_Misalignment(amp2Idx)];
                misalignment_vmodulation = misalignment_vcurrent(modIdx);
                misalignment_vcurrent_std = [excitatoryResponse_Misalignment_std(amp1Idx) inhibitoryResponse_Misalignment_std(amp2Idx)];
                misalignment_vmodulation_std = misalignment_vcurrent_std(modIdx);

                switch app.ChooseXDataListBox.Value
                    case "current (uA)"
                        Xdata = current;
                        Ydata = eyeVel_vcurrent;
                        Ydata_std = eyeVel_vcurrent_std;
                        Ydata_misalignment = misalignment_vcurrent;
                        Ydata_misalignment_std = misalignment_vcurrent_std;
                    case "current from baseline (uA)"
                        Xdata = current_fromBaseline;
                        Ydata = eyeVel_vcurrent;
                        Ydata_std = eyeVel_vcurrent_std;
                        Ydata_misalignment = misalignment_vcurrent;
                        Ydata_misalignment_std = misalignment_vcurrent_std;
                    case "modulation (%)"
                        Xdata = modulation;
                        Ydata = eyeVel_vmodulation;
                        Ydata_std = eyeVel_vmodulation_std;
                        Ydata_misalignment = misalignment_vmodulation;
                        Ydata_misalignment_std = misalignment_vmodulation_std;
                end

                % plot eye velocity
                p(iBaseline) = errorbar(app.UIAxes,Xdata,Ydata,Ydata_std,'.-','Color',app.PlotColors(iBaseline,:));
                xline(app.UIAxes,0,'-','Color',[0.5 0.5 0.5])
                yline(app.UIAxes,1,'-','Color',[0.5 0.5 0.5])
                hold(app.UIAxes,"on")
                box(app.UIAxes,"off")
                ytickformat(app.UIAxes,'%+4.4g')

                % plot misalignment
                errorbar(app.UIAxes_3,Xdata,Ydata_misalignment,Ydata_misalignment_std,'.-', 'Color',app.PlotColors(iBaseline,:))
                xline(app.UIAxes_3,0,'-','Color',[0.5 0.5 0.5])
                hold(app.UIAxes_3,"on")

                % link eye velocity magnitude and misalignment plots
                linkaxes([app.UIAxes,app.UIAxes_3],'x')

                app.PlotData.EyeVelvMisalignment{iBaseline} = [Xdata; Ydata; Ydata_std; Ydata_misalignment; Ydata_misalignment_std];
            end
            xlabel(app.UIAxes_3,app.ChooseXDataListBox.Value)
            ylabel(app.UIAxes,app.ChooseYDataListBox.Value)
            ylabel(app.UIAxes_3,"misalignment (dps)")
            xlabel(app.UIAxes_6,"relative experiment time (assumed baseline spacing)")
            ylabel(app.UIAxes_6,app.ChooseYDataListBox.Value)
            leg = legend(app.UIAxes,[p(:)],num2str(unique_baselines),'Location','northwest');
            title(leg,'baselines:')
            fontsize(leg,5,'points')
            ytickformat(app.UIAxes_3,'%+4.4g')
            title(app.UIAxes,"Eye Velocity and Misalignment")

            % adjust axes
            axis(app.UIAxes, 'tight')
            yl = ylim(app.UIAxes);
            ylim(app.UIAxes, yl)
            ylim(app.UIAxes, ylim(app.UIAxes) + [-1,1]*range(ylim(app.UIAxes)).* 0.05)
            xlim(app.UIAxes, xlim(app.UIAxes) + [-1,1]*range(xlim(app.UIAxes)).* 0.05)
            xticklabels(app.UIAxes,{})
            box(app.UIAxes,"off")
            
            axis(app.UIAxes_3, 'tight')
            yl = ylim(app.UIAxes_3);
            ylim(app.UIAxes_3, yl)
            ylim(app.UIAxes_3, ylim(app.UIAxes_3) + [-1,1]*range(ylim(app.UIAxes_3)).* 0.05)
            xlim(app.UIAxes_3, xlim(app.UIAxes_3) + [-1,1]*range(xlim(app.UIAxes_3)).* 0.05)
            xticklabels(app.UIAxes_3,{})
            box(app.UIAxes_3,"off")

%             app.UIAxes.Position = [1 306 522 195];
%             app.UIAxes.PlotBoxAspectRatio = [3.36879432624113 1 1];
            
%             app.UIAxes_3.Position = [1 132 522 195];
%             app.UIAxes_3.PlotBoxAspectRatio = [3.36879432624113 1 1];
        end
        
        function plotSensitivity(app)
            cla(app.UIAxes_6)

            if app.NystagmusCorrectedCheckBox.Value % show nystagmus corrected data
                eyeVel_start = [app.StandardData.Start.EyeVel_Magnitude_Cathodic_avg_nystagmusCorrected app.StandardData.Start.EyeVel_Magnitude_Anodic_avg_nystagmusCorrected];
                eyeVel_start_std = [app.StandardData.Start.EyeVel_Magnitude_Cathodic_std_nystagmusCorrected app.StandardData.Start.EyeVel_Magnitude_Anodic_std_nystagmusCorrected];

                eyeVel_end = [app.StandardData.End.EyeVel_Magnitude_Cathodic_avg_nystagmusCorrected app.StandardData.End.EyeVel_Magnitude_Anodic_avg_nystagmusCorrected];
                eyeVel_end_std = [app.StandardData.End.EyeVel_Magnitude_Cathodic_std_nystagmusCorrected app.StandardData.End.EyeVel_Magnitude_Anodic_std_nystagmusCorrected];
            else % show data without nystagmus correction
                eyeVel_start = [app.StandardData.Start.EyeVel_Magnitude_Cathodic_avg app.StandardData.Start.EyeVel_Magnitude_Anodic_avg];
                eyeVel_start_std = [app.StandardData.Start.EyeVel_Magnitude_Cathodic_std app.StandardData.Start.EyeVel_Magnitude_Anodic_std];

                eyeVel_end = [app.StandardData.End.EyeVel_Magnitude_Cathodic_avg app.StandardData.End.EyeVel_Magnitude_Anodic_avg];
                eyeVel_end_std = [app.StandardData.End.EyeVel_Magnitude_Cathodic_std app.StandardData.End.EyeVel_Magnitude_Anodic_std];
            end

            dateIdx_start = find([app.StandardData.Start.Date] == str2double(app.ChooseDatesListBox.Value{:}));
            dateIdx_end = find([app.StandardData.End.Date] == str2double(app.ChooseDatesListBox.Value{:}));

            Ydata = [eyeVel_start(dateIdx_start) eyeVel_end(dateIdx_end)];
            Ydata_std = [eyeVel_start_std(dateIdx_start) eyeVel_end_std(dateIdx_end)];

            stimoffset = str2double(extractBefore({app.Data.StimOffset}','uA'));
            unique_baselines = unique(stimoffset);
            nBaselines = length(unique_baselines);
            Xdata = [1,nBaselines];
            Xdata_interp = 1:nBaselines;

            Ydata_interp = interp1(Xdata,Ydata,Xdata_interp);
            
            plot(app.UIAxes_6,Xdata_interp,Ydata_interp,'.-k')
            hold(app.UIAxes_6,"on")
            errorbar(app.UIAxes_6,Xdata,Ydata,Ydata_std,'ok')

            axis(app.UIAxes_6, 'tight')
            yl = ylim(app.UIAxes_6);
            ylim(app.UIAxes_6, yl)
            ylim(app.UIAxes_6, ylim(app.UIAxes_6) + [-1,1]*range(ylim(app.UIAxes_6)).* 0.05)
            xlim(app.UIAxes_6, xlim(app.UIAxes_6) + [-1,1]*range(xlim(app.UIAxes_6)).* 0.05)

            app.PlotData.Sensitivity.Cathodic = [Xdata_interp; Ydata_interp];

            if app.NystagmusCorrectedCheckBox.Value % show nystagmus corrected data
                eyeVel_start_anodic = [app.StandardData.Start.EyeVel_Magnitude_Anodic_avg_nystagmusCorrected app.StandardData.Start.EyeVel_Magnitude_Anodic_avg_nystagmusCorrected];
                eyeVel_start_anodic_std = [app.StandardData.Start.EyeVel_Magnitude_Anodic_std_nystagmusCorrected app.StandardData.Start.EyeVel_Magnitude_Anodic_std_nystagmusCorrected];

                eyeVel_end_anodic  = [app.StandardData.End.EyeVel_Magnitude_Anodic_avg_nystagmusCorrected app.StandardData.End.EyeVel_Magnitude_Anodic_avg_nystagmusCorrected];
                eyeVel_end_anodic_std = [app.StandardData.End.EyeVel_Magnitude_Anodic_std_nystagmusCorrected app.StandardData.End.EyeVel_Magnitude_Anodic_std_nystagmusCorrected];
            else % show data without nystagmus correction
                eyeVel_start_anodic = [app.StandardData.Start.EyeVel_Magnitude_Anodic_avg app.StandardData.Start.EyeVel_Magnitude_Anodic_avg];
                eyeVel_start_anodic_std = [app.StandardData.Start.EyeVel_Magnitude_Anodic_std app.StandardData.Start.EyeVel_Magnitude_Anodic_std];

                eyeVel_end_anodic  = [app.StandardData.End.EyeVel_Magnitude_Anodic_avg app.StandardData.End.EyeVel_Magnitude_Anodic_avg];
                eyeVel_end_anodic_std = [app.StandardData.End.EyeVel_Magnitude_Anodic_std app.StandardData.End.EyeVel_Magnitude_Anodic_std];
            end

            Ydata_anodic  = [eyeVel_start(dateIdx_start) eyeVel_end(dateIdx_end)];
            Ydata_anodic_std = [eyeVel_start_std(dateIdx_start) eyeVel_end_std(dateIdx_end)];

            Ydata_anodic_interp = interp1(Xdata,Ydata_anodic,Xdata_interp);

            app.PlotData.Sensitivity.Anodic = [Xdata_interp; Ydata_anodic_interp];

            if app.SensitivityCorrectedusingstandardsCheckBox.Value 
                plotSensitivityAdjustedData(app)
            end
        end
        
        function plotSensitivityAdjustedData(app)
            leg_text = get(legend(app.UIAxes),'String');
            cla(app.UIAxes)
            standard_eyeVel_cathodic = app.PlotData.Sensitivity.Cathodic(2,:);
            standard_eyeVel_anodic = app.PlotData.Sensitivity.Anodic(2,:);

            for iBaseline = 1:length(app.PlotData.Sensitivity.Cathodic)
                eyeVelData = app.PlotData.EyeVelvMisalignment{iBaseline}(2,:);
                anodicIdx = app.PlotData.EyeVelvMisalignment{iBaseline}(1,:) < 0;
                eyeVelDataCathodic = eyeVelData(~anodicIdx);
                eyeVelDataAnodic = eyeVelData(anodicIdx);
                sensitivityAdjustmentFactor_cathodic = standard_eyeVel_cathodic(1)/standard_eyeVel_cathodic(iBaseline);
                eyeVelData_adjusted_cathodic = eyeVelDataCathodic * sensitivityAdjustmentFactor_cathodic;
                sensitivityAdjustmentFactor_anodic = standard_eyeVel_anodic(1)/standard_eyeVel_anodic(iBaseline);
                eyeVelData_adjusted_anodic = eyeVelDataAnodic * sensitivityAdjustmentFactor_anodic;
                
                eyeVelData_adjusted = [eyeVelData_adjusted_anodic eyeVelData_adjusted_cathodic];

                Xdata = app.PlotData.EyeVelvMisalignment{iBaseline}(1,:);

                p(iBaseline) = plot(app.UIAxes,Xdata,eyeVelData_adjusted,'.-','MarkerSize',8,'Color',app.PlotColors(iBaseline,:));
                hold(app.UIAxes,"on")
                xline(app.UIAxes,0,'-','Color',[0.5 0.5 0.5])
                yline(app.UIAxes,1,'-','Color',[0.5 0.5 0.5])
                
            end
            leg = legend(app.UIAxes,[p(:)],leg_text,'Location','northwest');
            title(leg,'baselines:')
            fontsize(leg,5,'points')
        end
        
        function plotEyeVelvTime(app)
            cla(app.UIAxes_2)
            cla(app.UIAxes_4)
            cla(app.UIAxes_9)
            cla(app.UIAxes_10)
            cla(app.UIAxes_11)
            cla(app.UIAxes_12)
            cla(app.UIAxes_13)
            cla(app.UIAxes_14)
            cla(app.UIAxes_15)
            cla(app.UIAxes_16)

            filepaths = {app.Data.FileLocation}';
            cycleFileNames = fullfile(filepaths,'Cycles', {app.Data.CycleName}');
            baselineVal = app.ChooseBaselineListBox.Value;
            stimVal = app.ChooseStimAmplitudeListBox.Value;

            selectedBaseline = app.ChooseBaselineListBox.Value;
            selectedStimType = "DCTrap";
            stimtype_bool = contains(cycleFileNames,selectedStimType);
            fileNames_correctType = cycleFileNames(stimtype_bool);

            baselines = str2double(extractBetween(fileNames_correctType,'Offs','uA_'));
            amp1 = str2double(extractBetween(fileNames_correctType,'Amp1_','_Amp2_'));
            
            selectedMod = app.ChooseStimAmplitudeListBox.Value;
            baseline_bool = contains(fileNames_correctType,['Offs' selectedBaseline]);
            fileNames_selectedBaseline = fileNames_correctType(baseline_bool);
            stimAmplitudes = round(abs((amp1(baseline_bool))/ max(abs(amp1(baseline_bool)))*100),2);
            stimAmplitudes_bool = stimAmplitudes == str2double(extractBefore(selectedMod,'%'));
            selectedFile = fileNames_selectedBaseline{stimAmplitudes_bool};

            data_temp = load(selectedFile).Data;

            % plot waveform
            plot(app.UIAxes_10,[str2double(selectedBaseline) data_temp.Waveform],'k','LineWidth',2)
            axis(app.UIAxes_10, 'tight')
            yl = ylim(app.UIAxes_10);
            ylim(app.UIAxes_10, yl)
            ylim(app.UIAxes_10, ylim(app.UIAxes_10) + [-1,1]*range(ylim(app.UIAxes_10)).* 0.05)
            xlim(app.UIAxes_10, xlim(app.UIAxes_10) + [-1,1]*range(xlim(app.UIAxes_10)).* 0.05)

            keepCycles = str2double(data_temp.KeepCycleNums);

            % plot LARP, RALP, Z
            hold(app.UIAxes_9,'on')
            plot(app.UIAxes_9,data_temp.RE_Velocity_LARP_Cycles(:,keepCycles),'Color',[0.4660 0.6740 0.1880 0.5])
            plot(app.UIAxes_9,data_temp.RE_Velocity_RALP_Cycles(:,keepCycles),'Color',[0 0.4470 0.7410 0.3])
            plot(app.UIAxes_9,data_temp.RE_Velocity_Z_Cycles(:,keepCycles),'Color',[0.6350 0.0780 0.1840 0.3])
            linkaxes([app.UIAxes_9,app.UIAxes_10],'x')

            excitatoryStimIdx = find((data_temp.Waveform - str2double(selectedBaseline)) < 0);
            inhibitoryStimIdx = find((data_temp.Waveform - str2double(selectedBaseline)) > 0);
            firstPhaseIdx = excitatoryStimIdx(1):excitatoryStimIdx(end) + 500;
            firstPhase = [str2double(selectedBaseline) data_temp.Waveform(firstPhaseIdx)];
            secondPhaseIdx = inhibitoryStimIdx(1):inhibitoryStimIdx(end) + 500;
            secondPhase = [str2double(selectedBaseline) data_temp.Waveform(secondPhaseIdx)];

            % plot waveform segments
            plot(app.UIAxes_4,firstPhase,'-k','LineWidth',2)
            plot(app.UIAxes_14,secondPhase,'-k','LineWidth',2)

            yticklabels(app.UIAxes_4,{})
            yticklabels(app.UIAxes_14,{})

            linkaxes([app.UIAxes_21,app.UIAxes_4],'y')
            linkaxes([app.UIAxes_21,app.UIAxes_14],'y')

            % plot LARP
            medianLARP = median(data_temp.RE_Velocity_LARP_Cycles(:,keepCycles),2);

            plot(app.UIAxes_2,data_temp.RE_Velocity_LARP_Cycles(firstPhaseIdx,keepCycles),'Color',[0.4660 0.6740 0.1880 0.5])
            if app.Show95CICheckBox.Value
                hold(app.UIAxes_2,'on')
                
                for iTimePoints = firstPhaseIdx
                    ciLARP(iTimePoints,:) = bootci(50, @median, data_temp.RE_Velocity_LARP_Cycles(iTimePoints,keepCycles));
                end
%                 ciLARP = movmedian(ciLARP,50);
                ciLARP = abs(medianLARP(firstPhaseIdx) - ciLARP);
                
                plot(app.UIAxes_2,medianLARP(firstPhaseIdx),'Color',[0.4660 0.6740 0.1880],'LineWidth',2)
                f = figure('Visible','off');
                p.rz = shadedErrorBar(firstPhaseIdx,medianLARP(firstPhaseIdx),ciLARP,'lineprops',...
                    {'r-','MarkerFaceColor',[0.4660 0.6740 0.1880]});
                set(p.rz.edge,'LineWidth',1,'color',[0.4660 0.6740 0.1880])
                set(p.rz.patch,'facecolor',[0.4660 0.6740 0.1880])
                set(p.rz.mainLine,'color',[0.4660 0.6740 0.1880])
                set(p.rz.mainLine,'LineWidth',2)
                set(p.rz.edge,'LineStyle','-')
                set(p.rz.mainLine,'LineStyle','-')
                copyobj(p.rz.mainLine, app.UIAxes_2)
                copyobj(p.rz.patch, app.UIAxes_2)
                copyobj(p.rz.edge, app.UIAxes_2)
                close(f)
                xticklabels(app.UIAxes_2,{})
                yticklabels(app.UIAxes_2,{})
            end

            plot(app.UIAxes_13,data_temp.RE_Velocity_LARP_Cycles(secondPhaseIdx,keepCycles),'Color',[0.4660 0.6740 0.1880 0.5])
            if app.Show95CICheckBox.Value
                hold(app.UIAxes_13,'on')
                
                iCount = 0;
                ciLARP = [];
                for iTimePoints = secondPhaseIdx
                    iCount = iCount + 1;
                    ciLARP(iCount,:) = bootci(50, @median, data_temp.RE_Velocity_LARP_Cycles(iTimePoints,keepCycles));
                end
%                 ciLARP = abs(movmedian(ciLARP,50));
                ciLARP = abs(medianLARP(secondPhaseIdx) - ciLARP);
                
                plot(app.UIAxes_13,medianLARP(secondPhaseIdx),'Color',[0.4660 0.6740 0.1880],'LineWidth',2)
                f = figure('Visible','off');
                p.rz = shadedErrorBar(1:length(secondPhaseIdx),medianLARP(secondPhaseIdx),ciLARP,'lineprops',...
                    {'r-','MarkerFaceColor',[0.4660 0.6740 0.1880]});
                set(p.rz.edge,'LineWidth',1,'color',[0.4660 0.6740 0.1880])
                set(p.rz.patch,'facecolor',[0.4660 0.6740 0.1880])
                set(p.rz.mainLine,'color',[0.4660 0.6740 0.1880])
                set(p.rz.mainLine,'LineWidth',2)
                set(p.rz.edge,'LineStyle','-')
                set(p.rz.mainLine,'LineStyle','-')
                copyobj(p.rz.mainLine, app.UIAxes_13)
                copyobj(p.rz.patch, app.UIAxes_13)
                copyobj(p.rz.edge, app.UIAxes_13)
                close(f)
                xticklabels(app.UIAxes_13,{})
                yticklabels(app.UIAxes_13,{})
            end

            % plot RALP
            medianRALP = median(data_temp.RE_Velocity_RALP_Cycles(:,keepCycles),2);

            plot(app.UIAxes_11,data_temp.RE_Velocity_RALP_Cycles(firstPhaseIdx,keepCycles),'Color',[0 0.4470 0.7410 0.3])
            if app.Show95CICheckBox.Value
                hold(app.UIAxes_11,'on')

                for iTimePoints = firstPhaseIdx
                    ciRALP(iTimePoints,:) = bootci(50, @median, data_temp.RE_Velocity_RALP_Cycles(iTimePoints,keepCycles));
                end
%                 ciRALP = abs(movmedian(ciRALP,50));
                ciRALP = abs(medianRALP(firstPhaseIdx) - ciRALP);
                

                plot(app.UIAxes_11,medianRALP(firstPhaseIdx),'Color',[0 0.4470 0.7410],'LineWidth',2)
                f = figure('Visible','off');
                p.rz = shadedErrorBar(firstPhaseIdx,medianRALP(firstPhaseIdx),ciRALP,'lineprops',...
                    {'r-','MarkerFaceColor',[0 0.4470 0.7410]});
                set(p.rz.edge,'LineWidth',1,'color',[0 0.4470 0.7410])
                set(p.rz.patch,'facecolor',[0 0.4470 0.7410])
                set(p.rz.mainLine,'color',[0 0.4470 0.7410])
                set(p.rz.mainLine,'LineWidth',2)
                set(p.rz.edge,'LineStyle','-')
                set(p.rz.mainLine,'LineStyle','-')
                copyobj(p.rz.mainLine, app.UIAxes_11)
                copyobj(p.rz.patch, app.UIAxes_11)
                copyobj(p.rz.edge, app.UIAxes_11)
                close(f)
                xticklabels(app.UIAxes_11,{})
                yticklabels(app.UIAxes_11,{})
            end

            plot(app.UIAxes_15,data_temp.RE_Velocity_RALP_Cycles(secondPhaseIdx,keepCycles),'Color',[0 0.4470 0.7410 0.3])
            if app.Show95CICheckBox.Value
                hold(app.UIAxes_15,'on')
                
                iCount = 0;
                ciRALP = [];
                for iTimePoints = secondPhaseIdx
                    iCount = iCount + 1;
                    ciRALP(iCount,:) = bootci(50, @median, data_temp.RE_Velocity_RALP_Cycles(iTimePoints,keepCycles));
                end
%                 ciRALP = abs(movmedian(ciRALP,50));
                ciRALP = abs(medianRALP(secondPhaseIdx) - ciRALP);
                
                plot(app.UIAxes_15,medianRALP(secondPhaseIdx),'Color',[0 0.4470 0.7410],'LineWidth',2)
                f = figure('Visible','off');
                p.rz = shadedErrorBar(1:length(secondPhaseIdx),medianRALP(secondPhaseIdx),ciRALP,'lineprops',...
                    {'r-','MarkerFaceColor',[0 0.4470 0.7410]});
                set(p.rz.edge,'LineWidth',1,'color',[0 0.4470 0.7410])
                set(p.rz.patch,'facecolor',[0 0.4470 0.7410])
                set(p.rz.mainLine,'color',[0 0.4470 0.7410])
                set(p.rz.mainLine,'LineWidth',2)
                set(p.rz.edge,'LineStyle','-')
                set(p.rz.mainLine,'LineStyle','-')
                copyobj(p.rz.mainLine, app.UIAxes_15)
                copyobj(p.rz.patch, app.UIAxes_15)
                copyobj(p.rz.edge, app.UIAxes_15)
                close(f)
                xticklabels(app.UIAxes_15,{})
                yticklabels(app.UIAxes_15,{})
            end

            % plot Z 
            medianZ = median(data_temp.RE_Velocity_Z_Cycles(:,keepCycles),2);
            
            plot(app.UIAxes_12,data_temp.RE_Velocity_Z_Cycles(firstPhaseIdx,keepCycles),'Color',[0.6350 0.0780 0.1840 0.3])
            if app.Show95CICheckBox.Value
                hold(app.UIAxes_12,'on')

                for iTimePoints = firstPhaseIdx
                    ciZ(iTimePoints,:) = bootci(50, @median, data_temp.RE_Velocity_Z_Cycles(iTimePoints,keepCycles));
                end
%                 ciZ = abs(movmedian(ciZ,50));
                ciZ= abs(medianZ(firstPhaseIdx) - ciZ);

                plot(app.UIAxes_12,medianZ(firstPhaseIdx),'Color',[0.6350 0.0780 0.1840],'LineWidth',2)
                f = figure('Visible','off');
                p.rz = shadedErrorBar(firstPhaseIdx,medianZ(firstPhaseIdx),ciZ,'lineprops',...
                    {'r-','MarkerFaceColor',[0.6350 0.0780 0.1840]});
                set(p.rz.edge,'LineWidth',1,'color',[0.6350 0.0780 0.1840])
                set(p.rz.patch,'facecolor',[0.6350 0.0780 0.1840])
                set(p.rz.mainLine,'color',[0.6350 0.0780 0.1840])
                set(p.rz.mainLine,'LineWidth',2)
                set(p.rz.edge,'LineStyle','-')
                set(p.rz.mainLine,'LineStyle','-')
                copyobj(p.rz.mainLine, app.UIAxes_12)
                copyobj(p.rz.patch, app.UIAxes_12)
                copyobj(p.rz.edge, app.UIAxes_12)
                close(f)
                xticklabels(app.UIAxes_12,{})
                yticklabels(app.UIAxes_12,{})
            end

            plot(app.UIAxes_16,data_temp.RE_Velocity_Z_Cycles(secondPhaseIdx,keepCycles),'Color',[0.6350 0.0780 0.1840 0.3])
            if app.Show95CICheckBox.Value
                hold(app.UIAxes_16,'on')

                iCount = 0;
                ciZ = [];
                for iTimePoints = secondPhaseIdx
                    iCount = iCount + 1;
                    ciZ(iCount,:) = bootci(50, @median, data_temp.RE_Velocity_Z_Cycles(iTimePoints,keepCycles));
                end
                ciZ= abs(medianZ(secondPhaseIdx) - ciZ);
%                 ciZ = movmedian(ciZ,20);

                plot(app.UIAxes_16,medianZ(secondPhaseIdx),'Color',[0.6350 0.0780 0.1840],'LineWidth',2)
                f = figure('Visible','off');
                p.rz = shadedErrorBar(1:length(secondPhaseIdx),movmedian(medianZ(secondPhaseIdx),50),ciZ,'lineprops',...
                    {'r-','MarkerFaceColor',[0.6350 0.0780 0.1840]});
                set(p.rz.edge,'LineWidth',1,'color',[0.6350 0.0780 0.1840])
                set(p.rz.patch,'facecolor',[0.6350 0.0780 0.1840])
                set(p.rz.mainLine,'color',[0.6350 0.0780 0.1840])
                set(p.rz.mainLine,'LineWidth',2)
                set(p.rz.edge,'LineStyle','-')
                set(p.rz.mainLine,'LineStyle','-')
                copyobj(p.rz.mainLine, app.UIAxes_16)
                copyobj(p.rz.patch, app.UIAxes_16)
                copyobj(p.rz.edge, app.UIAxes_16)
                close(f)
            end

            % link eye velocity and waveform plots
            linkaxes([app.UIAxes_4,app.UIAxes_2,app.UIAxes_11,app.UIAxes_12],'x','on')
            linkaxes([app.UIAxes_14,app.UIAxes_13,app.UIAxes_15,app.UIAxes_16],'x','on')
            linkaxes([app.UIAxes_17,app.UIAxes_2,app.UIAxes_13],'y','on')
            linkaxes([app.UIAxes_19,app.UIAxes_11,app.UIAxes_15],'y','on')
            linkaxes([app.UIAxes_20,app.UIAxes_12,app.UIAxes_16],'y','on')

            axis(app.UIAxes_4, 'tight')
            axis(app.UIAxes_14, 'tight')
            ymin = round(min(data_temp.Waveform));
            ymax = round(max(data_temp.Waveform));
            ylim(app.UIAxes_4, [ymin ymax] + [-1,1]*range([ymin ymax]).* 0.5)
            ylim(app.UIAxes_14, [ymin ymax] + [-1,1]*range([ymin ymax]).* 0.5)
            yl = ylim(app.UIAxes_4);
            xlim(app.UIAxes_4, xlim(app.UIAxes_4) + [-1,1]*range(xlim(app.UIAxes_4)).* 0.05)
            yl = ylim(app.UIAxes_14);
            xlim(app.UIAxes_14, xlim(app.UIAxes_14) + [-1,1]*range(xlim(app.UIAxes_14)).* 0.05)

            medianLARP = movmedian(median(data_temp.RE_Velocity_LARP_Cycles,2),250);
            medianRALP = movmedian(median(data_temp.RE_Velocity_RALP_Cycles,2),250);
            medianZ = movmedian(median(data_temp.RE_Velocity_Z_Cycles,2),250);
            ymin = round(min([medianLARP' medianRALP' medianZ']));
            ymax = round(max([medianLARP' medianRALP' medianZ']));
            ylim(app.UIAxes_17, [ymin ymax] + [-1,1]*range([ymin ymax]).* 0.75)
            ylim(app.UIAxes_19, [ymin ymax] + [-1,1]*range([ymin ymax]).* 0.75)
            ylim(app.UIAxes_20, [ymin ymax] + [-1,1]*range([ymin ymax]).* 0.75)
            ylim(app.UIAxes_9, [ymin ymax] + [-1,1]*range([ymin ymax]).* 0.75)
            xticklabels(app.UIAxes_17,{})
            xticklabels(app.UIAxes_19,{})
            xticklabels(app.UIAxes_20,{})
            xticklabels(app.UIAxes_21,{})
            yt = yticks(app.UIAxes_17);
            set([app.UIAxes_2,app.UIAxes_13],'ytick',yt)
            yt = yticks(app.UIAxes_19);
            set([app.UIAxes_11,app.UIAxes_15],'ytick',yt)
            yt = yticks(app.UIAxes_20);
            set([app.UIAxes_12,app.UIAxes_16],'ytick',yt)
            yt = yticks(app.UIAxes_21);
            set([app.UIAxes_4,app.UIAxes_14],'ytick',yt)
            xt = xticks(app.UIAxes_4);
            xticklabels(app.UIAxes_4,xt + firstPhaseIdx(1)-1)
            set([app.UIAxes_2,app.UIAxes_11,app.UIAxes_12],'xtick',xt)
            xt = xticks(app.UIAxes_14);
            set([app.UIAxes_13,app.UIAxes_15,app.UIAxes_16],'xtick',xt)
            xticklabels(app.UIAxes_14,xt + secondPhaseIdx(1)-1)
            xticklabels([app.UIAxes_2,app.UIAxes_11,app.UIAxes_12,app.UIAxes_13,app.UIAxes_15,app.UIAxes_16],{})
            yticklabels([app.UIAxes_2,app.UIAxes_4,app.UIAxes_11,app.UIAxes_12,app.UIAxes_13,app.UIAxes_14,app.UIAxes_15,app.UIAxes_16],{})
            ytickformat(app.UIAxes_9,'%+4.4g')
            ylabel(app.UIAxes_9,'eye velocity (dps)')
            ylabel(app.UIAxes_10,'current (uA)')
            xlabel([app.UIAxes_4,app.UIAxes_10,app.UIAxes_14],'time (ms)')
        end 
        
        function threshold = lineMovingFcn(obj,event)
            % Update title with new x position of the line
            ax = ancestor(obj,'axes');
            ax.Title.String = sprintf('x = %.3f',event.CurrentPosition(1));
            threshold = event.CurrentPosition(1);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadTableButton
        function LoadTableButtonPushed(app, event)
            % ask user for table and load data as struct
            [file, location] = uigetfile({'*.csv'},'Select an excel sheet:');
            table_data = readtable(fullfile(location,file));
            app.Data = table2struct(table_data);
            app.EditField.Value = fullfile(location,file);

            % load standard data from tables in main folder
            nestedfolders = split(location,'\');
            fileidx = find(contains(nestedfolders,'Data'));
            standardTableLoc = fullfile(nestedfolders{1:fileidx});
            tablenames = {dir(fullfile(standardTableLoc,'*.csv')).name}';
            standards_atStart_table = tablenames{contains(tablenames,'Start')};
            standards_atEnd_table = tablenames{contains(tablenames,'End')};
            table_data_standards_atStart = readtable(fullfile(standardTableLoc,standards_atStart_table));
            app.StandardData.Start = table2struct(table_data_standards_atStart);
            app.EditField_2.Value = fullfile(standardTableLoc,standards_atStart_table);
            table_data_standards_atEnd = readtable(fullfile(standardTableLoc,standards_atEnd_table));
            app.StandardData.End = table2struct(table_data_standards_atEnd);
            app.EditField_3.Value = fullfile(standardTableLoc,standards_atEnd_table);

            % load dates listed in table
            table_dates = unique(strcat(string([app.Data.Date]'),'-',[app.Data.StimAxis]'));
            app.ChooseDatesListBox.Items = table_dates;

            % load stim data for tab 2
            [filepath,~] = fileparts(app.EditField.Value);
            cycleFileNames = {dir(fullfile(filepath,'*.mat')).name}';

            baselines = str2double(extractBetween(cycleFileNames,'Offs','uA_'));
            app.ChooseBaselineListBox.Items = string(sort(unique(baselines)));
            if isempty(app.ChooseBaselineListBox.Value)
                app.ChooseBaselineListBox.Value = num2str(baselines(1));
            end
            
            amp1 = str2double(extractBetween(cycleFileNames,'Amp1_','_Amp2_'));

            selectedBaseline = app.ChooseBaselineListBox.Value;
            baseline_bool = contains(cycleFileNames,selectedBaseline);
            stimAmplitudes = round(abs(unique(sort((amp1(baseline_bool))/ max(abs(amp1(baseline_bool))))*100)),2);

            app.ChooseStimAmplitudeListBox.Items = string(stimAmplitudes) + '%';
            if isempty(app.ChooseStimAmplitudeListBox.Value)
                app.ChooseStimAmplitudeListBox.Value = app.ChooseStimAmplitudeListBox.Items(1);
            end

            switch app.ChooseColorMapDropDown.Value
                case "grayscale"
                    app.PlotColors = gray(length(unique(baselines)) + 2);
                case "matlab default"
                    app.PlotColors = parula(length(unique(baselines)));
                case "colorblind friendly"
                    app.PlotColors = ametrine(length(unique(baselines)) + 1);
                case "colorblind friendly 2"
                    app.PlotColors = [[0.3010 0.7450 0.9330];[0.4660 0.6740 0.1880];[0.4940 0.1840 0.5560];[0.6350 0.0780 0.1840];[0 0 0]];
            end

        end

        % Callback function
        function ManuallyselectdatafromtableButtonPushed(app, event)
            app.ChooseXDataListBox.Items = fieldnames(app.Data);
            app.ChooseYDataListBox.Items = fieldnames(app.Data);
        end

        % Value changed function: ChooseXDataListBox
        function ChooseXDataListBoxValueChanged(app, event)
            valueX = app.ChooseXDataListBox.Value;
            valueY = app.ChooseYDataListBox.Value;
            if strcmp(valueX,"current (uA)") && strcmp(valueY,"eye velocity (dps)")
                app.EyeVelocityandMisalignmentButton.Value = 1;
                h = app.TabGroup.Children(1);
                app.TabGroup.SelectedTab = h;
            elseif strcmp(valueX,"current from baseline (uA)") && strcmp(valueY,"eye velocity (dps)")
                app.EyeVelocityandMisalignmentButton.Value = 1;
                h = app.TabGroup.Children(1);
                app.TabGroup.SelectedTab = h;
            elseif strcmp(valueX,"modulation (%)") && strcmp(valueY,"eye velocity (dps)")
                app.EyeVelocityandMisalignmentButton.Value = 1;
                h = app.TabGroup.Children(1);
                app.TabGroup.SelectedTab = h;
            elseif strcmp(valueX,"time (ms)") && strcmp(valueY,"eye velocity (dps)")
                app.CycleAverageandWaveformButton.Value = 1;
                h = app.TabGroup.Children(2);
                app.TabGroup.SelectedTab = h;
            else
                app.OtherButton.Value = 1;
                h = app.TabGroup.Children(3);
                app.TabGroup.SelectedTab = h;
            end
        end

        % Value changed function: SelectXandYfromtableButton
        function SelectXandYfromtableButtonValueChanged(app, event)
            % display table column names when button is selected
            value = app.SelectXandYfromtableButton.Value;
            if value
                app.ChooseXDataListBox.Items = fieldnames(app.Data);
                app.ChooseYDataListBox.Items = fieldnames(app.Data);

                h = app.TabGroup.Children(3);
                app.TabGroup.SelectedTab = h;

                app.ChooseXDataListBox.Value = "StimAmplitude1";
                app.ChooseYDataListBox.Value = "EyeVel_Magnitude_Cathodic_avg_nystagmusCorrected";

                app.OtherButton.Value = 1;
            else
                app.ChooseXDataListBox.Items = ["current (uA)";"current from baseline (uA)"; "modulation (%)"; "time (ms)"];
                app.ChooseYDataListBox.Items = "eye velocity (dps)";

                h = app.TabGroup.Children(1);
                app.TabGroup.SelectedTab = h;

                app.ChooseXDataListBox.Value = "modulation (%)";
                app.ChooseYDataListBox.Value = "eye velocity (dps)";

                app.EyeVelocityandMisalignmentButton.Value = 1;
            end
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            % make sure the correct X and Y data is selected if the type of
            % plot is changed
            selectedButton = app.ButtonGroup.SelectedObject;
            switch selectedButton.Text
                case "1. Eye Velocity and Misalignment"
                    if ~any(strcmp(app.ChooseXDataListBox.Value,["current (uA)";"current from baseline (uA)";"modulation (%)"]))
                        app.ChooseXDataListBox.Items = ["current (uA)";"current from baseline (uA)"; "modulation (%)"; "time (ms)"];
                        app.ChooseYDataListBox.Items = "eye velocity (dps)";

                        app.ChooseXDataListBox.Value = "modulation (%)";
                        app.ChooseYDataListBox.Value = "eye velocity (dps)";

                        h = app.TabGroup.Children(1);
                        app.TabGroup.SelectedTab = h;
                    end
                case "2. Cycle Average and Waveform"
                    if ~any(strcmp(app.ChooseXDataListBox.Value,"time (ms)"))
                        app.ChooseXDataListBox.Items = ["current (uA)";"current from baseline (uA)"; "modulation (%)"; "time (ms)"];
                        app.ChooseYDataListBox.Items = "eye velocity (dps)";

                        app.ChooseXDataListBox.Value = "time (ms)";
                        app.ChooseYDataListBox.Value = "eye velocity (dps)";

                        h = app.TabGroup.Children(2);
                        app.TabGroup.SelectedTab = h;
                    end
                case "3. Other"
                    if ~app.SelectXandYfromtableButton.Value
                        app.ChooseXDataListBox.Items = fieldnames(app.Data);
                        app.ChooseYDataListBox.Items = fieldnames(app.Data);

                        app.ChooseXDataListBox.Value = "StimAmplitude1";
                        app.ChooseYDataListBox.Value = "EyeVel_Magnitude_Cathodic_avg_nystagmusCorrected";

                        h = app.TabGroup.Children(3);
                        app.TabGroup.SelectedTab = h;
                    end
            end
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            switch app.ButtonGroup.SelectedObject.Text
                case "1. Eye Velocity and Misalignment"
                    plotEyeVelvStim(app)
                    plotSensitivity(app);
                case "2. Cycle Average and Waveform"
                    plotEyeVelvTime(app)
                case "3. Other"
            end
        end

        % Value changed function: 
        % SensitivityCorrectedusingstandardsCheckBox
        function SensitivityCorrectedusingstandardsCheckBoxValueChanged(app, event)
            
        end

        % Value changed function: ChooseColorMapDropDown
        function ChooseColorMapDropDownValueChanged(app, event)
            value = app.ChooseColorMapDropDown.Value;
            [filepath,~] = fileparts(app.EditField.Value);
            cycleFileNames = {dir(fullfile(filepath,'*.mat')).name}';
            baselines = str2double(extractBetween(cycleFileNames,'Offs','uA_'));
            switch value
                case "grayscale"
                    app.PlotColors = gray(length(unique(baselines)) + 2);
                case "matlab default"
                    app.PlotColors = parula(length(unique(baselines)));
                case "colorblind friendly"
                    app.PlotColors = ametrine(length(unique(baselines)) + 1);
                case "colorblind friendly 2"
                    app.PlotColors = [[0.3010 0.7450 0.9330];[0.4660 0.6740 0.1880];[0.4940 0.1840 0.5560];[0.6350 0.0780 0.1840];[0 0 0]];
            end
        end

        % Value changed function: ChooseBaselineListBox
        function ChooseBaselineListBoxValueChanged(app, event)
            amplitude_value = app.ChooseStimAmplitudeListBox.Value;

            [filepath,~] = fileparts(app.EditField.Value);
            cycleFileNames = fullfile(filepath,'Cycles', {app.Data.CycleName}');

            selectedBaseline = app.ChooseBaselineListBox.Value;
            selectedStimType = "DCTrap";
            stimtype_bool = contains(cycleFileNames,selectedStimType);
            fileNames_correctType = cycleFileNames(stimtype_bool);

            amp1 = str2double(extractBetween(fileNames_correctType,'Amp1_','_Amp2_'));
            
            baseline_bool = contains(fileNames_correctType,['Offs' selectedBaseline]);
            stimAmplitudes = round(abs((amp1(baseline_bool))/ max(abs(amp1(baseline_bool)))*100),2);

            app.ChooseStimAmplitudeListBox.Items = string(sort(stimAmplitudes,'desc')) + '%';
            if ~contains(app.ChooseStimAmplitudeListBox.Items,amplitude_value)
                app.ChooseStimAmplitudeListBox.Value = app.ChooseStimAmplitudeListBox.Items(1);
            end
            
        end

        % Value changed function: RemoveRemainingQPsCheckBox
        function RemoveRemainingQPsCheckBoxValueChanged(app, event)
            value = app.RemoveRemainingQPsCheckBox.Value;

            if value
                switch extractAfter(app.ChooseDatesListBox.Value{:},'-')
                    case 'LARP'
                % larp thresholds
                hlarp_excite = images.roi.Line(app.UIAxes_2,'Position',[xlim(app.UIAxes_2);0 0]',... % start at y=0
                    'Label','threshold', ...
                    'LabelAlpha',.35, ...
                    'InteractionsAllowed','translate', ...
                    'MarkerSize',1, ...
                    'Color',[0 0 0] );
                % Update title when line is moving
                addlistener(hlarp_excite, 'MovingROI', @app.lineMovingFcn)

                hlarp_inhibit = images.roi.Line(app.UIAxes_13,'Position',[xlim(app.UIAxes_13);0 0]',... % start at y=0
                    'Label','threshold', ...
                    'LabelAlpha',.35, ...
                    'InteractionsAllowed','translate', ...
                    'MarkerSize',1, ...
                    'Color',[0 0 0] );
                % Update title when line is moving
                addlistener(hlarp_inhibit, 'MovingROI', @app.lineMovingFcn)
                    case 'RALP'
                % ralp thresholds
                hralp_excite = images.roi.Line(app.UIAxes_11,'Position',[xlim(app.UIAxes_11);0 0]',... % start at y=0
                    'Label','threshold', ...
                    'LabelAlpha',.35, ...
                    'InteractionsAllowed','translate', ...
                    'MarkerSize',1, ...
                    'Color',[0 0 0] );
                % Update title when line is moving
                addlistener(hralp_excite, 'MovingROI', @app.lineMovingFcn)

                hralp_inhibit = images.roi.Line(app.UIAxes_15,'Position',[xlim(app.UIAxes_15);0 0]',... % start at y=0
                    'Label','threshold', ...
                    'LabelAlpha',.35, ...
                    'InteractionsAllowed','translate', ...
                    'MarkerSize',1, ...
                    'Color',[0 0 0] );
                % Update title when line is moving
                addlistener(hralp_inhibit, 'MovingROI', @app.lineMovingFcn)
                    case 'Z'
                % lhrh thresholds
                hlhrh_excite = images.roi.Line(app.UIAxes_12,'Position',[xlim(app.UIAxes_12);0 0]',... % start at y=0
                    'Label','threshold', ...
                    'LabelAlpha',.35, ...
                    'InteractionsAllowed','translate', ...
                    'MarkerSize',1, ...
                    'Color',[0 0 0] );
                % Update title when line is moving
                addlistener(hlhrh_excite, 'MovingROI', @app.lineMovingFcn)

                hlhrh_inhibit = images.roi.Line(app.UIAxes_16,'Position',[xlim(app.UIAxes_16);0 0]',... % start at y=0
                    'Label','threshold', ...
                    'LabelAlpha',.35, ...
                    'InteractionsAllowed','translate', ...
                    'MarkerSize',1, ...
                    'Color',[0 0 0]);
                % Update title when line is moving
                addlistener(hlhrh_inhibit, 'MovingROI', @app.lineMovingFcn)
                end

            end
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            switch extractAfter(app.ChooseDatesListBox.Value{:},'-')
                case 'LARP'
                    threshold_excite = app.UIAxes_2.Children(1).Position(1,2);
                    threshold_inhibit = app.UIAxes_13.Children(1).Position(1,2);
                case 'RALP'
                    threshold_excite = app.UIAxes_11.Children(1).Position(1,2);
                    threshold_inhibit = app.UIAxes_15.Children(1).Position(1,2);
                case 'Z'
                    threshold_excite = app.UIAxes_12.Children(1).Position(1,2);
                    threshold_inhibit = app.UIAxes_16.Children(1).Position(1,2);

                    traces_excite = app.UIAxes_12.Children(2:end);
                    waveform = app.UIAxes_4.Children(1).YData;
                    offset = waveform(1);
                    stimon = min(find((waveform - offset) ~= 0))-1;
                    stimoff = max(find((waveform - offset) ~= 0))+50;
                    nTraces = length(traces_excite); % number of traces on plot without threshold line, should be equal to nCycles

                    traces_all = vertcat(traces_excite(:).YData);
                    traces_stimon = traces_all(:,stimon:stimoff);
                   

                    for iTrace = 1:nTraces

                        trace = traces_excite(iTrace).YData;
                        data_temp = trace(stimon:stimoff);

                        dy = diff(traces_all(iTrace,:))./diff([1:length(traces_all)]);
                        [~,qpstart] = max(dy(1:stimoff-100)); % looking for increasing Z response after onset of stim
                        [~,qpend] = min(dy(qpstart:end));
                        data_noqp = data_temp;
                        data_noqp(qpstart:qpend) = NaN;
% 
%                         % find region(s) to spline over when data crosses
%                         % set threshold
%                         toSpline = data_temp >= threshold_excite;
%                         localmins = islocalmin(data_temp);
%                         spline_times = find(~toSpline .* localmins);
%                         cutoff_times = find(diff(toSpline));
%                         cutoff_times(1:3:end) = []; % we only want the times the quick phase starts and stops
%                         
% 
%                         % spline over QPs
%                         xq = [];
%                         for iCutoff = 1:length(cutoff_times)/2
%                             [~,idx_start] = min(abs((cutoff_times(iCutoff*2-1) - spline_times)));
%                             [~,idx_stop] = min(abs((cutoff_times(iCutoff*2) - spline_times)));
%                             xq = [xq spline_times(idx_start):spline_times(idx_stop)];
%                         end  
%                         data_noqp = data_temp;
%                         data_noqp(xq) = NaN;
% %                         s = spline(1:length(data_temp),data_noqp,xq);
% %                         data_noqp(xq) = s;
                        traces_noqp(iTrace,:) = trace;
                        traces_noqp(iTrace,stimon:stimoff) = data_noqp;
                    end

                    cla(app.UIAxes_12)
                    plot(app.UIAxes_12,traces_noqp','Color',[0.6350 0.0780 0.1840])

            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {774, 774};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {550, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ChooseXDataListBoxLabel = uilabel(app.LeftPanel);
            app.ChooseXDataListBoxLabel.Text = 'Choose X Data:';
            app.ChooseXDataListBox = uilistbox(app.LeftPanel);
            app.ChooseXDataListBox.Items = {'current (uA)', 'current from baseline (uA)', 'modulation (%)', 'time (ms)'};
            app.ChooseXDataListBox.ValueChangedFcn = createCallbackFcn(app, @ChooseXDataListBoxValueChanged, true);
            app.ChooseXDataListBox.Value = 'current (uA)';
            app.ChooseYDataListBoxLabel = uilabel(app.LeftPanel);
            app.ChooseYDataListBoxLabel.Text = 'Choose Y Data:';
            app.ChooseYDataListBox = uilistbox(app.LeftPanel);
            app.ChooseYDataListBox.Items = {'eye velocity (dps)'};
            app.ChooseYDataListBox.Value = 'eye velocity (dps)';
            app.NystagmusCorrectedCheckBox = uicheckbox(app.LeftPanel);
            app.NystagmusCorrectedCheckBox.Text = 'Nystagmus Corrected';
            app.SensitivityCorrectedusingstandardsCheckBox = uicheckbox(app.LeftPanel);
            app.SensitivityCorrectedusingstandardsCheckBox.ValueChangedFcn = createCallbackFcn(app, @SensitivityCorrectedusingstandardsCheckBoxValueChanged, true);
            app.SensitivityCorrectedusingstandardsCheckBox.Text = {'Sensitivity Corrected'; '(using standards)'};
            app.LoadTableButton = uibutton(app.LeftPanel, 'push');
            app.LoadTableButton.ButtonPushedFcn = createCallbackFcn(app, @LoadTableButtonPushed, true);
            app.LoadTableButton.Text = 'Load Table:';
            app.EditField = uieditfield(app.LeftPanel, 'text');
            app.ButtonGroup = uibuttongroup(app.LeftPanel);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.EyeVelocityandMisalignmentButton = uitogglebutton(app.ButtonGroup);
            app.EyeVelocityandMisalignmentButton.Text = '1. Eye Velocity and Misalignment';
            app.EyeVelocityandMisalignmentButton.Value = true;
            app.CycleAverageandWaveformButton = uitogglebutton(app.ButtonGroup);
            app.CycleAverageandWaveformButton.Text = '2. Cycle Average and Waveform';
            app.OtherButton = uitogglebutton(app.ButtonGroup);
            app.OtherButton.Text = '3. Other';
            app.TypeofPlotLabel = uilabel(app.LeftPanel);
            app.TypeofPlotLabel.Text = 'Type of Plot:';
            app.ChooseColorMapLabel = uilabel(app.LeftPanel);
            app.ChooseColorMapLabel.Text = 'Choose Color Map:';
            app.ChooseColorMapDropDown = uidropdown(app.LeftPanel);
            app.ChooseColorMapDropDown.Items = {'grayscale', 'matlab default', 'colorblind friendly', 'colorblind friendly 2'};
            app.ChooseColorMapDropDown.ValueChangedFcn = createCallbackFcn(app, @ChooseColorMapDropDownValueChanged, true);
            app.ChooseColorMapDropDown.Value = 'grayscale';
            app.EditField_2 = uieditfield(app.LeftPanel, 'text');
            app.EditField_3 = uieditfield(app.LeftPanel, 'text');
            app.StandardstartLabel = uilabel(app.LeftPanel);
            app.StandardstartLabel.Text = 'Standard (start):';
            app.StandardendLabel = uilabel(app.LeftPanel);
            app.StandardendLabel.Text = 'Standard (end):';
            app.ChooseDatesListBoxLabel = uilabel(app.LeftPanel);
            app.ChooseDatesListBoxLabel.Text = 'Choose Dates:';
            app.ChooseDatesListBox = uilistbox(app.LeftPanel);
            app.ChooseDatesListBox.Items = {};
            app.ChooseDatesListBox.Multiselect = 'on';
            app.ChooseDatesListBox.Value = {};
            app.ChooseBaselineTypeDropDownLabel = uilabel(app.LeftPanel);
            app.ChooseBaselineTypeDropDownLabel.Text = 'Choose Baseline Type:';
            app.ChooseBaselineTypeDropDown = uidropdown(app.LeftPanel);
            app.ChooseBaselineTypeDropDown.Items = {'cathodic iDC', 'anodic iDC', 'PFM'};
            app.ChooseBaselineTypeDropDown.Value = 'cathodic iDC';
            app.PlotButton = uibutton(app.LeftPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.SelectXandYfromtableButton = uibutton(app.LeftPanel, 'state');
            app.SelectXandYfromtableButton.ValueChangedFcn = createCallbackFcn(app, @SelectXandYfromtableButtonValueChanged, true);
            app.SelectXandYfromtableButton.Text = 'Select X and Y from table';
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '1.';
            app.UIAxes = uiaxes(app.Tab);
            title(app.UIAxes, 'Title')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_3 = uiaxes(app.Tab);
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_6 = uiaxes(app.Tab);
            title(app.UIAxes_6, 'Sensitivity')
            xlabel(app.UIAxes_6, 'X')
            ylabel(app.UIAxes_6, 'Y')
            zlabel(app.UIAxes_6, 'Z')
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '2.';
            app.UIAxes_9 = uiaxes(app.Tab_2);
            title(app.UIAxes_9, 'Title')
            ylabel(app.UIAxes_9, 'Y')
            zlabel(app.UIAxes_9, 'Z')
            app.UIAxes_10 = uiaxes(app.Tab_2);
            xlabel(app.UIAxes_10, 'X')
            ylabel(app.UIAxes_10, 'Y')
            zlabel(app.UIAxes_10, 'Z')
            app.UIAxes_2 = uiaxes(app.Tab_2);
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_4 = uiaxes(app.Tab_2);
            xlabel(app.UIAxes_4, 'X')
            zlabel(app.UIAxes_4, 'Z')
            app.UIAxes_11 = uiaxes(app.Tab_2);
            zlabel(app.UIAxes_11, 'Z')
            app.UIAxes_12 = uiaxes(app.Tab_2);
            zlabel(app.UIAxes_12, 'Z')
            app.UIAxes_13 = uiaxes(app.Tab_2);
            zlabel(app.UIAxes_13, 'Z')
            app.UIAxes_14 = uiaxes(app.Tab_2);
            xlabel(app.UIAxes_14, 'X')
            zlabel(app.UIAxes_14, 'Z')
            app.UIAxes_15 = uiaxes(app.Tab_2);
            zlabel(app.UIAxes_15, 'Z')
            app.UIAxes_16 = uiaxes(app.Tab_2);
            zlabel(app.UIAxes_16, 'Z')
            app.UIAxes_17 = uiaxes(app.Tab_2);
            zlabel(app.UIAxes_17, 'Z')
            app.UIAxes_19 = uiaxes(app.Tab_2);
            zlabel(app.UIAxes_19, 'Z')
            app.UIAxes_20 = uiaxes(app.Tab_2);
            zlabel(app.UIAxes_20, 'Z')
            app.UIAxes_21 = uiaxes(app.Tab_2);
            zlabel(app.UIAxes_21, 'Z')
            app.ChooseBaselineListBoxLabel = uilabel(app.Tab_2);
            app.ChooseBaselineListBoxLabel.Text = 'Choose Baseline:';
            app.ChooseBaselineListBox = uilistbox(app.Tab_2);
            app.ChooseBaselineListBox.Items = {};
            app.ChooseBaselineListBox.ValueChangedFcn = createCallbackFcn(app, @ChooseBaselineListBoxValueChanged, true);
            app.ChooseBaselineListBox.Value = {};
            app.ChooseStimAmplitudeListBoxLabel = uilabel(app.Tab_2);
            app.ChooseStimAmplitudeListBoxLabel.Text = 'Choose Stim Amplitude:';
            app.ChooseStimAmplitudeListBox = uilistbox(app.Tab_2);
            app.ChooseStimAmplitudeListBox.Items = {};
            app.ChooseStimAmplitudeListBox.Value = {};
            app.Show95CICheckBox = uicheckbox(app.Tab_2);
            app.Show95CICheckBox.Text = 'Show 95% CI';
            app.RemoveRemainingQPsCheckBox = uicheckbox(app.Tab_2);
            app.RemoveRemainingQPsCheckBox.ValueChangedFcn = createCallbackFcn(app, @RemoveRemainingQPsCheckBoxValueChanged, true);
            app.RemoveRemainingQPsCheckBox.Text = 'Remove Remaining QPs';
            app.DoneButton = uibutton(app.Tab_2, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.Tab_3 = uitab(app.TabGroup);
            app.Tab_3.Title = '3.';
            app.UIAxes_5 = uiaxes(app.Tab_3);
            title(app.UIAxes_5, 'Title')
            ylabel(app.UIAxes_5, 'Y')
            zlabel(app.UIAxes_5, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = coilanalysis_plotdata

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end