classdef cycleanalysis_cyclefiltering < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        SelectfiletoanalyzeListBox   matlab.ui.control.ListBox
        SelectfiletoanalyzeListBoxLabel  matlab.ui.control.Label
        PreviousFileButton           matlab.ui.control.Button
        NextFileButton               matlab.ui.control.Button
        TabGroup                     matlab.ui.container.TabGroup
        PositionFilteringTab         matlab.ui.container.Tab
        filterorderEditField_2       matlab.ui.control.EditField
        filterorderEditField_2Label  matlab.ui.control.Label
        filtertypeDropDown_4         matlab.ui.control.DropDown
        filtertypeDropDown_4Label    matlab.ui.control.Label
        windowsizeEditField_2        matlab.ui.control.EditField
        windowsizeEditField_2Label   matlab.ui.control.Label
        filtertypeDropDown_3         matlab.ui.control.DropDown
        LHRHZCheckBox_2              matlab.ui.control.CheckBox
        displayLabel_3               matlab.ui.control.Label
        YCheckBox_2                  matlab.ui.control.CheckBox
        XCheckBox_2                  matlab.ui.control.CheckBox
        ApplyFilteringButton_2       matlab.ui.control.Button
        UIAxes_7                     matlab.ui.control.UIAxes
        VelocityFilteringTab         matlab.ui.container.Tab
        filterorderEditField_3       matlab.ui.control.EditField
        filterorderEditField_3Label  matlab.ui.control.Label
        filtertypeDropDown_6         matlab.ui.control.DropDown
        filtertypeDropDown_6Label    matlab.ui.control.Label
        windowsizeEditField_3        matlab.ui.control.EditField
        windowsizeEditField_3Label   matlab.ui.control.Label
        filtertypeDropDown_5         matlab.ui.control.DropDown
        ApplyFilteringButton_3       matlab.ui.control.Button
        LHRHZCheckBox_5              matlab.ui.control.CheckBox
        RALPCheckBox_3               matlab.ui.control.CheckBox
        LARPCheckBox_3               matlab.ui.control.CheckBox
        displayLabel_4               matlab.ui.control.Label
        YCheckBox_3                  matlab.ui.control.CheckBox
        XCheckBox_3                  matlab.ui.control.CheckBox
        UIAxes_6                     matlab.ui.control.UIAxes
        CycleFilteringTab            matlab.ui.container.Tab
        SaveCyclesButton_2           matlab.ui.control.Button
        ResetButton                  matlab.ui.control.Button
        CyclesListBox                matlab.ui.control.ListBox
        CyclesListBoxLabel           matlab.ui.control.Label
        LHRHCheckBox_2               matlab.ui.control.CheckBox
        RALPCheckBox_2               matlab.ui.control.CheckBox
        LARPCheckBox_2               matlab.ui.control.CheckBox
        displayLabel_2               matlab.ui.control.Label
        filtereddataCheckBox_2       matlab.ui.control.CheckBox
        rawdataCheckBox_2            matlab.ui.control.CheckBox
        UIAxes_4                     matlab.ui.control.UIAxes
        UIAxes_3                     matlab.ui.control.UIAxes
        Label_3                      matlab.ui.control.Label
        Label                        matlab.ui.control.Label
        createdbyKNM2024026Label     matlab.ui.control.Label
        CycleAnalysisGUILabel        matlab.ui.control.Label
        FileLocationEditField        matlab.ui.control.EditField
        LoadFilesButton              matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadFilesButton
        function LoadFilesButtonPushed(app, event)
            filepath = uigetdir('Select the root folder for the experiment:');
            filepath_segments = fullfile(filepath,"Segments");
            filepath_cycles = fullfile(filepath,"Cycles");
            if ~isfolder(filepath_cycles) % make sure there is a cycles folder
                mkdir(filepath_cycles)
            end
            FileLocationEditFieldValueChanging(app, filepath)
        end

        % Value changing function: FileLocationEditField
        function FileLocationEditFieldValueChanging(app, event)
            changingValue = event.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LoadFilesButton = uibutton(app.UIFigure, 'push');
            app.LoadFilesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFilesButtonPushed, true);
            app.LoadFilesButton.Text = 'Load Files';
            app.FileLocationEditField = uieditfield(app.UIFigure, 'text');
            app.FileLocationEditField.ValueChangingFcn = createCallbackFcn(app, @FileLocationEditFieldValueChanging, true);
            app.FileLocationEditField.Editable = 'off';
            app.CycleAnalysisGUILabel = uilabel(app.UIFigure);
            app.CycleAnalysisGUILabel.Text = 'Cycle Analysis GUI ';
            app.createdbyKNM2024026Label = uilabel(app.UIFigure);
            app.createdbyKNM2024026Label.Text = 'created by KNM 2024026';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '1.';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '2.';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PositionFilteringTab = uitab(app.TabGroup);
            app.PositionFilteringTab.Title = '3. Position Filtering';
            app.UIAxes_7 = uiaxes(app.PositionFilteringTab);
            title(app.UIAxes_7, 'Eye Position')
            xlabel(app.UIAxes_7, 'time (ms)')
            ylabel(app.UIAxes_7, 'eye position (degs)')
            zlabel(app.UIAxes_7, 'Z')
            app.ApplyFilteringButton_2 = uibutton(app.PositionFilteringTab, 'push');
            app.ApplyFilteringButton_2.Text = {'Apply'; 'Filtering'};
            app.XCheckBox_2 = uicheckbox(app.PositionFilteringTab);
            app.XCheckBox_2.Text = 'X';
            app.YCheckBox_2 = uicheckbox(app.PositionFilteringTab);
            app.YCheckBox_2.Text = 'Y';
            app.displayLabel_3 = uilabel(app.PositionFilteringTab);
            app.displayLabel_3.Text = 'display:';
            app.LHRHZCheckBox_2 = uicheckbox(app.PositionFilteringTab);
            app.LHRHZCheckBox_2.Text = 'LHRH/Z';
            app.filtertypeDropDown_3 = uidropdown(app.PositionFilteringTab);
            app.filtertypeDropDown_3.Items = {'LE - Ang. Pos. - X', 'LE - Ang. Pos. - Y', 'LE - Ang. Pos. - Z', 'RE - Ang. Pos. - X', 'RE - Ang. Pos. - Y', 'RE - Ang. Pos. - Z'};
            app.filtertypeDropDown_3.Value = 'LE - Ang. Pos. - X';
            app.windowsizeEditField_2Label = uilabel(app.PositionFilteringTab);
            app.windowsizeEditField_2Label.Text = 'window size';
            app.windowsizeEditField_2 = uieditfield(app.PositionFilteringTab, 'text');
            app.filtertypeDropDown_4Label = uilabel(app.PositionFilteringTab);
            app.filtertypeDropDown_4Label.Text = 'filter type';
            app.filtertypeDropDown_4 = uidropdown(app.PositionFilteringTab);
            app.filtertypeDropDown_4.Items = {'moving median', 'moving mean', 'Savitzky-Golay', 'iteratively reweighted least-squares smoother'};
            app.filtertypeDropDown_4.Value = 'moving median';
            app.filterorderEditField_2Label = uilabel(app.PositionFilteringTab);
            app.filterorderEditField_2Label.Text = 'filter order';
            app.filterorderEditField_2 = uieditfield(app.PositionFilteringTab, 'text');
            app.VelocityFilteringTab = uitab(app.TabGroup);
            app.VelocityFilteringTab.Title = '4. Velocity Filtering';
            app.UIAxes_6 = uiaxes(app.VelocityFilteringTab);
            title(app.UIAxes_6, 'Eye Velocity')
            xlabel(app.UIAxes_6, 'time (ms)')
            ylabel(app.UIAxes_6, 'eye velocity (dps)')
            zlabel(app.UIAxes_6, 'Z')
            app.XCheckBox_3 = uicheckbox(app.VelocityFilteringTab);
            app.XCheckBox_3.Text = 'X';
            app.YCheckBox_3 = uicheckbox(app.VelocityFilteringTab);
            app.YCheckBox_3.Text = 'Y';
            app.displayLabel_4 = uilabel(app.VelocityFilteringTab);
            app.displayLabel_4.Text = 'display:';
            app.LARPCheckBox_3 = uicheckbox(app.VelocityFilteringTab);
            app.LARPCheckBox_3.Text = 'LARP';
            app.RALPCheckBox_3 = uicheckbox(app.VelocityFilteringTab);
            app.RALPCheckBox_3.Text = 'RALP';
            app.LHRHZCheckBox_5 = uicheckbox(app.VelocityFilteringTab);
            app.LHRHZCheckBox_5.Text = 'LHRH/Z';
            app.ApplyFilteringButton_3 = uibutton(app.VelocityFilteringTab, 'push');
            app.ApplyFilteringButton_3.Text = {'Apply'; 'Filtering'};
            app.filtertypeDropDown_5 = uidropdown(app.VelocityFilteringTab);
            app.filtertypeDropDown_5.Items = {'LE - Ang. Vel. - LARP', 'LE - Ang. Vel. - RALP', 'LE - Ang. Vel. - Z', 'RE - Ang. Vel. - LARP', 'RE - Ang. Vel. - RALP', 'RE - Ang. Vel. - Z'};
            app.filtertypeDropDown_5.Value = 'LE - Ang. Vel. - LARP';
            app.windowsizeEditField_3Label = uilabel(app.VelocityFilteringTab);
            app.windowsizeEditField_3Label.Text = 'window size';
            app.windowsizeEditField_3 = uieditfield(app.VelocityFilteringTab, 'text');
            app.filtertypeDropDown_6Label = uilabel(app.VelocityFilteringTab);
            app.filtertypeDropDown_6Label.Text = 'filter type';
            app.filtertypeDropDown_6 = uidropdown(app.VelocityFilteringTab);
            app.filtertypeDropDown_6.Items = {'moving median', 'moving mean', 'Savitzky-Golay', 'iteratively reweighted least-squares smoother', 'moving median after stimulation'};
            app.filtertypeDropDown_6.Value = 'moving median';
            app.filterorderEditField_3Label = uilabel(app.VelocityFilteringTab);
            app.filterorderEditField_3Label.Text = 'filter order';
            app.filterorderEditField_3 = uieditfield(app.VelocityFilteringTab, 'text');
            app.CycleFilteringTab = uitab(app.TabGroup);
            app.CycleFilteringTab.Title = '5. Cycle Filtering';
            app.UIAxes_3 = uiaxes(app.CycleFilteringTab);
            title(app.UIAxes_3, 'All Cycles')
            xlabel(app.UIAxes_3, 'time (ms)')
            ylabel(app.UIAxes_3, 'eye velocity (dps)')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_4 = uiaxes(app.CycleFilteringTab);
            title(app.UIAxes_4, 'Cycle Average')
            xlabel(app.UIAxes_4, 'time (ms)')
            ylabel(app.UIAxes_4, 'eye velocity (dps)')
            zlabel(app.UIAxes_4, 'Z')
            app.rawdataCheckBox_2 = uicheckbox(app.CycleFilteringTab);
            app.rawdataCheckBox_2.Text = 'raw data';
            app.filtereddataCheckBox_2 = uicheckbox(app.CycleFilteringTab);
            app.filtereddataCheckBox_2.Text = 'filtered data';
            app.displayLabel_2 = uilabel(app.CycleFilteringTab);
            app.displayLabel_2.Text = 'display:';
            app.LARPCheckBox_2 = uicheckbox(app.CycleFilteringTab);
            app.LARPCheckBox_2.Text = 'LARP';
            app.RALPCheckBox_2 = uicheckbox(app.CycleFilteringTab);
            app.RALPCheckBox_2.Text = 'RALP';
            app.LHRHCheckBox_2 = uicheckbox(app.CycleFilteringTab);
            app.LHRHCheckBox_2.Text = 'LHRH';
            app.CyclesListBoxLabel = uilabel(app.CycleFilteringTab);
            app.CyclesListBoxLabel.Text = 'Cycles:';
            app.CyclesListBox = uilistbox(app.CycleFilteringTab);
            app.CyclesListBox.Multiselect = 'on';
            app.CyclesListBox.Value = {'Item 1'};
            app.ResetButton = uibutton(app.CycleFilteringTab, 'push');
            app.ResetButton.Text = 'Reset';
            app.SaveCyclesButton_2 = uibutton(app.CycleFilteringTab, 'push');
            app.SaveCyclesButton_2.Text = 'Save Cycles';
            app.NextFileButton = uibutton(app.UIFigure, 'push');
            app.NextFileButton.Text = 'Next File';
            app.PreviousFileButton = uibutton(app.UIFigure, 'push');
            app.PreviousFileButton.Text = 'Previous File';
            app.SelectfiletoanalyzeListBoxLabel = uilabel(app.UIFigure);
            app.SelectfiletoanalyzeListBoxLabel.Text = 'Select file to analyze:';
            app.SelectfiletoanalyzeListBox = uilistbox(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cycleanalysis_cyclefiltering

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end